classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainAppUIFigure           matlab.ui.Figure
        RobotParamsButton         matlab.ui.control.Button
        RobotControlParamsButton  matlab.ui.control.Button
        WorkspaceParamsButton     matlab.ui.control.Button
        LoadParametersButton      matlab.ui.control.Button
        SaveParametersButton      matlab.ui.control.Button
        TLTypeLabel               matlab.ui.control.Label
        TLTypeDropDown            matlab.ui.control.DropDown
        FontSizeLabel             matlab.ui.control.Label
        FontSizeSpinner           matlab.ui.control.Spinner
        FontSizeUnitSwitch        matlab.ui.control.Switch
        TextToWriteLabel          matlab.ui.control.Label
        TextToWriteEditField      matlab.ui.control.EditField
        TextPreviewLabel          matlab.ui.control.Label
        ReturnToRefPoseLabel      matlab.ui.control.Label
        ReturnToRefPoseSwitch     matlab.ui.control.Switch
        SimulateButton            matlab.ui.control.Button
        RobotTransferButton       matlab.ui.control.Button
        TextPreviewPanel          matlab.ui.container.Panel
        TextPreviewGridLayout     matlab.ui.container.GridLayout
        TextPreviewHTML           matlab.ui.control.HTML
    end

    
    properties (Access = private)
        dws = [600,310]; % Podrazumijevana velicina prozora
        dws2 = [600,310,600,310];
        pt2mm = 0.3527777778; % point u milimetar
        mm2pt = 2.8346456693; % milimetar u point
        text_ok = true; % Da li je uneseni tekst validan
        to_write = ''; % tekst koji se ispisuje
        pr_generated = false; % Da li je vec izvrsena generatePR funkcija
                              % za uneseni tekst
        qt_generated = false; % Da li je vec izvrsena simul funkcija za
                              % uneseni tekst
        v3d; % Parametri za visualize3d funkciju
        % fig - Handle za figure za simulaciju
        % ax - Handle za axes unutar figure
        
        posrot; % Vektori pozicija i rotacija koji se salju na robota -
                % Rezultat funkcije generatePR
        potr; % Kolekcija tacaka i tragova - Rezultat funkcije generatePR
        qs; % Vektori unutrasnjih koordinata za simulaciju - Rezultat
            % funkcije simul
        ts; % Tragovi za simulaciju - Rezultat funkcije simul
        sb_timer; % Promjena boje teksta SimulateButton-a u zavisnosti
                  % da li postoje modeli i da li je operacija uspjesno
                  % izvrsena
        rtb_timer; % Promjena boje teksta RobotTransferButton-a u
                   % zavisnosti da li je operacija uspjesno izvrsena
        
        % Pomocne aplikacije ---------------------------------------------
        robot_params_handle; % Aplikacija za podesavanje parametara robota
        robotcontrol_params_handle; % Aplikacija za podesavanje parametara
                                    % kontrole robota
        workspace_params_handle; % Aplikacija za podesavanje parametara
                                 % radnog okruzenja
        robot_transfer_handle; % Aplikacija za podesavanje konekcije sa
                               % robotom i prenosa
    end
    
    properties (Access = public)
        % Parametri teksta ------------------------------------------------
        text_params;
        % def_chr - Svi definisani karakteri
        % def_chr_ca - def_chr kao cell array
        % def_wss = 'i' - Definisani specifikatori stilova pisanja
        %                   i - kurziv
        % * Odsustvo specifikatora stila pisanja podrazumijeva pisanje
        %   normalnim stilom (uspravnim tehnickim pismom)
        % def_aes = 'usd' - Definisani specifikatori dodatnih efekata
        %                     u - podvucena slova
        %                     s - precrtana slova
        %                     d - dvostruko precrtana slova
        % def_fs = 'iusd' - Svi specifikatori formata
        % font_size - Velicina fonta u pt
        
        % Parametri tehnickog pisma --------------------------------------
        tl_params;
        % tl_type - Tip tehnickog pisma
        % h - Visina velikih slova i brojeva
        % w - Prosjecna sirina velikih slova
        % hm - Visina malih slova
        % wm - Prosjecna sirina malih slova i brojeva
        % cd - Rastojanje izmedju znakova
        % sp - Rastojanje izmedju rijeci
        % ld - Rastojanje izmedju redova

        % Parametri robota -----------------------------------------------
        robot_params;
        % dh - DH matrica kinematickih parametara
        % dh_angle_unit - jedinica za theta i alpha uglove iz DH matrice
        % name - Naziv robota
        % offset - Pocetni pomjeraj u zglobovima
        % qlim - Ogranicenja u zglobovima
        % qlim_angle_unit - jedinica za qlim uglove
        % tool - Transformacija alata (markera)
        % tom - Orjentacija alata u toku pisanja
        % to_angle_unit - jedinica za TO uglove
        % R - SerialLink objekat robota
        % rep - Ponovljivost robota
        % qz - Pocetne vrijednosti pomjeraja u zglobovima
        % Tr - Referentni polozaj vrha manipulatora
        % qr - Referentne vrijednosti pomjeraja u zglobovima
        % q - Trenutne vrijednosti pomjeraja u zglobovima
        % models_pth - Putanja do modela segmenata i dodatnih modela
        %              robota, kao i odgovarajucih boja za modele
        
        % Parametri radnog prostora --------------------------------------
        % Jedinica za duzinu je metar
        workspace_params;
        % ws_size - Velicina povrsine za pisanje [visina sirina]
        %           Visina je u pravcu x ose, sa sirina u pravcu y ose
        %           referentnog koordinatnog sistema robota
        % hlm - lijeva margina
        % hrm - desna margina
        % vtm - gornja margina
        % vbm - donja margina
        % hf0 - Pocetni orizontalni pomjeraj
        % vf0 - Pocetni vertikalni pomjeraj
        % g - Gornja lijeva granicna tacka povrsine za pisanje
        % d - Sigurno odstojanje vrha markera od povrsine za pisanje
        % hf - Trenutni horizontalni pomjeraj
        % vf - Trenutni vertikalni pomjeraj
        % go_to_ref - Da li se vratiti u referentni polozaj nakon ispisa
        
        % Parametri kontrole robota --------------------------------------
        robotcontrol_params;
        % ovrd - Ogranicenje brzine za sve metode interpolacije
        % jovrd - Ogranicenje brzine za interpolaciju u unutrasnjim koordinatama
        % spd - Apsolutna brzina vrha manipulatora za pravolinijsku i kruznu interpolaciju
        % accel - Postotak maksimalnog ubrzanja
        % decel - Postotak maksimalnog usporenja
        % interpolation_cmd - Metoda interpolacije
        
        % Parametri konekcije sa robotom ---------------------------------
        robotconnection_params;
        % ip - IP adresa robotskog kontrolera
        % port - port robotskog kontrolera
        % ibs - velicina ulaznog bafera u byte-ima
        % obs - velicina izlaznog bafera u byte-ima
    end
    
    methods (Access = private)
        
        function timer_func(app, obj, event, btn)
            btn.FontColor = 'black';
        end
        
        function timer_stop_func(app, obj, event)
            delete(obj);
        end
        
        function [h,w,hm,wm,cd,sp,ld] = genTLparams(app, tl_type, font_size)
            % Point (pt) je najmanja jedinica mjere u tipografiji i sluzi za
            % definisanje velicine fonta
            
            % h  - visina velikih slova i brojeva
            % w  - prosjecna sirina velikih slova
            % hm - visina malih slova
            % wm - prosjecna sirina malih slova i brojeva
            % cd - rastojanje izmedju slova i/ili brojeva (character distance)
            % sp - rastojanje izmedju rijeci (space)
            % ld - rastojanje izmedju pojedinacnih linija (line distance)
            
            if strcmp(app.FontSizeUnitSwitch.Value,'pt')
                h = font_size*app.pt2mm/1000;
            else
                h = font_size/1000;
            end
            switch tl_type
                case {'а', 'А','a','A'}
                    w  = 6/14*h;
                    hm = 10/14*h;
                    wm = 6/14*h;
                    cd = 2/14*h;
                    sp = 8/14*h;
                    ld = 22/14*h;
                case {'б','Б','b', 'B'}
                    w  = 7/10*h;
                    hm = 7/10*h;
                    wm = 6/10*h;
                    cd = 2/10*h;
                    sp = 7/10*h;
                    ld = 16/10*h;
                case {'ц','Ц','c', 'C'}
                    w  = 5/7*h;
                    hm = 5/7*h;
                    wm = 4/7*h;
                    cd = 2/7*h;
                    sp = 4/7*h;
                    ld = 11/7*h;
            end
        end
        
        function check = checkTextValidity(app, text)
            check = true;
            for chr = text
                if ~contains([app.text_params.def_chr app.text_params.def_fs 'n '],chr)
                    check = false;
                    return;
                end
            end
            for fs = app.text_params.def_fs
                len = length(strfind(text,fs));
            	if mod(len,2) == 0
                    if len > 0
                        [sp,ep] = regexp(text,[fs '[^' fs ']*' fs]);
                        for i = 1 : length(sp)
                            for ifs = erase(app.text_params.def_fs,fs)
                                if mod(length(strfind(text(sp:ep),ifs)),2) ~= 0
                                    check = false;
                                    return;
                                end
                            end
                        end
                    end
                else
                    check = false;
                    return;
            	end
            end
        end
        
        function html = text2html(app, text)
            html_body = text;
            html_body = regexprep(html_body, 'n', '<br>');
            
            for fs = app.text_params.def_fs
                html_body = regexprep(html_body, fs, ['<' fs '>']);
                html_body = regexprep(html_body, ['(<' fs '>.*?)<' fs '>'], ['$1</' fs '>']);
            end
            html_body = regexprep(html_body, '<u>', '<ue>');
            html_body = regexprep(html_body, '</u>', '</ue>');
            html_body = regexprep(html_body, '<d>', '<s style="text-decoration-style: double;">');
            html_body = regexprep(html_body, '</d>', '</s>');
            html_body = regexprep(html_body, ' ', '&nbsp');
            
            if strcmp(app.FontSizeUnitSwitch.Value,'pt')
                font_size = app.FontSizeSpinner.Value;
            else
                font_size = app.FontSizeSpinner.Value*app.mm2pt;
            end
            html = ['<!DOCTYPE html>' ...
                    '<html>' ...
                    '<style>' ...
                    'ue {border-bottom: ' num2str(font_size/12) 'px solid black;}' ...
                    'p {margin: 10px;}' ...
                    '</style>' ...
                    '<body>' ...
                    '<p style="display:inline;white-space:nowrap;font-family:Helvetica;font-size:' num2str(font_size) 'px;">' html_body '</p>' ...
                    '</body>' ...
                    '</html>'];
        end
        
        function initializeRobotParams(app)
            % Inicijalizacija parametara robota
            app.robot_params.dh = [   0        0.295    0       -pi/2      0
                                    -pi/2      0        0.23      0        0
                                     pi/2      0       -0.05     pi/2      0 
                                      0        0.27     0       -pi/2      0
                                      0        0        0        pi/2      0
                                      0        0.07     0         0        0 ];
            app.robot_params.dh_angle_unit = 'rad';
            app.robot_params.name = 'Mitsubishi RV-2SDB';
            app.robot_params.offset = [0 -pi/2 pi/2 0 0 0];
            app.robot_params.qlim = [ -240 240 ;
                                      -120 120 ;
                                         0 160 ;
                                      -200 200 ;
                                      -120 120 ;
                                      -360 360 ] * pi/180;
            app.robot_params.qlim_angle_unit = 'deg';
            app.robot_params.tool = [ 1  0  0  0.030 ;
                                      0  1  0    0   ;
                                      0  0  1  0.176 ;
                                      0  0  0    1   ];
            app.robot_params.tom = [0  1  0 ;
                                    1  0  0 ;
                                    0  0 -1];
            app.robot_params.to_angle_unit = 'rad';
            app.robot_params.R = SerialLink(app.robot_params.dh, 'name', app.robot_params.name, 'offset', app.robot_params.offset, 'qlim', app.robot_params.qlim, 'tool', app.robot_params.tool);
            app.robot_params.R
            app.robot_params.rep = 0.00002;
            app.robot_params.Tz = [1 0 0 -0.02; 0 1 0 0; 0 0 1 1.041; 0 0 0 1];
            app.robot_params.qz = [0 0 0 0 0 0];
            app.robot_params.Tr = [0 1 0 -0.075; 1 0 0 0.21; 0 0 -1 0.3; 0 0 0 1];
            app.robot_params.qr = [1.9656 -0.3391 1.9428 -3.1415 -1.5380 0.3948];
            app.robot_params.T = app.robot_params.Tr;
            app.robot_params.q = app.robot_params.qr;
            app.robot_params.models_pth = fullfile(fileparts(mfilename('fullpath')), 'models');
        end
        
        function restoreview(app,e,d)
            @(e,d)matlab.graphics.controls.internal.resetHelper(d.Axes,true);
            app.RobotSimulationUIAxes.CameraPosition = [1,1,1];
        end
        
        function initializeAxesFigure(app)
            if ~isfield(app.v3d,'fig') || ~ishandle(app.v3d.fig)
                app.v3d.fig = figure();
                app.v3d.ax = axes(app.v3d.fig);
                app.v3d.ax.XLabel.String = 'X';
                app.v3d.ax.YLabel.String = 'Y';
                app.v3d.ax.ZLabel.String = 'Z';
                grid(app.v3d.ax,'on');
                app.v3d.ax.GridAlpha = 0.1;
                app.v3d.ax.DataAspectRatio = [1,1,1]; % daspect(app.v3d.ax,[1,1,1]);
                app.v3d.ax.CameraPosition = [1,1,1]; % campos(app.v3d.ax,[1,1,1]);
                cameratoolbar(app.v3d.fig,'show');
                rotate3d(app.v3d.ax,'on');
                hold(app.v3d.ax,'on');
    %             restoreviewbtn = findall(app.v3d.fig.Toolbar,'Tooltip','Restore View');
    %             restoreviewbtn.ButtonPushedFcn = @app.restoreview;
                
                % Primijeni ogranicenja osa
                axis(app.v3d.ax,app.v3d.axl);
                
                % Kreiraj poplocanu podlogu ----------------------------------
                tile1color = [0.7 0.7 0.7];  % light green
                tile2color = [0.3 0.6 0.7];  % white
                xt = app.v3d.axl(1):app.v3d.tilesize:app.v3d.axl(2);
                yt = app.v3d.axl(3):app.v3d.tilesize:app.v3d.axl(4);
                Z = app.v3d.axl(5)*ones(numel(yt),numel(xt));
                C = zeros(size(Z));
                [r,c] = ind2sub(size(C), 1:numel(C));
                C = bitand(r+c,1);
                C = reshape(C, size(Z));
                C = cat(3, tile1color(1)*C+tile2color(1)*(1-C), ...
                           tile1color(2)*C+tile2color(2)*(1-C), ...
                           tile1color(3)*C+tile2color(3)*(1-C));
                [X,Y] = meshgrid(xt, yt);
                fl = surface(app.v3d.ax, X, Y, Z, C, ...
                        'FaceColor','texturemap',...
                        'EdgeColor','none',...
                        'CDataMapping','direct');
                material(fl,[0.5 0.5 0]);
                
                % Podesi osvjetljenje ----------------------------------------
                light(app.v3d.ax,'Style','infinite','Position',[0 0 app.v3d.reach]);
                light(app.v3d.ax,'Style','infinite','Position',[0 -app.v3d.reach 0]);
                light(app.v3d.ax,'Style','infinite','Position',[0 app.v3d.reach 0]);
                light(app.v3d.ax,'Style','infinite','Position',[app.v3d.reach 0 0]);
                light(app.v3d.ax,'Style','infinite','Position',[-app.v3d.reach 0 0]);
                
                app.v3d.group = hggroup(app.v3d.ax, 'Tag', app.robot_params.name);
                app.v3d.link = [];
                app.v3d.extra = [];
                for i = 1 : app.v3d.nlinks
                    if i == 1
                        p = patch(app.v3d.ax, 'Faces', app.robot_params.R.faces{i}, 'vertices', app.robot_params.R.points{i}, ...
                            'FaceColor', app.v3d.color(mod(i-1,app.v3d.ncolors)+1,:), 'EdgeAlpha', 0, 'FaceAlpha', 1, 'FaceLighting', 'gouraud');
                    else
                        app.v3d.link(i-1) = hgtransform(app.v3d.ax, 'Tag', sprintf('link%d', i-1), 'Parent', app.v3d.group);
            
                        p = patch(app.v3d.ax, 'Faces', app.robot_params.R.faces{i}, 'vertices', app.robot_params.R.points{i}, ...
                            'FaceColor', app.v3d.color(mod(i-1,app.v3d.ncolors)+1,:), 'EdgeAlpha', 0, 'FaceAlpha', 1, ...
                            'Parent', app.v3d.link(i-1), 'FaceLighting', 'gouraud');
                    end
                    material(p, [0.7 0.3 0.1]);
                end
                for i = app.v3d.nlinks+1 : app.v3d.nshapes
                    if(app.v3d.extras_parent{i-app.v3d.nlinks}==0)
                        p = patch(app.v3d.ax, 'Faces', app.robot_params.R.faces{i}, 'vertices', app.robot_params.R.points{i}, ...
                            'FaceColor', app.v3d.color(mod(i-1,app.v3d.ncolors)+1,:), 'EdgeAlpha', 0, 'FaceAlpha', 1, 'FaceLighting', 'gouraud');
                    else
                        app.v3d.extra(i-1) = hgtransform(app.v3d.ax, 'Tag', app.v3d.extras_name{1,i-app.v3d.nlinks}, 'Parent', app.v3d.group);
            
                        p = patch(app.v3d.ax, 'Faces', app.robot_params.R.faces{i}, 'vertices', app.robot_params.R.points{i}, ...
                            'FaceColor', app.v3d.color(mod(i-1,app.v3d.ncolors)+1,:), 'EdgeAlpha', 0, 'FaceAlpha', 1, ...
                            'Parent', app.v3d.link(app.v3d.extras_parent{i-app.v3d.nlinks}), 'FaceLighting', 'gouraud');
                    end
                    material(p, [0.7 0.3 0.1]);
                end
                app.v3d.link = [0 app.v3d.link];
                
                % Transformacija baze u odnosu na referentni sistem (jedinicna matrica)
                Tc = app.robot_params.R.base;
    
                for i = 1 : app.robot_params.R.n
                    if app.v3d.link(i) ~= 0
                        % Ukoliko se ne radi o bazi, azuriraj polozaj segmenta
                        set(app.v3d.link(i), 'Matrix', Tc.T);
                    end
                    % Sada podesi transformaciju na sljedeci segment u
                    % odnosu na bazu
                    Tc = Tc * app.robot_params.R.links(i).A(app.robot_params.q(i));
                end
    
                % Azuriraj polozaj markera
                Tc = Tc * SE3(app.robot_params.tool);
                if length(app.v3d.link) > app.robot_params.R.n
                    set(app.v3d.link(app.robot_params.R.n+1), 'Matrix', Tc.T);
                end
            end
        end
        
        function initializeV3DParams(app)
            % Inicijalizacija parametara visualize3d funkcije       
            links_pth = fullfile(app.robot_params.models_pth,'links');
            app.v3d.nlinks = sum(cellfun(@(name)(contains(name,'.stl','IgnoreCase',true)), {dir(links_pth).name}));
            if app.v3d.nlinks ~= app.robot_params.R.n+1
                app.v3d.links_ok = false;
            else
                app.v3d.links_ok = true;
            end
            
            if app.v3d.links_ok
                extras_pth = fullfile(app.robot_params.models_pth,'extras');
                extras = {dir(extras_pth).name};
                extras(cellfun(@(name)(~contains(name,'.stl','IgnoreCase',true)), extras)) = [];
                app.v3d.nextras = length(extras);
                
                app.v3d.nshapes = app.v3d.nlinks + app.v3d.nextras;
                
                % Sada ucitaj STL fajlove
                app.robot_params.R.points = cell(1, app.v3d.nshapes);
                app.robot_params.R.faces = cell(1, app.v3d.nshapes);
                fprintf('Loading STL models');
                for i = 1 : app.v3d.nlinks
                    [P,F] = stlRead(fullfile(links_pth, sprintf('link%d.stl', i-1)));
                    app.robot_params.R.points{i} = P;
                    app.robot_params.R.faces{i} = F;
                    fprintf('.');
                end
                for i = app.v3d.nlinks+1 : app.v3d.nshapes
                    app.v3d.extras_name(1,i-app.v3d.nlinks) = {extras{1,i-app.v3d.nlinks}(1:end-4)};
                    app.v3d.extras_parent(1,i-app.v3d.nlinks) = {str2double(extras{1,i-app.v3d.nlinks}(end-5))};
                    [P,F] = stlRead(fullfile(extras_pth, [app.v3d.extras_name{i-app.v3d.nlinks} '.stl']));
                    app.robot_params.R.points{i} = P;
                    app.robot_params.R.faces{i} = F;
                    fprintf('.');
                end
                fprintf('\n');
                
                % Podesi boje ucitanih modela
                colors_pth = fullfile(app.robot_params.models_pth,'colors');
                lcolors_pth = fullfile(colors_pth,'links.txt');
                ecolors_pth = fullfile(colors_pth,'extras.txt');
                if isfile(lcolors_pth)
                    fileID = fopen(lcolors_pth,'r');
                    lcolors = splitlines(fscanf(fileID,'%c'));
                    if size(lcolors,1) ~= app.v3d.nlinks
                        for i = 1:app.robot_params.R.n
                            app.v3d.color(i,:) = [192 191 178];
                        end
                        app.v3d.color(app.robot_params.R.n+1,:) = [32 128 255];
                    else
                        for i = 1:app.v3d.nlinks
                            app.v3d.color(i,:) = str2num(lcolors{i});
                        end
                    end
                end
                if isfile(ecolors_pth)
                    fileID = fopen(ecolors_pth,'r');
                    ecolors = splitlines(fscanf(fileID,'%c'));
                    if size(ecolors,1) ~= app.v3d.nextras
                        app.v3d.color(app.v3d.nlinks+1:app.v3d.nshapes,1:3) = [[255 255 255]; [223 0 36]; [255 255 255]; [32 32 32]; [255 255 255]; [128 128 128]; [128 128 128]; [223 0 36]; [32 32 32]];
                    else
                        for i = app.v3d.nlinks+1:app.v3d.nshapes
                            app.v3d.color(i,:) = str2num(ecolors{i-app.v3d.nlinks});
                        end
                    end
                end
                app.v3d.color = app.v3d.color/255;
                app.v3d.ncolors = numrows(app.v3d.color);
                
                % Podesi ostale parametre
                app.v3d.tilesize = 0.2;
                app.v3d.reach = 0;
                for i=1:app.robot_params.R.n
                	app.v3d.reach = app.v3d.reach + abs(app.robot_params.R.links(i).d) + abs(app.robot_params.R.links(i).a);
                end
                app.v3d.reach = app.v3d.tilesize * ceil(app.v3d.reach/app.v3d.tilesize);
                
                % Podesi ogranicenja osa
                app.v3d.axl = repmat([-app.v3d.reach app.v3d.reach], [1 3]);
                mn = min(app.robot_params.R.points{1});
                app.v3d.axl(5) = mn(3);
                app.v3d.floorlevel = mn(3);
                
                app.v3d.trailstyle = {'color','blue','LineWidth',0.05};
            end
        end
        
        function initializeTextParams(app)
            % Inicijalizacija parametara teksta
            app.text_params.def_chr = 'АБВГДЂЕЖЗИЈКЛЉМНЊОПРСТЋУФХЦЧЏШ0123456789-.:!';
            app.text_params.def_chr_ca = cellstr(app.text_params.def_chr')';
            app.text_params.def_wss = 'i';
            app.text_params.def_aes = 'usd';
            app.text_params.def_fs = [app.text_params.def_wss app.text_params.def_aes];
            app.text_params.font_size = 48;
        end
        
        function initializeTLParams(app)
            % Inicijalizacija parametara tehnickog pisma
            app.tl_params.tl_type = 'A';
            [app.tl_params.h, ...
            app.tl_params.w, ...
            app.tl_params.hm, ...
            app.tl_params.wm, ...
            app.tl_params.cd, ...
            app.tl_params.sp, ...
            app.tl_params.ld] = app.genTLparams(app.tl_params.tl_type, app.text_params.font_size);
        end
        
        function initializeWorkspaceParams(app)
             % Inicijalizacija parametara radnog prostora
            app.workspace_params.ws_size = [0.297, 0.21];
            app.workspace_params.hlm = app.tl_params.sp;
            app.workspace_params.hrm = app.tl_params.sp;
            app.workspace_params.vtm = app.tl_params.sp;
            app.workspace_params.vbm = app.tl_params.sp;
            app.workspace_params.hlm_sw = true;
            app.workspace_params.hrm_sw = true;
            app.workspace_params.vtm_sw = true;
            app.workspace_params.vbm_sw = true;
            app.workspace_params.hf0 = app.workspace_params.hlm;
            app.workspace_params.vf0 = app.workspace_params.vtm + app.tl_params.h;
            app.workspace_params.g = [0  0  0 -0.075;
                                      0  0  0  0.21;
                                      0  0  0  0.007;
                                      0  0  0  0];
            app.workspace_params.d = 0.01;
            app.workspace_params.hf = app.workspace_params.hf0;
            app.workspace_params.vf = app.workspace_params.vf0;
            app.workspace_params.go_to_ref = 1;
        end
        
        function initializeRobotControlParams(app)
            % Inicijalizacija parametara kontrole robota
            app.robotcontrol_params.ovrd = 100;
            app.robotcontrol_params.jovrd = 100;
            app.robotcontrol_params.spd = 100;
            app.robotcontrol_params.accel = 2;
            app.robotcontrol_params.decel = 2;
            app.robotcontrol_params.interpolation_cmd = 'MVS';
        end
        
        function initializeRobotConnectionParams(app)
            % Inicijalizacija parametara konekcije sa robotom
            app.robotconnection_params.ip = '10.1.1.120';
            app.robotconnection_params.port = 10001;
            app.robotconnection_params.ibs = 1024;
            app.robotconnection_params.obs = 1024;
        end
        
        function [points, trails] = genchr(app, chr, it, ch, cw, hf, vf, d)
            rotper = [app.robot_params.tom; [0  0  0]];
            
            switch chr
                case 'А'
            		tana = ch/(3/6*cw);
            		transcl = [	      0                  0  d  1 ;
            					      0                  0  0  1 ;
            					     ch             3/6*cw  0  1 ;
            					      0                 cw  0  1 ;
            					      0                 cw  d  1 ;
            					5/18*ch     (5/18*ch)/tana  d  1 ;
            					5/18*ch     (5/18*ch)/tana  0  1 ;
            					5/18*ch  cw-(5/18*ch)/tana  0  1 ;
            					5/18*ch  cw-(5/18*ch)/tana  d  1 ];
            		pnum = 9;
            		trails = [0 0 1 1 0 0 0 1 0];
            		
                case 'Б'
            		transcl = [	      ch   4/6*cw  d  1 ;
            					      ch   4/6*cw  0  1 ;
            					      ch        0  0  1 ;
            					       0        0  0  1 ;
            					       0  7/12*cw  0  1 ;
            					 3/18*ch   5/6*cw  0  1 ;
            					 7/18*ch   5/6*cw  0  1 ;
            					10/18*ch  7/12*cw  0  1 ;
            					10/18*ch        0  0  1 ;
            					10/18*ch        0  d  1 ];
            		pnum = 10;         
            		trails = [0 0 1 1 1 1 1 1 1 0];
            		
                case 'В'
            		transcl = [	10/18*ch   3/6*cw  d  1 ;
            					10/18*ch   3/6*cw  0  1 ;
            					13/18*ch  9/12*cw  0  1 ;
            					15/18*ch  9/12*cw  0  1 ;
            					      ch   3/6*cw  0  1 ;
            					      ch        0  0  1 ;
            					       0        0  0  1 ;
            					       0  7/12*cw  0  1 ;
            					 3/18*ch   5/6*cw  0  1 ;
            					 7/18*ch   5/6*cw  0  1 ;
            					10/18*ch  7/12*cw  0  1 ;
            					10/18*ch        0  0  1 ;
            					10/18*ch        0  d  1 ];
            		pnum = 13;
            		trails = [0 0 1 1 1 1 1 1 1 1 1 1 0];
            		
                case 'Г'
            		transcl = [	 0       0  d  1 ;
            					 0       0  0  1 ;
            					ch       0  0  1 ;
            					ch  4/6*cw  0  1 ;
            					ch  4/6*cw  d  1 ];
            		pnum = 5;
            		trails = [0 0 1 1 0];
            		
                case 'Д'
            		transcl = [	-1/9*ch       0  d  1 ;
            					-1/9*ch       0  0  1 ;
            					      0       0  0  1 ;
            					      0      cw  0  1 ;
            					-1/9*ch      cw  0  1 ;
            					-1/9*ch      cw  d  1 ;
            					      0  1/6*cw  d  1 ;
            					      0  1/6*cw  0  1 ;
            					     ch  2/6*cw  0  1 ;
            					     ch  5/6*cw  0  1 ;
            					      0  5/6*cw  0  1 ;
            					      0  5/6*cw  d  1 ];
            		pnum = 12;
            		trails = [0 0 1 1 1 0 0 0 1 1 1 0];
                    
                case 'Ђ'
            		transcl = [	     ch       0  d  1 ;
            					     ch       0  0  1 ;
            					     ch  5/6*cw  0  1 ;
            					     ch  5/6*cw  d  1 ;
            					     ch  2/6*cw  d  1 ;
            					     ch  2/6*cw  0  1 ;
            					      0  2/6*cw  0  1 ;
            					      0  2/6*cw  d  1 ;
            					 7/9*ch  2/6*cw  d  1 ;
            					 7/9*ch  2/6*cw  0  1 ;
            					 7/9*ch      cw  0  1 ;
            					 6/9*ch  7/6*cw  0  1 ;
            					 1/9*ch  7/6*cw  0  1 ;
            					      0      cw  0  1 ;
            					      0  4/6*cw  0  1 ;
            					      0  4/6*cw  d  1 ];
            		pnum = 16;
            		trails = [0 0 1 0 0 0 1 0 0 0 1 1 1 1 1 0];
            		
                case 'Е'
            		transcl = [	    ch  4/6*cw  d  1 ;
            					    ch  4/6*cw  0  1 ;
            					    ch       0  0  1 ;
            					     0       0  0  1 ;
            					     0  4/6*cw  0  1 ;
            					     0  4/6*cw  d  1 ;
            					1/2*ch       0  d  1 ;
            					1/2*ch       0  0  1 ;
            					1/2*ch  3/6*cw  0  1 ;
            					1/2*ch  3/6*cw  d  1 ];
            		pnum = 10;
                    trails = [0 0 1 1 1 0 0 0 1 0];
            		
                case 'Ж'
            		transcl = [	    ch        0  d  1 ;
            					    ch        0  0  1 ;
            					5/9*ch   3/6*cw  0  1 ;
            					     0        0  0  1 ;
            					     0        0  d  1 ;
            					    ch  7/12*cw  d  1 ;
            					    ch  7/12*cw  0  1 ;
            					     0  7/12*cw  0  1 ;
            					     0  7/12*cw  d  1 ;
            					    ch   7/6*cw  d  1 ;
            					    ch   7/6*cw  0  1 ;
            					5/9*ch   4/6*cw  0  1 ;
            					     0   7/6*cw  0  1 ;
            					     0   7/6*cw  d  1 ;
            					5/9*ch   3/6*cw  d  1 ;
            					5/9*ch   3/6*cw  0  1 ;
            					5/9*ch   4/6*cw  0  1 ;
            					5/9*ch   4/6*cw  d  1 ];
            		pnum = 18;
            		trails = [0 0 1 1 0 0 0 1 0 0 0 1 1 0 0 0 1 0];
            		
                case 'З'
            		transcl = [	17/18*ch        0  d  1 ;
            					17/18*ch        0  0  1 ;
            					      ch   1/6*cw  0  1 ;
            					      ch   3/6*cw  0  1 ;
            					  8/9*ch  9/12*cw  0  1 ;
            					  6/9*ch  9/12*cw  0  1 ;
            					  5/9*ch   3/6*cw  0  1 ;
            					  5/9*ch   1/6*cw  0  1 ;
            					  5/9*ch   3/6*cw  0  1 ;
            					15/36*ch  9/12*cw  0  1 ;
            					 5/36*ch  9/12*cw  0  1 ;
            					       0   3/6*cw  0  1 ;
            					       0   1/6*cw  0  1 ;
            					 1/18*ch        0  0  1 ;
            					 1/18*ch        0  d  1 ];
            		pnum = 15;
            		trails = [0 0 1 1 1 1 1 1 1 1 1 1 1 1 0];
            		
                case 'И'
            		transcl = [	ch       0  d  1 ;
            					ch       0  0  1 ;
            					 0       0  0  1 ;
            					ch  5/6*cw  0  1 ;
            					 0  5/6*cw  0  1 ;
            					 0  5/6*cw  d  1 ];
            		pnum = 6;
            		trails = [0 0 1 1 1 0];
            		
                case 'Ј'
            		transcl = [	     ch   5/6*cw  d  1 ;
            					     ch   5/6*cw  0  1 ;
            					3/18*ch   5/6*cw  0  1 ;
            					      0  7/12*cw  0  1 ;
            					      0  3/12*cw  0  1 ;
            					 1/9*ch        0  0  1 ;
            					 1/9*ch        0  d  1 ];
            		pnum = 7;
            		trails = [0 0 1 1 1 1 0];
            		
                case 'К'
            		tana = (1/2*ch)/(5/6*cw);
            		transcl = [	                 ch        0  d  1 ;
            					                 ch        0  0  1 ;
            					                  0        0  0  1 ;
            					                  0        0  d  1 ;
            					                 ch   5/6*cw  d  1 ;
            					                 ch   5/6*cw  0  1 ;
            					             1/2*ch        0  0  1 ;
            					             1/2*ch        0  d  1 ;
            					1/2*ch+3/12*cw*tana  3/12*cw  d  1 ;
            					1/2*ch+3/12*cw*tana  3/12*cw  0  1 ;
            					                  0   5/6*cw  0  1 ;
            					                  0   5/6*cw  d  1 ];
            		pnum = 12;
            		trails = [0 0 1 0 0 0 1 0 0 0 1 0];
            		
                case 'Л'
            		transcl = [	    0        0  d  1 ;
            					    0        0  0  1 ;
            					   ch  5/12*cw  0  1 ;
            					   ch   5/6*cw  0  1 ;
            					    0   5/6*cw  0  1 ;
            					    0   5/6*cw  d  1 ];
            		pnum = 6;
            		trails = [0 0 1 1 1 0];
            		
                case 'Љ'
            		transcl = [	     0        0  d  1 ;
            					     0        0  0  1 ;
            					    ch  5/12*cw  0  1 ;
            					    ch   5/6*cw  0  1 ;
            					     0   5/6*cw  0  1 ;
            					     0   8/6*cw  0  1 ;
            					1/9*ch   9/6*cw  0  1 ;
            					4/9*ch   9/6*cw  0  1 ;
            					5/9*ch   8/6*cw  0  1 ;
            					5/9*ch   5/6*cw  0  1 ;
            					5/9*ch   5/6*cw  d  1 ];
            		pnum = 11;
            		trails = [0 0 1 1 1 1 1 1 1 1 0];
            		
                case 'М'
            		transcl = [	     0       0  d  1 ;
            					     0       0  0  1 ;
            					    ch       0  0  1 ;
            					4/9*ch  1/2*cw  0  1 ;
            					    ch      cw  0  1 ;
            					     0      cw  0  1 ;
            					     0      cw  d  1 ];
            		pnum = 7;
            		trails = [0 0 1 1 1 1 0];
            		
                case 'Н'
            		transcl = [	    ch       0  d  1 ;
            					    ch       0  0  1 ;
            					     0       0  0  1 ;
            					     0       0  d  1 ;
            					    ch  5/6*cw  d  1 ;
            					    ch  5/6*cw  0  1 ;
            					     0  5/6*cw  0  1 ;
            					     0  5/6*cw  d  1 ;
            					5/9*ch   0  d  1 ;
            					5/9*ch   0  0  1 ;
            					5/9*ch  5/6*cw  0  1 ;
            					5/9*ch  5/6*cw  d  1 ];
            		pnum = 12;
            		trails = [0 0 1 0 0 0 1 0 0 0 1 0];
            		
                case 'Њ'
            		transcl = [	    ch       0  d  1 ;
            					    ch       0  0  1 ;
            					     0       0  0  1 ;
            					     0       0  d  1 ;
            					    ch  5/6*cw  d  1 ;
            					    ch  5/6*cw  0  1 ;
            					     0  5/6*cw  0  1 ;
            					     0  8/6*cw  0  1 ;
            					1/9*ch  9/6*cw  0  1 ;
            					4/9*ch  9/6*cw  0  1 ;
            					5/9*ch  8/6*cw  0  1 ;
            					5/9*ch       0  0  1 ;
            					5/9*ch       0  d  1 ];
            		pnum = 13;
            		trails = [0 0 1 0 0 0 1 1 1 1 1 1 0];
            		
                case 'О'
            		transcl = [	      ch  4/6*cw  d  1 ;
            					      ch  4/6*cw  0  1 ;
            					      ch  1/6*cw  0  1 ;
            					15/18*ch       0  0  1 ;
            					 3/18*ch       0  0  1 ;
            					       0  1/6*cw  0  1 ;
            					       0  4/6*cw  0  1 ;
            					 3/18*ch  5/6*cw  0  1 ;
            					15/18*ch  5/6*cw  0  1 ;
            					      ch  4/6*cw  0  1 ;
            						  ch  4/6*cw  d  1 ];
            		pnum = 11;
            		trails = [0 0 1 1 1 1 1 1 1 1 0];
            		
                case 'П'
            		transcl = [	 0       0  d  1 ;
            					 0       0  0  1 ;
            					ch       0  0  1 ;
            					ch  5/6*cw  0  1 ;
            					 0  5/6*cw  0  1 ;
            					 0  5/6*cw  d  1 ];
            		pnum = 6;
            		trails = [0 0 1 1 1 0];
            		
                case 'Р'
            		transcl = [	     0       0  d  1 ;
            					     0       0  0  1 ;
            					    ch       0  0  1 ;
            					    ch  4/6*cw  0  1 ;
            					8/9*ch  5/6*cw  0  1 ;
            					5/9*ch  5/6*cw  0  1 ;
            					4/9*ch  4/6*cw  0  1 ;
            					4/9*ch       0  0  1 ;
            					4/9*ch       0  d  1 ];
            		pnum = 9;
            		trails = [0 0 1 1 1 1 1 1 0];
            		
                case 'С'
            		transcl = [	    ch  4/6*cw  d  1 ;
            					    ch  4/6*cw  0  1 ;
            					    ch  1/6*cw  0  1 ;
            					8/9*ch       0  0  1 ;
            					1/9*ch       0  0  1 ;
            					     0  1/6*cw  0  1 ;
            					     0  4/6*cw  0  1 ;
            					     0  4/6*cw  d  1 ];
            		pnum = 8;
            		trails = [0 0 1 1 1 1 1 0];
            		
                case 'Т'
            		transcl = [	ch        0  d  1 ;
            					ch        0  0  1 ;
            					ch   5/6*cw  0  1 ;
            					ch   5/6*cw  d  1 ;
            					ch  5/12*cw  d  1 ;
            					ch  5/12*cw  0  1 ;
            					 0  5/12*cw  0  1 ;
            					 0  5/12*cw  d  1 ];
            		pnum = 8;
            		trails = [0 0 1 0 0 0 1 0];
            		
                case 'Ћ'
            		transcl = [	      ch        0  d  1 ;
            					      ch        0  0  1 ;
            					      ch   5/6*cw  0  1 ;
            					      ch   5/6*cw  d  1 ;
            					      ch   2/6*cw  d  1 ;
            					      ch   2/6*cw  0  1 ;
            					       0   2/6*cw  0  1 ;
            					       0   2/6*cw  d  1 ;
            					  5/9*ch   2/6*cw  d  1 ;
            					  5/9*ch   2/6*cw  0  1 ;
            					  5/9*ch       cw  0  1 ;
            					  4/9*ch   7/6*cw  0  1 ;
            					       0   7/6*cw  0  1 ;
              					       0   7/6*cw  d  1 ];
            		pnum = 14;
            		trails = [0 0 1 0 0 0 1 0 0 0 1 1 1 0];
            		
                case 'У'
            		transcl = [	     ch       0  d  1 ;
            					     ch       0  0  1 ;
            					 5/9*ch       0  0  1 ;
            					 4/9*ch  1/6*cw  0  1 ;
            					 4/9*ch  5/6*cw  0  1 ;
            					 4/9*ch  5/6*cw  d  1 ;
            					     ch  5/6*cw  d  1 ;
            					     ch  5/6*cw  0  1 ;
            					 1/9*ch  5/6*cw  0  1 ;
            					      0  4/6*cw  0  1 ;
            					      0  1/6*cw  0  1 ;
            					      0  1/6*cw  d  1 ];
            		pnum = 12;
            		trails = [0 0 1 1 1 0 0 0 1 1 1 0];
            		
                case 'Ф'
            		transcl = [	      ch  7/12*cw  d  1 ;
            					      ch  7/12*cw  0  1 ;
            					       0  7/12*cw  0  1 ;
            					       0  7/12*cw  d  1 ;
            					15/18*ch  7/12*cw  d  1 ;
            					15/18*ch  7/12*cw  0  1 ;
            					15/18*ch   2/6*cw  0  1 ;
            					11/18*ch        0  0  1 ;
            					 7/18*ch        0  0  1 ;
            					 3/18*ch   2/6*cw  0  1 ;
            					 3/18*ch   5/6*cw  0  1 ;
            					 7/18*ch   7/6*cw  0  1 ;
            					11/18*ch   7/6*cw  0  1 ;
            					15/18*ch   5/6*cw  0  1 ;
            					15/18*ch  7/12*cw  0  1 ;
            					15/18*ch  7/12*cw  d  1 ];
            		pnum = 16;
                    trails = [0 0 1 0 0 0 1 1 1 1 1 1 1 1 1 0];
            		
                case 'Х'
            		transcl = [	ch   0  d  1 ;
            					ch   0  0  1 ;
            					 0  cw  0  1 ;
            					 0  cw  d  1 ;
            					 0   0  d  1 ;
            					 0   0  0  1 ;
            					ch  cw  0  1 ;
            					ch  cw  d  1 ];
            		pnum = 8;
            		trails = [0 0 1 0 0 0 1 0];
            		
                case 'Ц'
            		transcl = [	       ch       0  d  1 ;
            					       ch       0  0  1 ;
            					        0       0  0  1 ;
            					        0      cw  0  1 ;
            					 -3/18*ch      cw  0  1 ;
            					 -3/18*ch      cw  d  1 ;
            					       ch  5/6*cw  d  1 ;
            					       ch  5/6*cw  0  1 ;
            					        0  5/6*cw  0  1 ;
            					        0  5/6*cw  d  1 ];
            		pnum = 10;
            		trails = [0 0 1 1 1 0 0 0 1 0];
            		
            	case 'Ч'
            		transcl = [	    ch       0  d  1 ;
            					    ch       0  0  1 ;
            					5/9*ch       0  0  1 ;
            					4/9*ch  1/6*cw  0  1 ;
            					4/9*ch  5/6*cw  0  1 ;
            					4/9*ch  5/6*cw  d  1 ;
            					    ch  5/6*cw  d  1 ;
            					    ch  5/6*cw  0  1 ;
            					     0  5/6*cw  0  1 ;
            					     0  5/6*cw  d  1 ];
            		pnum = 10;
            		trails = [0 0 1 1 1 0 0 0 1 0];
            		
                case 'Џ'
            		transcl = [	     ch        0  d  1 ;
            					     ch        0  0  1 ;
            					      0        0  0  1 ;
            					      0   5/6*cw  0  1 ;
            					     ch   5/6*cw  0  1 ;
            					     ch   5/6*cw  d  1 ;
            					      0  5/12*cw  d  1 ;
            					      0  5/12*cw  0  1 ;
            					-1/9*ch  5/12*cw  0  1 ;
            					-1/9*ch  5/12*cw  d  1 ];
            		pnum = 10;
            		trails = [0 0 1 1 1 0 0 0 1 0];
            		
                case 'Ш'
            		transcl = [	ch        0  d  1 ;
            					ch        0  0  1 ;
            					 0        0  0  1 ;
            					 0   7/6*cw  0  1 ;
            					ch   7/6*cw  0  1 ;
            					ch   7/6*cw  d  1 ;
            					ch  7/12*cw  d  1 ;
            					ch  7/12*cw  0  1 ;
            					 0  7/12*cw  0  1 ;
            					 0  7/12*cw  d  1 ];
            		pnum = 10;
            		trails = [0 0 1 1 1 0 0 0 1 0];
            		
            	case '0'
            		transcl = [	    ch  3/6*cw  d  1 ;
            					    ch  3/6*cw  0  1 ;
            					    ch  1/6*cw  0  1 ;
            					7/9*ch       0  0  1 ;
            					2/9*ch       0  0  1 ;
            					     0  1/6*cw  0  1 ;
            						 0  3/6*cw  0  1 ;
            					2/9*ch  4/6*cw  0  1 ;
            					7/9*ch  4/6*cw  0  1 ;
            					    ch  3/6*cw  0  1 ;
            					    ch  3/6*cw  d  1 ];
            		pnum = 11;
            		trails = [0 0 1 1 1 1 1 1 1 1 0];
            		
            	case '1'
            		transcl = [	7/9*ch       0  d  1 ;
            					7/9*ch       0  0  1 ;
            					    ch  2/6*cw  0  1 ;
            						 0  2/6*cw  0  1 ;
            						 0  2/6*cw  d  1 ];
            		pnum = 5;
            		trails = [0 0 1 1 0];
            		
            	case '2'
            		transcl = [	15/18*ch       0  d  1 ;
            					15/18*ch       0  0  1 ;
            					      ch  1/6*cw  0  1 ;
            					      ch  3/6*cw  0  1 ;
            					15/18*ch  4/6*cw  0  1 ;
            					13/18*ch  4/6*cw  0  1 ;
            					       0       0  0  1 ;
            					       0  4/6*cw  0  1 ;
            					       0  4/6*cw  d  1 ];
            		pnum = 9;
            		trails = [0 0 1 1 1 1 1 1 0];
            		
                case '3'
            		transcl = [	      ch        0  d  1 ;
            					      ch        0  0  1 ;
            					      ch  5/12*cw  0  1 ;
            					15/18*ch   4/6*cw  0  1 ;
            					13/18*ch   4/6*cw  0  1 ;
            					10/18*ch  5/12*cw  0  1 ;
            					10/18*ch  1/12*cw  0  1 ;
            					10/18*ch  5/12*cw  0  1 ;
            					 7/18*ch   4/6*cw  0  1 ;
            					 3/18*ch   4/6*cw  0  1 ;
            					       0  5/12*cw  0  1 ;
            					       0        0  0  1 ;
            					       0        0  d  1 ];
            		pnum = 13;
            		trails = [0 0 1 1 1 1 1 1 1 1 1 1 0];
            		
            	case '4'
            		transcl = [	     ch   2/6*cw  d  1 ;
            					     ch   2/6*cw  0  1 ;
            					 2/9*ch        0  0  1 ;
            					 2/9*ch   5/6*cw  0  1 ;
            					 2/9*ch   5/6*cw  d  1 ;
            					 5/9*ch  7/12*cw  d  1 ;
            					 5/9*ch  7/12*cw  0  1 ;
            					      0  7/12*cw  0  1 ;
            					      0  7/12*cw  d  1 ];
            		pnum = 9;
            		trails = [0 0 1 1 0 0 0 1 0];
            		
            	case '5'
            		transcl = [	     ch   4/6*cw  d  1 ;
            					     ch   4/6*cw  0  1 ;
            					     ch        0  0  1 ;
            					 5/9*ch        0  0  1 ;
            					 5/9*ch  5/12*cw  0  1 ;
            					7/18*ch   4/6*cw  0  1 ;
            					3/18*ch   4/6*cw  0  1 ;
            					      0  5/12*cw  0  1 ;
            					      0        0  0  1 ;
            					      0        0  d  1 ];
            		pnum = 10;
            		trails = [0 0 1 1 1 1 1 1 1 0];
            		
            	case '6'
            		transcl = [	    ch   3/6*cw  d  1 ;
            					    ch   3/6*cw  0  1 ;
            					    ch  3/12*cw  0  1 ;
            					7/9*ch        0  0  1 ;
            					1/9*ch        0  0  1 ;
            					     0   1/6*cw  0  1 ;
            					     0   3/6*cw  0  1 ;
            					1/9*ch   4/6*cw  0  1 ;
            					4/9*ch   4/6*cw  0  1 ;
            					5/9*ch   3/6*cw  0  1 ;
            					5/9*ch        0  0  1 ;
            					5/9*ch        0  d  1 ];
            		pnum = 12;
            		trails = [0 0 1 1 1 1 1 1 1 1 1 0];
            		
            	case '7'
            		transcl = [	ch       0  d  1 ;
            					ch       0  0  1 ;
            					ch  4/6*cw  0  1 ;
            					 0       0  0  1 ;
            					 0       0  d  1 ];
            		pnum = 5;
            		trails = [0 0 1 1 0];
            		
            	case '8'
            		transcl = [	 5/9*ch  3/12*cw  d  1 ;
            					 5/9*ch  3/12*cw  0  1 ;
            					 6/9*ch  1/12*cw  0  1 ;
            					 8/9*ch  1/12*cw  0  1 ;
            					     ch  3/12*cw  0  1 ;
            					     ch  5/12*cw  0  1 ;
            					 8/9*ch  7/12*cw  0  1 ;
            					 6/9*ch  7/12*cw  0  1 ;
            					 5/9*ch  5/12*cw  0  1 ;
            					 5/9*ch  3/12*cw  0  1 ;
            					7/18*ch        0  0  1 ;
            					3/18*ch        0  0  1 ;
            					      0  3/12*cw  0  1 ;
            					      0  5/12*cw  0  1 ;	
            					3/18*ch   4/6*cw  0  1 ;
            					7/18*ch   4/6*cw  0  1 ;
            					 5/9*ch  5/12*cw  0  1 ;
            					 5/9*ch  5/12*cw  d  1 ];
            		pnum = 18;
            		trails = [0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0];
            		
            	case '9'
            		transcl = [	4/9*ch   4/6*cw  d  1 ;
            					4/9*ch   4/6*cw  0  1 ;
            					4/9*ch   1/6*cw  0  1 ;
            					5/9*ch        0  0  1 ;
            					8/9*ch        0  0  1 ;
            					    ch   1/6*cw  0  1 ;
            					    ch   3/6*cw  0  1 ;
            					8/9*ch   4/6*cw  0  1 ;
            					2/9*ch   4/6*cw  0  1 ;
            					     0  5/12*cw  0  1 ;
            					     0   1/6*cw  0  1 ;
            					     0   1/6*cw  d  1 ];
            		pnum = 12;
            		trails = [0 0 1 1 1 1 1 1 1 1 1 0];
            		
                case '-'
            		transcl = [	9/18*ch       0  d  1 ;
            					9/18*ch       0  0  1 ;
            					9/18*ch  4/6*cw  0  1 ;
            					9/18*ch  4/6*cw  d  1 ];
            		pnum = 4;
            		trails = [0 0 1 0];
            		
            	case '.'
            		transcl = [	0  0  d  1 ;
            					0  0  0  1 ;
            					0  0  0  1 ;
            					0  0  d  1 ];
            		pnum = 4;
            		trails = [0 0 1 0];
            		
            	case ':'
            		transcl = [	4/9*ch  0  d  1 ;
            					4/9*ch  0  0  1 ;
            					4/9*ch  0  0  1 ;
            					4/9*ch  0  d  1 ;
            					     0  0  d  1 ;
            					     0  0  0  1 ;
            					     0  0  0  1 ;
            					     0  0  d  1 ];
            		pnum = 8;
            		trails = [0 0 1 0 0 0 1 0];
            		
            	case '!'
            		transcl = [	    ch  0  d  1 ;
            					    ch  0  0  1 ;
            					3/9*ch  0  0  1 ;
            					3/9*ch  0  d  1 ;
            					     0  0  d  1 ;
            					     0  0  0  1 ;
            					     0  0  0  1 ;
            					     0  0  d  1 ];
            		pnum = 8;
            		trails = [0 0 1 0 0 0 1 0];
            		
            	case 'u'
            		transcl = [	-2/9*ch  0  d  1 ;
            					-2/9*ch  0  0  1 ];
            		pnum = 2;
            		trails = [0];
            		
            	case 's'
            		transcl = [	4/9*ch  0  d  1 ;
            					4/9*ch  0  0  1 ];
            		pnum = 2;
            		trails = [0];
            		
            	case 'd'
            		transcl = [	6/9*ch  0  d  1 ;
            					6/9*ch  0  0  1 ;
            					3/9*ch  0  d  1 ;
            					3/9*ch  0  0  1 ];
            		pnum = 4;
            		trails = [0 0];
            		
                otherwise
                    transcl = [];
                    pnum = 0;
                    trails = [];
            end
            
            points = zeros(4, 4, pnum);
            for i = 1 : pnum
            	points(:,:,i) = [rotper, transcl(i,:)'];
            end
            
            if it
                points(2,4,:) = points(2,4,:)+points(1,4,:)*tan(pi/12);
            end
            
            points(1,4,:) = -points(1,4,:) + vf;
            points(2,4,:) = points(2,4,:) + hf;
        end
        
        function traj = ctrajm(app, T0, T1, t)
            if isscalar(t)
                s = lspb(0, 1, t);
            else
                s = t(:);
            end
        	
        	s(s<0) = 0;
        	s(s>1) = 1;
        
        
            for i=1:length(s)
                traj(:,:,i) = trinterp(T0, T1, s(i));
            end
        end
        
        function acw = getacw(app, chr, cw)
            switch chr
                case 'А'
            		acw = 0;
            	case 'Б'
            		acw = -1/6*cw;
            	case 'В'
            		acw = -1/6*cw;
            	case 'Г'
            		acw = -2/6*cw;
            	case 'Д'
            		acw = 0;
            	case 'Ђ'
            		acw = 1/6*cw;
            	case 'Е'
            		acw = -2/6*cw;
            	case 'Ж'
            		acw = 1/6*cw;
            	case 'З'
            		acw = -3/12*cw;
            	case 'И'
            		acw = -1/6*cw;
            	case 'Ј'
            		acw = -1/6*cw;
            	case 'К'
            		acw = -1/6*cw;
            	case 'Л'
            		acw = -1/6*cw;
            	case 'Љ'
            		acw = 3/6*cw;
            	case 'М'
            		acw = 0;
            	case 'Н'
            		acw = -1/6*cw;
            	case 'Њ'
            		acw = 3/6*cw;
            	case 'О'
            		acw = -1/6*cw;
            	case 'П'
            		acw = -1/6*cw;
            	case 'Р'
            		acw = -1/6*cw;
            	case 'С'
            		acw = -2/6*cw;
            	case 'Т'
            		acw = -1/6*cw;
            	case 'Ћ'
            		acw = 1/6*cw;
            	case 'У'
            		acw = -1/6*cw;
            	case 'Ф'
            		acw = 1/6*cw;
            	case 'Х'
            		acw = 0;
            	case 'Ц'
            		acw = 0;
            	case 'Ч'
            		acw = -1/6*cw;
            	case 'Џ'
            		acw = -1/6*cw;
            	case 'Ш'
            		acw = 1/6*cw;
            	case '0'
            		acw = -2/6*cw;
            	case '1'
            		acw = -4/6*cw;
            	case '2'
            		acw = -2/6*cw;
            	case '3'
            		acw = -2/6*cw;
            	case '4'
            		acw = -1/6*cw;
            	case '5'
            		acw = -2/6*cw;
            	case '6'
            		acw = -2/6*cw;
            	case '7'
            		acw = -2/6*cw;
            	case '8'
            		acw = -2/6*cw;
            	case '9'
            		acw = -2/6*cw;
            	case '-'
            		acw = -2/6*cw;
            	case '.'
            		acw = -5/6*cw;
            	case ':'
            		acw = -5/6*cw;
            	case '!'
            		acw = -5/6*cw;
            	otherwise
            		acw = 0;
            end
        end
        
        function words = splittext(app, towrite)
            % Podijelimo towrite po delimiterima za space i nove linije
        	[s, match] = strsplit(towrite,{' ','n'},'CollapseDelimiters',false);
            ind = 1;
        	for i = 1 : size(match,2)
        		words(ind:ind+1) = [s(i) match(i)];
                ind = i*2+1;
        	end
        	words(ind) = s(end);
        	words = words(~cellfun('isempty',words));
        end
        
        function [posrot,potr] = generatePR(app)
            words = app.splittext(app.to_write); % Rijeci koje ce se ispisivati
            
            % Resetovanje horizontalnog i vertikalnog pomjeraja
            app.workspace_params.hf = app.workspace_params.hf0;
            app.workspace_params.vf = app.workspace_params.vf0;
            
            % Pomocne promjenljive za kurziv
            it = false; % da li je ukljucen italic
            itoff = false; % da li je upravo iskljucen italic
            itahf = app.tl_params.h*tan(pi/12); % italic additional horizontal feed
            
            fs_found = []; % Skup pronadjenih specifikatora formatiranja (neparni)
            fs_confirmed = []; % Skup razrijesenih specifikatora formatiranja (parni)
            fs_sp = containers.Map; % Pocetne pozicije specifikatora formatiranja u tekstu
            fs_ep = containers.Map; % Krajnje pozicije specifikatora formatiranja u tekstu
            for fs = app.text_params.def_aes
            	fs_sp(fs) = cell(0);
            	fs_ep(fs) = cell(0);
            end
            i = 0;
            if ~isempty(app.to_write) % Ukoliko ulazni tekst nije prazan
            	for i = 1 : size(words, 2) % Za svaku rijec
            		if isequal(words{1,i},' ') % Ukoliko je dobijen karakter za razmak
                        if app.workspace_params.hf + app.tl_params.sp < app.workspace_params.ws_size(2)-app.workspace_params.hrm % Ukoliko se moze napraviti razmak u tekucem redu
                            app.workspace_params.hf = app.workspace_params.hf + app.tl_params.sp;
                        elseif (app.workspace_params.vf + app.tl_params.ld) <= app.workspace_params.ws_size(1)-app.workspace_params.vbm % Ukoliko se moze preci u sljedeci red
            				for fs = app.text_params.def_aes % Provjeravamo da li ima nerazrijesenih formatiranja (idemo redom specifikator po specifikator)
            					if length(strfind(fs_found,fs)) ~= length(strfind(fs_confirmed,fs)) % Ukoliko nisu razrijeseni specifikatori formatiranja ovog tipa
            						% Uzimamo posljednju u nizu pocetnu poziciju (horizontalnu i vertikalnu) specifikatora formata
            						hfsp = fs_sp(fs);
            						hfsp = hfsp{1,end}{1,1};
            						vfsp = fs_sp(fs);
            						vfsp = vfsp{1,end}{1,2};
            						if abs(hfsp-app.workspace_params.hf)>app.robot_params.rep || abs(vfsp-app.workspace_params.vf)>app.robot_params.rep % Ukoliko se te pozicije razlikuju od trenutnih
            							fs_ep(fs) = [fs_ep(fs) {{app.workspace_params.hf app.workspace_params.vf}}]; % Upari novu krajnju poziciju sa postojecom pocetnom
            							fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}]; % Dodaj novu pocetnu poziciju
            						elseif abs(hfsp-app.workspace_params.hf)<app.robot_params.rep && abs(vfsp-app.workspace_params.vf)<app.robot_params.rep % Ukoliko su te pozicije iste kao trenutne
            							% Izbrisati stare pozicije i dodati nove na pocetku novog reda
            							fs_sp_tmp = fs_sp(fs);
            							fs_sp_tmp(end) = [];
            							fs_sp(fs) = [fs_sp_tmp {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}];
            						end
            					end
            				end
            				app.workspace_params.hf = app.workspace_params.hf0;
            				app.workspace_params.vf = app.workspace_params.vf + app.tl_params.ld;
            			else
            				break;
            			end
            		elseif isequal(words{1,i},'n') % Ukoliko je dobijen karakter za prelazak u novi red
            			if (app.workspace_params.vf + app.tl_params.ld) <= app.workspace_params.ws_size(1)-app.workspace_params.vbm % Ukoliko se moze preci u novi red
            				for fs = app.text_params.def_aes % Provjeravamo da li ima nerazrijesenih formatiranja (idemo redom specifikator po specifikator)
            					if length(strfind(fs_found,fs)) ~= length(strfind(fs_confirmed,fs)) % Ukoliko nisu razrijeseni specifikatori formatiranja ovog tipa
            						% Uzimamo posljednju u nizu pocetnu poziciju (horizontalnu i vertikalnu) specifikatora formata
            						hfsp = fs_sp(fs);
            						hfsp = hfsp{1,end}{1,1};
            						vfsp = fs_sp(fs);
            						vfsp = vfsp{1,end}{1,2};
            						if abs(hfsp-app.workspace_params.hf)>app.robot_params.rep || abs(vfsp-app.workspace_params.vf)>app.robot_params.rep % Ukoliko se te pozicije razlikuju od trenutnih
            							fs_ep(fs) = [fs_ep(fs) {{app.workspace_params.hf app.workspace_params.vf}}]; % Upari novu krajnju poziciju sa postojecom pocetnom
            							fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}]; % Dodaj novu pocetnu poziciju
            						elseif abs(hfsp-app.workspace_params.hf)<app.robot_params.rep && abs(vfsp-app.workspace_params.vf)<app.robot_params.rep % Ukoliko su te pozicije iste kao trenutne
            							% Izbrisati stare pozicije i dodati nove na pocetku novog reda
            							fs_sp_tmp = fs_sp(fs);
            							fs_sp_tmp(end) = [];
            							fs_sp(fs) = [fs_sp_tmp {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}];
            						end
            					end
                            end
            				app.workspace_params.hf = app.workspace_params.hf0;
            				app.workspace_params.vf = app.workspace_params.vf + app.tl_params.ld;
            			else
            				break;
            			end
            		else
            			wwf = erase(words{1,i},cellstr(app.text_params.def_fs')'); % Rijec bez specifikatora formatiranja
            			len = length(wwf); % Duzina rijeci bez specifikatora formatiranja
            			lenwfs = length(words{1,i}); % Duzina rijeci sa specifikatorima formatiranja
            			acws = 0; % Dodatne duzine karaktera
            			for k = 1 : len
            				acws = acws + app.getacw(wwf(k),app.tl_params.w);
            			end
            			aiws = floor(length(strfind(words{1,i},'i'))/2)*app.tl_params.h*tan(pi/12); % Dodatne duzine usljed specifikatora 'i'
            			if (len*app.tl_params.w + acws + aiws + (len-1)*app.tl_params.cd) <= app.workspace_params.ws_size(2)-app.workspace_params.hlm-app.workspace_params.hrm % Ukoliko rijec moze stati u jednu praznu liniju
            				if (app.workspace_params.hf + len*app.tl_params.w + acws + aiws + (len-1)*app.tl_params.cd) > app.workspace_params.ws_size(2)-app.workspace_params.hrm % Ukoliko rijec ne moze stati u tekucu liniju
            					if (app.workspace_params.vf + app.tl_params.ld) <= app.workspace_params.ws_size(1)-app.workspace_params.vbm % Ukoliko se moze preci u novi red
            						for fs = app.text_params.def_aes % Provjeravamo da li ima nerazrijesenih formatiranja (idemo redom specifikator po specifikator)
                    					if length(strfind(fs_found,fs)) ~= length(strfind(fs_confirmed,fs)) % Ukoliko nisu razrijeseni specifikatori formatiranja ovog tipa
                    						% Uzimamo posljednju u nizu pocetnu poziciju (horizontalnu i vertikalnu) specifikatora formata
                    						hfsp = fs_sp(fs);
                    						hfsp = hfsp{1,end}{1,1};
                    						vfsp = fs_sp(fs);
                    						vfsp = vfsp{1,end}{1,2};
                    						if abs(hfsp-app.workspace_params.hf)>app.robot_params.rep || abs(vfsp-app.workspace_params.vf)>app.robot_params.rep % Ukoliko se te pozicije razlikuju od trenutnih
                    							fs_ep(fs) = [fs_ep(fs) {{app.workspace_params.hf app.workspace_params.vf}}]; % Upari novu krajnju poziciju sa postojecom pocetnom
                    							fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}]; % Dodaj novu pocetnu poziciju
                    						elseif abs(hfsp-app.workspace_params.hf)<app.robot_params.rep && abs(vfsp-app.workspace_params.vf)<app.robot_params.rep % Ukoliko su te pozicije iste kao trenutne
                    							% Izbrisati stare pozicije i dodati nove na pocetku novog reda
                    							fs_sp_tmp = fs_sp(fs);
                    							fs_sp_tmp(end) = [];
                    							fs_sp(fs) = [fs_sp_tmp {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}];
                    						end
                    					end
                                    end
            						app.workspace_params.hf = app.workspace_params.hf0;
            						app.workspace_params.vf = app.workspace_params.vf + app.tl_params.ld;
            					else
            						break;
            					end
                            end
            				for j = 1 : lenwfs % Za svaki karakter u rijeci
            					if contains(app.text_params.def_fs,words{1,i}(j)) % Ukoliko se radi o specifikatoru formatiranja
            						fs = words{1,i}(j);
            						if isequal(fs,'i') % Posebno razmatramo specifikator za kurziv
            							it = ~it;
            							if ~it
                                            itoff = true;
            							end
                                    elseif length(strfind(fs_found,fs)) == length(strfind(fs_confirmed, fs)) % Ukoliko su razrijeseni specifikatori formatiranja ovog tipa
            							% Dodati novu pocetnu poziciju specifikatora formatiranja
            							fs_found = [fs_found fs];
            							if itoff
            								fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf+itahf app.workspace_params.vf}}];
            							else
            								fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf app.workspace_params.vf}}];
            							end
            						else % U suprotnom, razrijesi postojece pozicije speficikatora formatiranja
            							fs_confirmed = [fs_confirmed fs];
            							if app.workspace_params.hf==app.workspace_params.hf0 % Ukoliko je parni specifikator detektovan odmah na pocetku reda, ukloni odgovarajuci pocetni(neparni) specifikator na pocetku reda
            								fs_sp_tmp = fs_sp(fs);
            								fs_sp_tmp(end) = [];
            								fs_sp(fs) = [fs_sp_tmp];
            							else % U suprotnom, dodaj validnu krajnju poziciju
            								fs_ep(fs) = [fs_ep(fs) {{app.workspace_params.hf app.workspace_params.vf}}];
            							end
                                        
                                        if size(fs_sp(fs),2) > 0
                                            posrot{1,i}{1,j} = [];
            							    potr{1,i}{1,j} = cell(2,1);
                							for nf = 1 : size(fs_sp(fs),2)
                								hfsp = fs_sp(fs);
                								hfsp = hfsp{1,nf}{1,1};
                								vfsp = fs_sp(fs);
                								vfsp = vfsp{1,nf}{1,2};
                								hfep = fs_ep(fs);
                								hfep = hfep{1,nf}{1,1};
                								vfep = fs_ep(fs);
                								vfep = vfep{1,nf}{1,2};
                                                
                								[spoints, strails] = app.genchr(fs, it, app.tl_params.h, app.tl_params.w, hfsp, vfsp, app.workspace_params.d);
                								[epoints, etrails] = app.genchr(fs, it, app.tl_params.h, app.tl_params.w, hfep, vfep, app.workspace_params.d);
                                                
                								points = [];
                								trails = [];
                                                
                								if fs ~= 'd'
                                                    epoints = flip(epoints,3);
                                                    points = cat(3,spoints,epoints);
                                                    trails = [0 strails 1 etrails];
                								else
                									epoints(:,:,1:2) = flip(epoints(:,:,1:2),3);
                									epoints(:,:,3:4) = flip(epoints(:,:,3:4),3);
                									points = cat(3, spoints(:,:,1:2), epoints(:,:,1:2), spoints(:,:,3:4), epoints(:,:,3:4));
                									trails = [0 strails(1) 1 etrails(1) 0 strails(2) 1 etrails(2)];
                								end
                                                
                								points = points + app.workspace_params.g;
                								pscs = size(posrot{1,i}{1,j},2);
                								for k = pscs+1 : pscs+size(points,3) % Za svaku tacku trenutne putanje (a moze biti vise putanja, za razliku od klasicnih karaktera)
                									posrot{1,i}{1,j}(1:3,k) = points(1:3,4,k-pscs)*1000;
                									posrot{1,i}{1,j}(4:6,k) = tr2rpy(points(:,:,k-pscs))*180/pi;
                								end
                								potr{1,i}{1,j}{1,1} = cat(3,potr{1,i}{1,j}{1,1},points);
                								potr{1,i}{1,j}{2,1} = cat(2,potr{1,i}{1,j}{2,1},trails);;
                							end
                                            fprintf('''%s'' = posrot{%d}{%d}\n\n', fs, i, j);
                							disp(posrot{1,i}{1,j});
                                        end
            							fs_sp(fs) = cell(0);
            							fs_ep(fs) = cell(0);
            						end
            					else
            						if itoff
            							app.workspace_params.hf = app.workspace_params.hf + itahf;
            							itoff = false;
            						end
            						posrot{1,i}{1,j} = [];
            						potr{1,i}{1,j} = cell(2,1);
            						[points, trails] = app.genchr(words{1,i}(j), it, app.tl_params.h, app.tl_params.w, app.workspace_params.hf, app.workspace_params.vf, app.workspace_params.d);
            						points = points + app.workspace_params.g;
            						for k = 1 : size(points,3) % Za svaku tacku u karakteru
            							posrot{1,i}{1,j}(1:3,k) = points(1:3,4,k)*1000;
            							posrot{1,i}{1,j}(4:6,k) = tr2rpy(points(:,:,k))*180/pi;
            						end
            						potr{1,i}{1,j}{1,1} = points;
            						potr{1,i}{1,j}{2,1} = trails;
            						fprintf('''%s'' = posrot{%d}{%d}\n\n', words{1,i}(j), i, j);
            						disp(posrot{1,i}{1,j});
                                    
            						app.workspace_params.hf = app.workspace_params.hf + app.tl_params.w + app.getacw(words{1,i}(j),app.tl_params.w) + app.tl_params.cd;
                                    if j == lenwfs || ~contains(words{1,i}(j+1:end),app.text_params.def_chr_ca)
                                        app.workspace_params.hf = app.workspace_params.hf - app.tl_params.cd;
                                    end
                                end
%                                 if len > 0
%                     			    app.workspace_params.hf = app.workspace_params.hf - app.tl_params.cd;
%                     			end
                            end
            			else % Ukoliko citava rijec ne moze stati u jednu liniju, dijelimo je u vise redova, pri cemu se svaki red odvaja karakterom '-'
            				bo = app.workspace_params.ws_size(2)-app.workspace_params.hrm-app.getacw(wwf(end),app.tl_params.w)-app.getacw('-',app.tl_params.w)-app.tl_params.cd; % Granica horizontalnog pomjeraja na kojoj mozemo ispisati posljednji karakter u redu, poslije tog karaktera idu app.tl_params.cd i karakter '-'
            				j = 1; % Trenutni karakter u rijeci
            				nh = 0; % Broj dodatnih karaktera unutar rijeci (karaktera '-')
            				while j <= (lenwfs) % Dok god rijec zajedno sa karakterima '-' nije ispisana do kraja, pisemo redom karaktere
            					while app.workspace_params.hf <= bo && j <= (lenwfs) % Dok god ima prostora u trenutnom redu, pisemo karakter po karakter
            						if contains(app.text_params.def_fs,words{1,i}(j)) % Ukoliko se radi o specifikatoru formatiranja
            							fs = words{1,i}(j);
            							if isequal(fs,'i')
            								it = ~it;
            								if ~it
            									itoff = true;
            								end
                                        elseif length(strfind(fs_found,fs)) == length(strfind(fs_confirmed, fs)) % Ukoliko su razrijeseni specifikatori formatiranja ovog tipa
            								% Dodati novu pocetnu poziciju specifikatora formatiranja
            								fs_found = [fs_found fs];
            								if itoff
            									fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf+itahf app.workspace_params.vf}}];
            								else
            									fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf app.workspace_params.vf}}];
            								end
            							else % U suprotnom, razrijesi postojecu poziciju speficikatora formatiranja
            								fs_confirmed = [fs_confirmed fs];
            								if app.workspace_params.hf==app.workspace_params.hf0 % Ukoliko je parni specifikator detektovan odmah na pocetku reda, ukloni ga
            									fs_sp_tmp = fs_sp(fs);
            									fs_sp_tmp(end) = [];
            									fs_sp(fs) = [fs_sp_tmp];
            								else % U suprotnom, dodaj validnu krajnju poziciju
            									fs_ep(fs) = [fs_ep(fs) {{app.workspace_params.hf app.workspace_params.vf}}];
            								end
                                            
                                            if size(fs_sp(fs),2) > 0
                                                posrot{1,i}{1,j+nh} = [];
            								    potr{1,i}{1,j+nh} = cell(2,1);
                								for nf = 1 : size(fs_sp(fs),2)
                									hfsp = fs_sp(fs);
                									hfsp = hfsp{1,nf}{1,1};
                									vfsp = fs_sp(fs);
                									vfsp = vfsp{1,nf}{1,2};
                									hfep = fs_ep(fs);
                									hfep = hfep{1,nf}{1,1};
                									vfep = fs_ep(fs);
                									vfep = vfep{1,nf}{1,2};
                                                    
                									[spoints, strails] = app.genchr(fs, it, app.tl_params.h, app.tl_params.w, hfsp, vfsp, app.workspace_params.d);
                									[epoints, etrails] = app.genchr(fs, it, app.tl_params.h, app.tl_params.w, hfep, vfep, app.workspace_params.d);
                                                    
                									points = [];
                									trails = [];
                                                    
                									if fs~='d'
                									epoints = flip(epoints,3);
                									points = cat(3,spoints,epoints);
                									trails = [0 strails 1 etrails];
                									else
                										epoints(:,:,1:2) = flip(epoints(:,:,1:2),3);
                										epoints(:,:,3:4) = flip(epoints(:,:,3:4),3);
                										points = cat(3, spoints(:,:,1:2), epoints(:,:,1:2), spoints(:,:,3:4), epoints(:,:,3:4));
                										trails = [0 strails(1) 1 etrails(1) 0 strails(2) 1 etrails(2)];
                									end
                                                    
                									points = points + app.workspace_params.g;
                									pscs = size(posrot{1,i}{1,j+nh},2);
                									for k = pscs+1 : pscs+size(points,3) % Za svaku tacku trenutne putanje (a moze biti vise putanja, za razliku od klasicnih karaktera)
                										posrot{1,i}{1,j+nh}(1:3,k) = points(1:3,4,k-pscs)*1000;
                										posrot{1,i}{1,j+nh}(4:6,k) = tr2rpy(points(:,:,k-pscs))*180/pi;
                									end
                									potr{1,i}{1,j+nh}{1,1} = cat(3,potr{1,i}{1,j+nh}{1,1},points);
                									potr{1,i}{1,j+nh}{2,1} = cat(2,potr{1,i}{1,j+nh}{2,1},trails);;
                								end
                                                fprintf('''%s'' = posrot{%d}{%d}\n\n', fs, i, j+nh);
                								disp(posrot{1,i}{1,j+nh});
                                            end
                                            fs_sp(fs) = cell(0);
                                            fs_ep(fs) = cell(0);
            							end
            						else
            							if itoff
            								app.workspace_params.hf = app.workspace_params.hf + itahf;
            								itoff = false;
            							end
            							posrot{1,i}{1,j+nh} = [];
            							potr{1,i}{1,j+nh} = cell(2,1);
            							[points, trails] = app.genchr(words{1,i}(j), it, app.tl_params.h, app.tl_params.w, app.workspace_params.hf, app.workspace_params.vf, app.workspace_params.d);
            							points = points + app.workspace_params.g;
            							for k = 1 : size(points,3) % Za svaku tacku u karakteru
            								posrot{1,i}{1,j+nh}(1:3,k) = points(1:3,4,k)*1000;
            								posrot{1,i}{1,j+nh}(4:6,k) = tr2rpy(points(:,:,k))*180/pi;
            							end
            							potr{1,i}{1,j+nh}{1,1} = points;
            							potr{1,i}{1,j+nh}{2,1} = trails;
            							fprintf('''%s'' = posrot{%d}{%d}\n\n', words{1,i}(j), i, j+nh);
            							disp(posrot{1,i}{1,j+nh});
            							
                                        app.workspace_params.hf = app.workspace_params.hf + app.tl_params.w + app.getacw(words{1,i}(j),app.tl_params.w) + app.tl_params.cd;
            						    if j == lenwfs || ~contains(words{1,i}(j+1:end),app.text_params.def_chr_ca)
                                            app.workspace_params.hf = app.workspace_params.hf - app.tl_params.cd;
                                        end
                                    end
            						j = j + 1;
            					end
            					if j <= (lenwfs) % Ukoliko rijec nije do kraja ispisana, a u trenutnom redu nema vise prostora, napisati karakter '-' i preci u sljedeci red
            						if itoff
            							app.workspace_params.hf = app.workspace_params.hf + itahf;
            							itoff = false;
            						end
            						posrot{1,i}{1,j+nh} = [];
            						potr{1,i}{1,j+nh} = cell(2,1);
            						[points, trails] = app.genchr('-', it, app.tl_params.h, app.tl_params.w, app.workspace_params.hf, app.workspace_params.vf, app.workspace_params.d);
            						points = points + app.workspace_params.g;
            						for k = 1 : size(points,3)
            							posrot{1,i}{1,j+nh}(1:3,k) = points(1:3,4,k)*1000;
            							posrot{1,i}{1,j+nh}(4:6,k) = tr2rpy(points(:,:,k))*180/pi;
            						end
            						potr{1,i}{1,j+nh}{1,1} = points;
            						potr{1,i}{1,j+nh}{2,1} = trails;
            						fprintf('''-'' = posrot{%d}{%d}\n\n', i, j+nh);
            						disp(posrot{1,i}{1,j+nh});
            						nh = nh + 1;
            						if app.workspace_params.vf+app.tl_params.ld <= app.workspace_params.ws_size(1)-app.workspace_params.vbm % Ukoliko se moze preci u novi red
            							for fs = app.text_params.def_aes % Provjeravamo da li ima nerazrijesenih formatiranja (idemo redom specifikator po specifikator)
                        					if length(strfind(fs_found,fs)) ~= length(strfind(fs_confirmed,fs)) % Ukoliko nisu razrijeseni specifikatori formatiranja ovog tipa
                        						% Uzimamo posljednju u nizu pocetnu poziciju (horizontalnu i vertikalnu) specifikatora formata
                        						hfsp = fs_sp(fs);
                        						hfsp = hfsp{1,end}{1,1};
                        						vfsp = fs_sp(fs);
                        						vfsp = vfsp{1,end}{1,2};
                        						if abs(hfsp-app.workspace_params.hf)>app.robot_params.rep || abs(vfsp-app.workspace_params.vf)>app.robot_params.rep % Ukoliko se te pozicije razlikuju od trenutnih
                        							fs_ep(fs) = [fs_ep(fs) {{app.workspace_params.hf app.workspace_params.vf}}]; % Upari novu krajnju poziciju sa postojecom pocetnom
                        							fs_sp(fs) = [fs_sp(fs) {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}]; % Dodaj novu pocetnu poziciju
                        						elseif abs(hfsp-app.workspace_params.hf)<app.robot_params.rep && abs(vfsp-app.workspace_params.vf)<app.robot_params.rep % Ukoliko su te pozicije iste kao trenutne
                        							% Izbrisati stare pozicije i dodati nove na pocetku novog reda
                        							fs_sp_tmp = fs_sp(fs);
                        							fs_sp_tmp(end) = [];
                        							fs_sp(fs) = [fs_sp_tmp {{app.workspace_params.hf0 app.workspace_params.vf+app.tl_params.ld}}];
                        						end
                        					end
                                        end
            							app.workspace_params.hf = app.workspace_params.hf0;
            							app.workspace_params.vf = app.workspace_params.vf + app.tl_params.ld;
            						else
            							% pokreni robotcontrol i cekaj potvrdu korisnika da se moze nastaviti pisanje (ili prekinuti)
            							% app.workspace_params.hf = app.workspace_params.hf0;
            							% app.workspace_params.vf = app.workspace_params.vf0;
            							break;
            
            							%ret = input('Citava radna povrsina ispisana.\nPoceti ispocetka?\n  -> Unesi [D] za Da\n  -> Unesi [N] za Ne\n','s');
            							%while((ret ~= 'd') && (ret ~= 'D') && (ret ~= 'n') && (ret ~= 'N'))
            							%	ret = input('\n','s');
            							%end
            							%if(ret == 'd' || ret == 'D')
            							%	app.workspace_params.vf = app.tl_params.sp + app.tl_params.h;
            							%else
            							%	app.workspace_params.vf = app.tl_params.sp + app.tl_params.h;
            							%	break;
            							%end
            						end
            					end
            				end
%             				if len > 0
%             					app.workspace_params.hf = app.workspace_params.hf - app.tl_params.cd;
%                             end
            			end
            		end
                end
            end
            
            % Vracanje u referentni polozaj
            if app.workspace_params.go_to_ref
            	posrot{1,i+1}{1,1}(1:3,1) = app.robot_params.Tr(1:3,4)*1000;
            	posrot{1,i+1}{1,1}(4:6,1) = tr2rpy(app.robot_params.Tr)*180/pi;
            	fprintf('refpoint = posrot{%d}{%d}\n\n', i+1, 1);
            	disp(posrot{1,i+1}{1,1});
            
            	potr{1,i+1}{1,1}{1,1} = app.robot_params.Tr;
            	potr{1,i+1}{1,1}{2,1} = [0];
            end
            
            % Uklanjanje praznih celija kao rezultat preskocenih
            % rijeci i karaktera
            posrot = posrot(~cellfun('isempty',posrot));
            potr = potr(~cellfun('isempty',potr));
            for i = 1 : size(posrot,2)
                temp = posrot{i};
                posrot{i} = temp(~cellfun('isempty',temp));
            end
            for i = 1 : size(posrot,2)
                temp = potr{i};
                potr{i} = temp(~cellfun('isempty',temp));
            end
            
            app.pr_generated = true;
        end
        
        function [qs,ts] = simul(app)
            % Racunanje broja koraka (medjutacki) izmedju svake dvije tacke koje
            % odredjuju putanju, kao i samih medjutacaka
            p2p = cell(0);
            for i = 1 : size(app.potr,2) % za svaku rijec
                for j = 1 : size(app.potr{1,i},2) % za svaki karakter u rijeci
                    for k = 1 : size(app.potr{1,i}{1,j}{2,1},2) % za svaku putanju koja odredjuje karakter (uzima se u obzir i putanja od trenutne pozicije robota do pocetne pozicije karaktera)
                        if k>1
                            iprev = i; % prethodni i
                            jprev = j; % prethodni j
                            kprev = k-1; % prethodni k
                        elseif j>1
                            iprev = i;
                            jprev = j-1;
                            kprev = size(app.potr{1,i}{1,jprev}{2,1},2);
                        elseif i>1
                            iprev = i-1;
                            jprev = size(app.potr{1,iprev}, 2);
                            kprev = size(app.potr{1,iprev}{1,jprev}{2,1},2);
                        else % k=1, j=1, i=1
                            Tc = app.robot_params.R.fkine(app.robot_params.q).T; % Trenutni polozaj manipulatora se uzima kao prethodni polozaj
                            stp = max([2 round(sqrt((Tc(1,4)-app.potr{1,1}{1,1}{1,1}(1,4,1))^2 + (Tc(2,4)-app.potr{1,1}{1,1}{1,1}(2,4,1))^2 + (Tc(3,4)-app.potr{1,1}{1,1}{1,1}(3,4,1))^2)*200)]);
                            p2p{1,1}{1,1}{1,1}{1,1} = app.ctrajm(Tc, app.potr{1,1}{1,1}{1,1}(:,:,1), stp);
                            p2p{1,1}{1,1}{1,1}{2,1} = 0;
                            continue;
                        end
            
                        stp = max([2 round(sqrt((app.potr{1,iprev}{1,jprev}{1,1}(1,4,kprev)-app.potr{1,i}{1,j}{1,1}(1,4,k))^2 + (app.potr{1,iprev}{1,jprev}{1,1}(2,4,kprev)-app.potr{1,i}{1,j}{1,1}(2,4,k))^2 + (app.potr{1,iprev}{1,jprev}{1,1}(3,4,kprev)-app.potr{1,i}{1,j}{1,1}(3,4,k))^2)*200)]);
                        p2p{1,i}{1,j}{1,k}{1,1} = app.ctrajm(app.potr{1,iprev}{1,jprev}{1,1}(:,:,kprev), app.potr{1,i}{1,j}{1,1}(:,:,k), stp);
                        if ~isequal(app.potr{1,iprev}{1,jprev}{1,1}(:,:,kprev), app.potr{1,i}{1,j}{1,1}(:,:,k))
                            p2p{1,i}{1,j}{1,k}{2,1} = app.potr{1,i}{1,j}{2,1}(1,k);
                        else
                            p2p{1,i}{1,j}{1,k}{2,1} = 2;
                        end
                    end
                end
            end
            
            % Inverzna kinematika
            q0 = app.robot_params.q; % pocetna pozicija je trenutna pozicija robota
            qfound = false; % pokazatelj da li je pronadjen odgovarajuci vektor unutrasnjih koordinata
            qtemp = [];
            qs = [];
            ts = [];
            for i = 1 : size(p2p,2) % Za svaku riječ
                for j = 1 : size(p2p{1,i},2) % Za svaki znak unutar riječi
                    for k = 1 : size(p2p{1,i}{1,j},2) % Za svaku tacku koja određuje pojedini karakter
                        p = 1;
                        qfailed = 0;
                        while p <= size(p2p{1,i}{1,j}{1,k}{1,1},3) % Za svaki medjutacku na putanji
                            while ~qfound % dok nije pronadjen odgovarajuci vektor unutrasnjih koordinata
                                [qc, err] = app.robot_params.R.ikcon(p2p{1,i}{1,j}{1,k}{1,1}(:,:,p), q0); % izracunaj inverznu kinematiku za polozaj koji posmatramo
                                if(err < app.robot_params.rep) % ukoliko su pronadjene odgovarajuce unutrasnje koordinate
                                    q0 = qc;
                                    qfound = true;
                                    qtemp = [qtemp; qc];
                                else % u suprotnom
                                    q0 = app.genric(app.robot_params.R.n, app.robot_params.R.qlim); % ukoliko nije, generisi novi pocetni pogodak unutrasnjih koordinata i probaj ponovo
                                    qfailed = qfailed + 1;
                                    if qfailed > 30
                                        error('Nije moguce izvrsiti simulaciju. Vrh alata ne moze da postigne odredjeni polozaj na putanji.');
                                    end
                                    qtemp = [];
                                    p = 0;
                                    break;
                                end
                            end
                            p = p + 1;
                            qfound = false;
                        end
                        qs = [qs; qtemp];
                        ts = [ts; zeros(size(p2p{1,i}{1,j}{1,k}{1,1},3),1)+p2p{1,i}{1,j}{1,k}{2,1}];
                        qtemp = [];
                    end
                end
            end
            
            % Korekcija rezultata, eliminacija uzastopnih identicnih
            % unutrašnjih koordinata
            i = 1;
            while i < size(qs,1)
                if isequal(qs(i,:), qs(i+1,:))
                    if ts(i+1) ~= 2
                        ind = i;
                        if ts(i+1) == 0
                            ind = i+1;
                        end
                        qs(ind,:) = [];
                        ts(ind) = [];
                        i = i-1;
                    else
                        ts(i+1) = 1;
                    end
                end
                i = i+1;
            end
            
            app.qt_generated = true;
        end
        
        function visualize3d(app)
            trailset = 1;
            trailline = cell(0);
            changed = false;
            trail = [];
            
            for i = 1 : size(app.qs,1)  % za sve polozaje na trajektoriji
                qc = app.qs(i,:); % uzimamo trenutni polozaj
                Tc = app.robot_params.R.base;
    
                for j = 1 : app.robot_params.R.n
                    if app.v3d.link(j) ~= 0
                        % for plot3d, skip any 0 in the handle list
                        set(app.v3d.link(j), 'Matrix', Tc.T);
                    end
    
                    Tc = Tc * app.robot_params.R.links(j).A(qc(j));
                end
    
                % update the transform for link N+1 (the tool)
                Tc = Tc * SE3(app.robot_params.tool);
                if length(app.v3d.link) > app.robot_params.R.n
                    set(app.v3d.link(app.robot_params.R.n+1), 'Matrix', Tc.T);
                end
                
                if app.ts(i) == 1
                    Tc = app.robot_params.R.fkine(qc);
                    trail= [trail; transl(Tc)];

                    if size(trailline,1) ~= trailset
                        trailline{trailset,1} = plot3(app.v3d.ax, 0, 0, 0, app.v3d.trailstyle{:});
                    end
                    
                    % Ukoliko je u pitanju tacka
                    if i > 1
                        if isequal(app.qs(i-1,:),qc)
                            trailline{trailset,1}.Marker = '.';
                        end
                    end
                    set(trailline{trailset,1}, 'Xdata', trail(:,1), 'Ydata', trail(:,2), 'Zdata', trail(:,3));
                    changed = true;
                elseif changed
                    trail = [];
                    trailset = trailset + 1;
                    changed = false;
                end
                drawnow;
            end
        end
        
    end
    
    methods (Access = public)
        
        function q = genric(app, n, qlim)
            q = zeros(1,n);
            for i = 1 : n
                q(i) = rand*(qlim(i,2)-qlim(i,1)) + qlim(1);
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all')
            slCharacterEncoding('UTF-8');
            cd(fullfile(fileparts(fileparts(mfilename('fullpath'))), 'app'));
            
            app.initializeRobotParams();
            app.initializeV3DParams();
            app.initializeRobotControlParams();
            app.initializeTextParams();
            app.initializeTLParams();
            app.initializeWorkspaceParams();
            app.initializeRobotConnectionParams();
        end

        % Size changed function: MainAppUIFigure
        function MainAppUIFigureSizeChanged(app, event)
            cws = app.MainAppUIFigure.Position(3:4);
            if cws(1) < app.dws(1)
                cws(1) = app.dws(1);
            end
            if cws(2) < app.dws(2)
                cws(2) = app.dws(2);
            end
            cws2 = [cws,cws];
            
            app.RobotParamsButton.Position = [0,270,150,40]./app.dws2.*cws2;
            app.WorkspaceParamsButton.Position = [150,270,210,40]./app.dws2.*cws2;
            app.RobotControlParamsButton.Position = [360,270,240,40]./app.dws2.*cws2;
            app.LoadParametersButton.Position = [20,224,230,26]./app.dws2.*cws2;
            app.SaveParametersButton.Position = [20,198,230,26]./app.dws2.*cws2;
            app.TLTypeLabel.Position(1:2) = [20,170]./app.dws.*cws;
            app.TLTypeLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.TLTypeDropDown.Position(1:2:end) = [sum(app.TLTypeLabel.Position(1:2:end)),(101+129)/app.dws(1)*cws(1)-129];
            app.TLTypeDropDown.Position(2:2:end) = [170,22]/app.dws(2)*cws(2);
            app.FontSizeLabel.Position(1:2) = [20,144]./app.dws.*cws;
            app.FontSizeLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.FontSizeSpinner.Position(1:2:end) = [sum(app.FontSizeLabel.Position(1:2:end)),(60+89)/app.dws(1)*cws(1)-89];
            app.FontSizeSpinner.Position(2:2:end) = [144,22]/app.dws(2)*cws(2);
            app.FontSizeUnitSwitch.Position(1:2) = [194,149]./app.dws.*cws;
            app.FontSizeUnitSwitch.Position(3:4) = round(12/app.dws(2)*cws(2))*[2,1];
            app.TextToWriteLabel.Position(1:2) = [20,118]./app.dws.*cws;
            app.TextToWriteLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.TextToWriteEditField.Position = [20,94,230,22]./app.dws2.*cws2;
            app.ReturnToRefPoseLabel.Position(1:2) = [20,62]./app.dws.*cws;
            app.ReturnToRefPoseLabel.Position(4) = 28/app.dws(2)*cws(2);
            app.ReturnToRefPoseSwitch.Position(1:2) = [sum(app.ReturnToRefPoseLabel.Position(1:2:end))+30/app.dws(1)*cws(1),67/app.dws(2)*cws(2)];
            app.ReturnToRefPoseSwitch.Position(3:4) = round(20/app.dws(2)*cws(2))*[2,1];
            app.SimulateButton.Position = [20,20,87,32]./app.dws2.*cws2;
            app.RobotTransferButton.Position = [121,20,129,32]./app.dws2.*cws2;
            app.TextPreviewLabel.Position(1:2) = [280,228]./app.dws.*cws;
            app.TextPreviewLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.TextPreviewPanel.Position = [280,20,300,206]./app.dws2.*cws2;
        end

        % Button pushed function: RobotParamsButton
        function RobotParamsButtonPushed(app, event)
            app.RobotParamsButton.Enable = false;
            app.pr_generated = false;
            app.qt_generated = false;
            app.robot_params_handle = robot_params(app);
        end

        % Button pushed function: RobotControlParamsButton
        function RobotControlParamsButtonPushed(app, event)
            app.RobotControlParamsButton.Enable = false;
            app.pr_generated = false;
            app.qt_generated = false;
            app.robotcontrol_params_handle = robotcontrol_params(app);
        end

        % Button pushed function: WorkspaceParamsButton
        function WorkspaceParamsButtonPushed(app, event)
            app.WorkspaceParamsButton.Enable = false;
            app.pr_generated = false;
            app.qt_generated = false;
            app.workspace_params_handle = workspace_params(app);
        end

        % Close request function: MainAppUIFigure
        function MainAppUIFigureCloseRequest(app, event)
            delete(app.robot_params_handle);
            delete(app.robotcontrol_params_handle);
            delete(app.workspace_params_handle);
            if isfield(app.v3d,'fig')
                delete(app.v3d.fig);
            end
            delete(app)
        end

        % Value changed function: TLTypeDropDown
        function TLTypeDropDownValueChanged(app, event)
            app.pr_generated = false;
            app.qt_generated = false;
            
            value = app.TLTypeDropDown.Value;
            switch value
                case 'A - usko'
                    app.tl_params.tl_type = 'A';
                case 'B - srednje'
                    app.tl_params.tl_type = 'B';
                case 'C - široko'
                    app.tl_params.tl_type = 'C';
            end
            
            [app.tl_params.h, ...
            app.tl_params.w, ...
            app.tl_params.hm, ...
            app.tl_params.wm, ...
            app.tl_params.cd, ...
            app.tl_params.sp, ...
            app.tl_params.ld] = app.genTLparams(app.tl_params.tl_type, app.text_params.font_size);
        end

        % Value changed function: FontSizeSpinner
        function FontSizeSpinnerValueChanged(app, event)
            app.pr_generated = false;
            app.qt_generated = false;
            
            app.text_params.font_size = app.FontSizeSpinner.Value;
            
            [app.tl_params.h, ...
            app.tl_params.w, ...
            app.tl_params.hm, ...
            app.tl_params.wm, ...
            app.tl_params.cd, ...
            app.tl_params.sp, ...
            app.tl_params.ld] = app.genTLparams(app.tl_params.tl_type, app.text_params.font_size);
            
            cv = app.TextToWriteEditField.Value;
            if app.checkTextValidity(cv)
                app.TextToWriteEditField.FontColor = 'black';
                app.TextPreviewHTML.HTMLSource = app.text2html(cv);
            else
                app.TextToWriteEditField.FontColor = [0.9,0.3,0.3];
            end
        end

        % Value changed function: FontSizeUnitSwitch
        function FontSizeUnitSwitchValueChanged(app, event)
            app.pr_generated = false;
            app.qt_generated = false;
            
            if strcmp(app.FontSizeUnitSwitch.Value,'pt')
                app.FontSizeSpinner.Limits = [8,192];
            else
                app.FontSizeSpinner.Limits = [3,68];
            end
            
            [app.tl_params.h, ...
            app.tl_params.w, ...
            app.tl_params.hm, ...
            app.tl_params.wm, ...
            app.tl_params.cd, ...
            app.tl_params.sp, ...
            app.tl_params.ld] = app.genTLparams(app.tl_params.tl_type, app.text_params.font_size);
        
            cv = app.TextToWriteEditField.Value;
            if app.checkTextValidity(cv)
                app.TextToWriteEditField.FontColor = 'black';
                app.TextPreviewHTML.HTMLSource = app.text2html(cv);
            else
                app.TextToWriteEditField.FontColor = [0.9,0.3,0.3];
            end
        end

        % Value changing function: TextToWriteEditField
        function TextToWriteEditFieldValueChanging(app, event)
            cv = event.Value;
            if app.checkTextValidity(cv)
                app.text_ok = true;
                app.TextToWriteEditField.BackgroundColor = 'white';
                app.TextPreviewHTML.HTMLSource = app.text2html(cv);
            else
                app.text_ok = false;
                app.TextToWriteEditField.BackgroundColor = [1 0.7 0.7];
            end
        end

        % Value changed function: ReturnToRefPoseSwitch
        function ReturnToRefPoseSwitchValueChanged(app, event)
            app.pr_generated = false;
            app.qt_generated = false;
            
            if strcmp(app.ReturnToRefPoseSwitch.Value,'Da')
                app.workspace_params.go_to_ref = true;
            else
                app.workspace_params.go_to_ref = false;
            end
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            app.pr_generated = false;
            app.qt_generated = false;
            
            [file,path] = uigetfile('./*.mat');
            if ~isempty([path file])
                vars = load([path file]);
                app.text_params = vars.text_params_s;
                app.tl_params = vars.tl_params_s;
                app.robot_params = vars.robot_params_s;
                app.robotcontrol_params = vars.robotcontrol_params_s;
                app.workspace_params = vars.workspace_params_s;
                app.robotconnection_params = vars.robotconnection_params_s;
            end
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            [file,path] = uiputfile('./*.mat');
            if ~isempty([path file])
                text_params_s = app.text_params;
                tl_params_s = app.tl_params;
                robot_params_s = app.robot_params;
                workspace_params_s = app.workspace_params;
                robotcontrol_params_s = app.robotcontrol_params;
                robotconnection_params_s = app.robotconnection_params;
                save([path file],'text_params_s', ...
                                 'tl_params_s', ...
                                 'robot_params_s', ...
                                 'robotcontrol_params_s', ...
                                 'workspace_params_s', ...
                                 'robotconnection_params_s');
            end
        end

        % Value changed function: TextToWriteEditField
        function TextToWriteEditFieldValueChanged(app, event)
            app.pr_generated = false;
            app.qt_generated = false;
            if app.text_ok
                app.to_write = app.TextToWriteEditField.Value;
            else
                app.to_write = '';
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            if app.v3d.links_ok
                if ~app.pr_generated
                    app.SimulateButton.FontColor = [0.9 0.7 0.0];
                    app.MainAppUIFigure.Pointer = 'watch';
                    pause(0.2);
                    [app.posrot,app.potr] = app.generatePR();
                    [app.qs,app.ts] = app.simul();
                elseif ~app.qt_generated
                    app.SimulateButton.FontColor = [0.9 0.7 0.0];
                    app.MainAppUIFigure.pr_generatedre.Pointer = 'watch';
                    pause(0.2);
                    [app.qs,app.ts] = app.simul();
                end
                app.SimulateButton.FontColor = [0.4 0.7 0.1];
                app.MainAppUIFigure.Pointer = 'arrow';
                pause(0.2);
                app.initializeAxesFigure();
                try
                    app.visualize3d();
                catch ex
                    app.SimulateButton.FontColor = 'black';
                    throw(ex);
                end
                app.SimulateButton.FontColor = 'black';
            else
                app.SimulateButton.FontColor = [0.9 0.3 0.3];
                app.sb_timer = timer('StartDelay',1, 'ExecutionMode','singleShot');
                app.sb_timer.TimerFcn = {@app.timer_func, app.SimulateButton};
                app.sb_timer.StopFcn = @app.timer_stop_func;
                start(app.sb_timer);
            end
        end

        % Button pushed function: RobotTransferButton
        function RobotTransferButtonPushed(app, event)
            if ~app.pr_generated
                app.RobotTransferButton.FontColor = [0.9 0.7 0.0];
                app.MainAppUIFigure.Pointer = 'watch';
                pause(0.2);
                [app.posrot,app.potr] = app.generatePR();
            end
            app.RobotTransferButton.FontColor = [0.4 0.7 0.1];
            app.MainAppUIFigure.Pointer = 'arrow';
            pause(0.2);
            
            app.rtb_timer = timer('StartDelay',1, 'ExecutionMode','singleShot');
            app.rtb_timer.TimerFcn = {@app.timer_func, app.RobotTransferButton};
            app.rtb_timer.StopFcn = @app.timer_stop_func;
            start(app.rtb_timer);
            
            app.RobotTransferButton.Enable = false;
            app.robot_transfer_handle = robot_transfer(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainAppUIFigure = uifigure('Visible', 'off');
            app.MainAppUIFigure.Name = 'Automatsko pisanje teksta pomoću MITSUBISHI robota';
            app.MainAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppUIFigureCloseRequest, true);
            app.MainAppUIFigure.SizeChangedFcn = createCallbackFcn(app, @MainAppUIFigureSizeChanged, true);
            app.RobotParamsButton = uibutton(app.MainAppUIFigure, 'push');
            app.RobotParamsButton.ButtonPushedFcn = createCallbackFcn(app, @RobotParamsButtonPushed, true);
            app.RobotParamsButton.Text = 'Parametri robota';
            app.RobotControlParamsButton = uibutton(app.MainAppUIFigure, 'push');
            app.RobotControlParamsButton.ButtonPushedFcn = createCallbackFcn(app, @RobotControlParamsButtonPushed, true);
            app.RobotControlParamsButton.Text = 'Parametri upravljanja robotom';
            app.WorkspaceParamsButton = uibutton(app.MainAppUIFigure, 'push');
            app.WorkspaceParamsButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceParamsButtonPushed, true);
            app.WorkspaceParamsButton.Text = 'Parametri radnog prostora';
            app.LoadParametersButton = uibutton(app.MainAppUIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Učitaj vrijednosti parametara';
            app.SaveParametersButton = uibutton(app.MainAppUIFigure, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Sačuvaj vrijednosti parametara';
            app.TLTypeLabel = uilabel(app.MainAppUIFigure);
            app.TLTypeLabel.Text = 'Tip tehničkog pisma';
            app.TLTypeDropDown = uidropdown(app.MainAppUIFigure);
            app.TLTypeDropDown.Items = {'A - usko', 'B - srednje', 'C - široko'};
            app.TLTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TLTypeDropDownValueChanged, true);
            app.TLTypeDropDown.Value = 'A - usko';
            app.FontSizeLabel = uilabel(app.MainAppUIFigure);
            app.FontSizeLabel.Text = 'Veličina fonta';
            app.FontSizeSpinner = uispinner(app.MainAppUIFigure);
            app.FontSizeSpinner.Limits = [8 192];
            app.FontSizeSpinner.ValueDisplayFormat = '%.0f';
            app.FontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @FontSizeSpinnerValueChanged, true);
            app.FontSizeSpinner.Value = 48;
            app.FontSizeUnitSwitch = uiswitch(app.MainAppUIFigure, 'slider');
            app.FontSizeUnitSwitch.Items = {'pt', 'mm'};
            app.FontSizeUnitSwitch.ValueChangedFcn = createCallbackFcn(app, @FontSizeUnitSwitchValueChanged, true);
            app.FontSizeUnitSwitch.Value = 'pt';
            app.TextToWriteLabel = uilabel(app.MainAppUIFigure);
            app.TextToWriteLabel.Text = 'Tekst za ispis';
            app.TextToWriteEditField = uieditfield(app.MainAppUIFigure, 'text');
            app.TextToWriteEditField.ValueChangedFcn = createCallbackFcn(app, @TextToWriteEditFieldValueChanged, true);
            app.TextToWriteEditField.ValueChangingFcn = createCallbackFcn(app, @TextToWriteEditFieldValueChanging, true);
            app.TextPreviewLabel = uilabel(app.MainAppUIFigure);
            app.TextPreviewLabel.Text = 'Prikaz teksta';
            app.ReturnToRefPoseLabel = uilabel(app.MainAppUIFigure);
            app.ReturnToRefPoseLabel.Text = {'Vratiti robot u referentni'; 'položaj nakon ispisa'};
            app.ReturnToRefPoseSwitch = uiswitch(app.MainAppUIFigure, 'slider');
            app.ReturnToRefPoseSwitch.Items = {'Ne', 'Da'};
            app.ReturnToRefPoseSwitch.ValueChangedFcn = createCallbackFcn(app, @ReturnToRefPoseSwitchValueChanged, true);
            app.ReturnToRefPoseSwitch.Value = 'Da';
            app.SimulateButton = uibutton(app.MainAppUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulacija';
            app.RobotTransferButton = uibutton(app.MainAppUIFigure, 'push');
            app.RobotTransferButton.ButtonPushedFcn = createCallbackFcn(app, @RobotTransferButtonPushed, true);
            app.RobotTransferButton.Text = 'Prenos na robota';
            app.TextPreviewPanel = uipanel(app.MainAppUIFigure);
            app.TextPreviewGridLayout = uigridlayout(app.TextPreviewPanel);
            app.TextPreviewHTML = uihtml(app.TextPreviewGridLayout);
            app.TextPreviewHTML.HTMLSource = '<!DOCTYPE html><html></html>';
            % Show the figure after all components are created
            app.MainAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainAppUIFigure)
        end
    end
end