classdef robot_params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotParamsUIFigure             matlab.ui.Figure
        UnitsInfoLabel                  matlab.ui.control.Label
        RobotNameLabel                  matlab.ui.control.Label
        RobotNameEditField              matlab.ui.control.EditField
        DHAngleUnitButtonGroup          matlab.ui.container.ButtonGroup
        DHRadButton                     matlab.ui.control.RadioButton
        DHDegreeButton                  matlab.ui.control.RadioButton
        DHAngleUnitLabel                matlab.ui.control.Label
        QLimsPanel                      matlab.ui.container.Panel
        QLimsGridLayout                 matlab.ui.container.GridLayout
        qminLabel                       matlab.ui.control.Label
        qmaxLabel                       matlab.ui.control.Label
        q0Label                         matlab.ui.control.Label
        q1Label                         matlab.ui.control.Label
        q2Label                         matlab.ui.control.Label
        q3Label                         matlab.ui.control.Label
        q4Label                         matlab.ui.control.Label
        q5Label                         matlab.ui.control.Label
        q0minEditField                  matlab.ui.control.EditField
        q0maxEditField                  matlab.ui.control.EditField
        q1minEditField                  matlab.ui.control.EditField
        q1maxEditField                  matlab.ui.control.EditField
        q2minEditField                  matlab.ui.control.EditField
        q2maxEditField                  matlab.ui.control.EditField
        q3minEditField                  matlab.ui.control.EditField
        q3maxEditField                  matlab.ui.control.EditField
        q4minEditField                  matlab.ui.control.EditField
        q4maxEditField                  matlab.ui.control.EditField
        q5minEditField                  matlab.ui.control.EditField
        q5maxEditField                  matlab.ui.control.EditField
        QLimsLabel                      matlab.ui.control.Label
        QLimsAngleUnitButtonGroup       matlab.ui.container.ButtonGroup
        QLimsRadButton                  matlab.ui.control.RadioButton
        QLimsDegreeButton               matlab.ui.control.RadioButton
        QLimsAngleUnitLabel             matlab.ui.control.Label
        DHKinematicParamsLabel          matlab.ui.control.Label
        DHKinematicParamsPanel          matlab.ui.container.Panel
        DHKinematicParamsGridLayout     matlab.ui.container.GridLayout
        thetaLabel                      matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        alphaLabel                      matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        cs01Label                       matlab.ui.control.Label
        cs12Label                       matlab.ui.control.Label
        cs23Label                       matlab.ui.control.Label
        cs34Label                       matlab.ui.control.Label
        cs45Label                       matlab.ui.control.Label
        cs56Label                       matlab.ui.control.Label
        theta01EditField                matlab.ui.control.EditField
        d01EditField                    matlab.ui.control.EditField
        a01EditField                    matlab.ui.control.EditField
        alpha01EditField                matlab.ui.control.EditField
        s01EditField                    matlab.ui.control.EditField
        theta12EditField                matlab.ui.control.EditField
        d12EditField                    matlab.ui.control.EditField
        a12EditField                    matlab.ui.control.EditField
        alpha12EditField                matlab.ui.control.EditField
        s12EditField                    matlab.ui.control.EditField
        theta23EditField                matlab.ui.control.EditField
        d23EditField                    matlab.ui.control.EditField
        a23EditField                    matlab.ui.control.EditField
        alpha23EditField                matlab.ui.control.EditField
        s23EditField                    matlab.ui.control.EditField
        theta34EditField                matlab.ui.control.EditField
        d34EditField                    matlab.ui.control.EditField
        a34EditField                    matlab.ui.control.EditField
        alpha34EditField                matlab.ui.control.EditField
        s34EditField                    matlab.ui.control.EditField
        theta45EditField                matlab.ui.control.EditField
        d45EditField                    matlab.ui.control.EditField
        a45EditField                    matlab.ui.control.EditField
        alpha45EditField                matlab.ui.control.EditField
        s45EditField                    matlab.ui.control.EditField
        theta56EditField                matlab.ui.control.EditField
        d56EditField                    matlab.ui.control.EditField
        a56EditField                    matlab.ui.control.EditField
        alpha56EditField                matlab.ui.control.EditField
        s56EditField                    matlab.ui.control.EditField
        RobotRepeatabilityLabel         matlab.ui.control.Label
        RobotRepeatabilityEditField     matlab.ui.control.EditField
        DefaultValuesButton             matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        ToolTransformLabel              matlab.ui.control.Label
        Ox6y6z6ToToolTipLabel           matlab.ui.control.Label
        ToolTransformPanel              matlab.ui.container.Panel
        ToolTransformGridLayout         matlab.ui.container.GridLayout
        TT11EditField                   matlab.ui.control.EditField
        TT12EditField                   matlab.ui.control.EditField
        TT13EditField                   matlab.ui.control.EditField
        TT14EditField                   matlab.ui.control.EditField
        TT21EditField                   matlab.ui.control.EditField
        TT22EditField                   matlab.ui.control.EditField
        TT23EditField                   matlab.ui.control.EditField
        TT24EditField                   matlab.ui.control.EditField
        TT31EditField                   matlab.ui.control.EditField
        TT32EditField                   matlab.ui.control.EditField
        TT33EditField                   matlab.ui.control.EditField
        TT34EditField                   matlab.ui.control.EditField
        TT41EditField                   matlab.ui.control.EditField
        TT42EditField                   matlab.ui.control.EditField
        TT43EditField                   matlab.ui.control.EditField
        TT44EditField                   matlab.ui.control.EditField
        CardanAnglesTypeDropDown        matlab.ui.control.DropDown
        EulerAnglesTypeDropDown         matlab.ui.control.DropDown
        ToolOrientationAnglesTypeLabel  matlab.ui.control.Label
        TOATButton                      matlab.ui.control.Button
        ToolOrientationDescLabel        matlab.ui.control.Label
        TOPsiEditField                  matlab.ui.control.EditField
        TOPhiEditField                  matlab.ui.control.EditField
        TOThetaEditField                matlab.ui.control.EditField
        TOPsiLabel                      matlab.ui.control.Label
        TOPhiLabel                      matlab.ui.control.Label
        TOThetaLabel                    matlab.ui.control.Label
        TOAngleUnitLabel                matlab.ui.control.Label
        TOAngleUnitButtonGroup          matlab.ui.container.ButtonGroup
        TORadButton                     matlab.ui.control.RadioButton
        TODegreeButton                  matlab.ui.control.RadioButton
        ToolOrientationSelectionMethodDropDown  matlab.ui.control.DropDown
        ToolOrientationLabel            matlab.ui.control.Label
        ToolOrientationMatrixPanel      matlab.ui.container.Panel
        ToolOrientationMatrixGridLayout  matlab.ui.container.GridLayout
        TOM11EditField                  matlab.ui.control.EditField
        TOM12EditField                  matlab.ui.control.EditField
        TOM13EditField                  matlab.ui.control.EditField
        TOM21EditField                  matlab.ui.control.EditField
        TOM22EditField                  matlab.ui.control.EditField
        TOM23EditField                  matlab.ui.control.EditField
        TOM31EditField                  matlab.ui.control.EditField
        TOM32EditField                  matlab.ui.control.EditField
        TOM33EditField                  matlab.ui.control.EditField
        ToolOrientationSelectionMethodLabel  matlab.ui.control.Label
        RefPosLabel                     matlab.ui.control.Label
        RxLabel                         matlab.ui.control.Label
        RxEditField                     matlab.ui.control.EditField
        RyLabel                         matlab.ui.control.Label
        RyEditField                     matlab.ui.control.EditField
        RzLabel                         matlab.ui.control.Label
        RzEditField                     matlab.ui.control.EditField
        PathToModelsEditField           matlab.ui.control.EditField
        PathToModelsButton              matlab.ui.control.Button
        PathToModelsLabel               matlab.ui.control.Label
        RobotPreviewLabel               matlab.ui.control.Label
        LinkedCoordinateSystemsLabel    matlab.ui.control.Label
        RobotPreviewImage               matlab.ui.control.Image
        LinkedCoordinateSystemsImage    matlab.ui.control.Image
        CloseImagePreviewButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        caller_app; % Glavna aplikacija
        dws = [675,600]; % Podrazumijevana velicina prozora
        dws2 = [675,600,675,600];
        robot_params_temp; % Trenutni parametri robota
        models_pth_temp; % Trenutna putanja do models foldera
        im_enl = false; % Da li je neka od slika povecana
        vals_ok; % Da li su korektne sve vrijednosti parametara
        btn_timer; % Promjena boje teksta ApplyButton-a u zavisnosti
                   % da li je operacija uspjesno izvrsena
    end
    
    methods (Access = private)
        
        function setDefaultRobotName(app)
            app.RobotNameEditField.Value = 'Mitsubishi RV-2SDB';
        end
        
        function setDefaultDHKinematicParameters(app)
            app.DHAngleUnitButtonGroup.SelectedObject = app.DHRadButton;
            
            app.theta01EditField.Value = '0';
            app.d01EditField.Value = '0.295';
            app.a01EditField.Value = '0';
            app.alpha01EditField.Value = '-pi/2';
            app.s01EditField.Value = '0';
            
            app.theta12EditField.Value = '-pi/2';
            app.d12EditField.Value = '0';
            app.a12EditField.Value = '0.23';
            app.alpha12EditField.Value = '0';
            app.s12EditField.Value = '0';
            
            app.theta23EditField.Value = 'pi/2';
            app.d23EditField.Value = '0';
            app.a23EditField.Value = '-0.05';
            app.alpha23EditField.Value = 'pi/2';
            app.s23EditField.Value = '0';
            
            app.theta34EditField.Value = '0';
            app.d34EditField.Value = '0.27';
            app.a34EditField.Value = '0';
            app.alpha34EditField.Value = '-pi/2';
            app.s34EditField.Value = '0';
            
            app.theta45EditField.Value = '0';
            app.d45EditField.Value = '0';
            app.a45EditField.Value = '0';
            app.alpha45EditField.Value = 'pi/2';
            app.s45EditField.Value = '0';
            
            app.theta56EditField.Value = '0';
            app.d56EditField.Value = '0.07';
            app.a56EditField.Value = '0';
            app.alpha56EditField.Value = '0';
            app.s56EditField.Value = '0';
        end
        
        function setDefaultQLims(app)
            app.QLimsAngleUnitButtonGroup.SelectedObject = app.QLimsDegreeButton;
            
            app.q0minEditField.Value = '-240';
            app.q0maxEditField.Value = '240';
            
            app.q1minEditField.Value = '-120';
            app.q1maxEditField.Value = '120';
            
            app.q2minEditField.Value = '0';
            app.q2maxEditField.Value = '160';
            
            app.q3minEditField.Value = '-200';
            app.q3maxEditField.Value = '200';
            
            app.q4minEditField.Value = '-120';
            app.q4maxEditField.Value = '120';
            
            app.q5minEditField.Value = '-360';
            app.q5maxEditField.Value = '360';
        end
        
        function setDefaultRobotRepeatabilityValue(app)
            app.RobotRepeatabilityEditField.Value = '0.00002';
        end
        
        function setDefaultToolTransformValues(app)
            app.TT11EditField.Value = '1';
            app.TT12EditField.Value = '0';
            app.TT13EditField.Value = '0';
            app.TT14EditField.Value = '0.03';
            app.TT21EditField.Value = '0';
            app.TT22EditField.Value = '1';
            app.TT23EditField.Value = '0';
            app.TT24EditField.Value = '0';
            app.TT31EditField.Value = '0';
            app.TT32EditField.Value = '0';
            app.TT33EditField.Value = '1';
            app.TT34EditField.Value = '0.176';
            app.TT41EditField.Value = '0';
            app.TT42EditField.Value = '0';
            app.TT43EditField.Value = '0';
            app.TT44EditField.Value = '1';
        end
        
        function setDefaultToolOrientationValues(app)
            app.ToolOrientationSelectionMethodDropDown.Value = 'Matrica rotacije';
            app.ToolOrientationAnglesTypeLabel.Visible = false;
            app.EulerAnglesTypeDropDown.Visible = false;
            app.EulerAnglesTypeDropDown.Value = 'z→y''→z'''' / z0←y0←z0';
            app.CardanAnglesTypeDropDown.Visible = false;
            app.CardanAnglesTypeDropDown.Value = 'z→y''→x'''' / z0←y0←x0 - ROLL-PITCH-YAW';
            app.TOAngleUnitButtonGroup.SelectedObject = app.TORadButton;
            
            app.TOM11EditField.Editable = true;
            app.TOM11EditField.Value = '0';
            app.TOM12EditField.Editable = true;
            app.TOM12EditField.Value = '1';
            app.TOM13EditField.Editable = true;
            app.TOM13EditField.Value = '0';
            app.TOM21EditField.Editable = true;
            app.TOM21EditField.Value = '1';
            app.TOM22EditField.Editable = true;
            app.TOM22EditField.Value = '0';
            app.TOM23EditField.Editable = true;
            app.TOM23EditField.Value = '0';
            app.TOM31EditField.Editable = true;
            app.TOM31EditField.Value = '0';
            app.TOM32EditField.Editable = true;
            app.TOM32EditField.Value = '0';
            app.TOM33EditField.Editable = true;
            app.TOM33EditField.Value = '-1';
            
            app.TOATButton.Text = 'O.1-zyz';
            app.TOThetaEditField.Editable = false;
            app.TOThetaEditField.Value = '0';
            app.TOPhiEditField.Editable = false;
            app.TOPhiEditField.Value = 'pi';
            app.TOPsiEditField.Editable = false;
            app.TOPsiEditField.Value = 'pi/2';
        end
        
        function setDefaultReferentPositionValue(app)
            app.RxEditField.Value = '-0.075';
            app.RyEditField.Value = '0.21';
            app.RzEditField.Value = '0.3';
        end
        
        function setDefaultPathToModels(app)
            app.PathToModelsEditField.Value = fullfile(fileparts(mfilename('fullpath')), 'models');
        end
        
        function setDHValue(app, ef)
            tag = str2double(ef.Tag);
            if(mod(tag,5)) == 0
                i = fix(tag/5);
            else
                i = fix(tag/5)+1;
            end
            j = tag-(i-1)*5;
            if app.vals_ok(tag)
                if j == 1 || j == 4
                    if app.DHAngleUnitButtonGroup.SelectedObject == app.DHRadButton
                        app.robot_params_temp.dh(i,j) = eval(ef.Value);
                    else
                        app.robot_params_temp.dh(i,j) = eval(ef.Value)*pi/180;
                    end
                else
                    app.robot_params_temp.dh(i,j) = eval(ef.Value);
                end
            else
                app.robot_params_temp.dh(i,j) = NaN;
            end
        end
        
        function setOffsetValue(app, ef)
            tag = str2double(ef.Tag);
            if(app.vals_ok(tag))
                switch(str2double(ef.Tag))
                    case 1
                        app.robot_params_temp.offset(1) = eval(ef.Value);
                    case 6
                        app.robot_params_temp.offset(2) = eval(ef.Value);
                    case 11
                        app.robot_params_temp.offset(3) = eval(ef.Value);
                    case 16
                        app.robot_params_temp.offset(4) = eval(ef.Value);
                    case 21
                        app.robot_params_temp.offset(5) = eval(ef.Value);
                    case 26
                        app.robot_params_temp.offset(6) = eval(ef.Value);
                end
            else
                switch(str2double(ef.Tag))
                    case 1
                        app.robot_params_temp.offset(1) = NaN;
                    case 6
                        app.robot_params_temp.offset(2) = NaN;
                    case 11
                        app.robot_params_temp.offset(3) = NaN;
                    case 16
                        app.robot_params_temp.offset(4) = NaN;
                    case 21
                        app.robot_params_temp.offset(5) = eval(ef.Value);
                    case 26
                        app.robot_params_temp.offset(6) = eval(ef.Value);
                end
            end
        end
        
        function setQLim(app, ef)
            tag = str2double(ef.Tag);
            mtag = tag-30;
            if(mod(mtag,2)) == 0
                i = fix(mtag/2);
            else
                i = fix(mtag/2)+1;
            end
            j = mtag-(i-1)*2;
            if app.vals_ok(tag)
                if app.QLimsAngleUnitButtonGroup.SelectedObject == app.QLimsRadButton
                    app.robot_params_temp.qlim(i,j) = eval(ef.Value);
                else
                    app.robot_params_temp.qlim(i,j) = eval(ef.Value)*pi/180;
                end
            else
                app.robot_params_temp.qlim(i,j) = NaN;
            end
        end
        
        function setRobotRepeatabilityValue(app)
            if app.vals_ok(str2double(app.RobotRepeatabilityEditField.Tag))
                app.robot_params_temp.rep =  eval(app.RobotRepeatabilityEditField.Value);
            else
                app.robot_params_temp.rep = NaN;
            end
        end
        
        function setToolTransformValue(app, ef)
            tag = str2double(ef.Tag);
            mtag = tag-43;
            if(mod(mtag,4)) == 0
                i = fix(mtag/4);
            else
                i = fix(mtag/4)+1;
            end
            j = mtag-(i-1)*4;
            if app.vals_ok(tag)
                app.robot_params_temp.tool(i,j) = eval(ef.Value);
            else
                app.robot_params_temp.tool(i,j) = NaN;
            end
        end
        
        function setTOMValue(app, ef)
            tag = str2double(ef.Tag);
            mtag = tag-59;
            if(mod(mtag,3)) == 0
                i = fix(mtag/3);
            else
                i = fix(mtag/3)+1;
            end
            j = mtag-(i-1)*3;
            if app.vals_ok(tag)
                app.robot_params_temp.tom(i,j) = eval(ef.Value);
                app.robot_params_temp.Tr(i,j) = eval(ef.Value);
            else
                app.robot_params_temp.tom(i,j) = NaN;
                app.robot_params_temp.Tr(i,j) = NaN;
            end
        end
        
        function setTOMValueFromTOA(app)
            toa = app.getTOAngles();
            cha = app.vals_ok(str2double(app.TOThetaEditField.Tag)) && ...
                  app.vals_ok(str2double(app.TOPhiEditField.Tag)) && ...
                  app.vals_ok(str2double(app.TOPsiEditField.Tag));
            if cha
                app.robot_params_temp.tom = app.angles2rotmat(toa);
            else
                app.robot_params_temp.tom = NaN(3,3);
            end
        end
        
        function setReferentPoseValue(app, ef)
            tag = str2double(ef.Tag);
            mtag = tag - 71;
            if app.vals_ok(tag)
                app.robot_params_temp.Tr(mtag,4) = eval(ef.Value);
            else
                app.robot_params_temp.Tr(mtag,4) = NaN;
            end
        end
        
        function check = isSValid(app, val)
            if val == 0 || val == 1
                check = true;
            else
                check = false;
            end
        end
        
        function check = isNumValueValid(app, val)
            mval = erase(val,{'pi','sin'});
            check = ~contains(mval,{'%',',','i'});
            try
                mval = eval(val);
            catch
                check = false;
            end
        end
        
        function updateEFState(app,check,ef)
            if check
                ef.BackgroundColor = 'white';
                app.vals_ok(str2double(ef.Tag)) = true;
            else
                ef.BackgroundColor = [1 0.7 0.7];
                app.vals_ok(str2double(ef.Tag)) = false;
            end
        end
        
        function updateTOMState(app)
            mat = app.robot_params_temp.tom;
            if ~isnan(norm(mat))
                if app.isRotMatValid(mat)
                    for i = 1:3
                        for j = 1:3
                            app.(sprintf('TOM%d%dEditField',i,j)).BackgroundColor = 'white';
                        end
                    end
                    app.vals_ok(76) = true;
                else
                    for i = 1:3
                        for j = 1:3
                            app.(sprintf('TOM%d%dEditField',i,j)).BackgroundColor = [1 0.8 0.5];
                        end
                    end
                    app.vals_ok(76) = false;
                end
            else
                app.vals_ok(76) = false;
            end
        end
        
        function updateTTRMState(app)
            mat = app.robot_params_temp.tool(1:3,1:3);
            if ~isnan(norm(mat))
                if app.isRotMatValid(mat)
                    for i = 1:3
                        for j = 1:3
                            app.(sprintf('TT%d%dEditField',i,j)).BackgroundColor = 'white';
                        end
                    end
                    app.vals_ok(77) = true;
                else
                    for i = 1:3
                        for j = 1:3
                            app.(sprintf('TT%d%dEditField',i,j)).BackgroundColor = [1 0.8 0.5];
                        end
                    end
                    app.vals_ok(77) = false;
                end
            else
                app.vals_ok(77) = false;
            end
        end
        
        function check = isRotMatValid(app, mat)
            check = true;
            e = mat*mat' - eye(3,3);
            if isnan(norm(e)) || norm(e) > 10*eps
                check = false;
            else
                e = mat'*mat - eye(3,3);
                if isnan(norm(e)) || norm(e) > 10*eps
                    check = false;
                else
                    e = abs(det(mat) - 1);
                    if isnan(norm(e)) || norm(e) > 10*eps
                        check = false;
                    end
                end
            end
        end
        
        function angles = rotmat2angles(app, R)
            tbt = app.TOATButton.Text;
            
            switch tbt
                case 'O.1-zyz'
                    phi = acos(R(3,3));
                    if abs(abs(R(3,3))-1) < 10*eps
                        theta = 0;
                        psi = atan2(R(2,1),R(2,2));
                    else
                        theta = atan2(R(2,3)/sin(phi),R(1,3)/sin(phi));
                        psi = atan2(R(3,2)/sin(phi),-R(3,1)/sin(phi));
                    end
                case 'O.2-zxz'
                    phi = acos(R(3,3));
                    if abs(abs(R(3,3))-1) < 10*eps
                        theta = 0;
                        psi = atan2(-R(1,2),R(1,1));
                    else
                        theta = atan2(R(1,3)/sin(phi),-R(2,3)/sin(phi));
                        psi = atan2(R(3,1)/sin(phi),R(3,2)/sin(phi));
                    end
                case 'O.3-yzy'
                    phi = acos(R(2,2));
                    if abs(abs(R(2,2))-1) < 10*eps
                        theta = 0;
                        psi = atan2(-R(3,1),R(3,3));
                    else
                        theta = atan2(R(3,2)/sin(phi),-R(1,2)/sin(phi));
                        psi = atan2(R(2,3)/sin(phi),R(2,1)/sin(phi));
                    end
                case 'O.4-yxy'
                    phi = acos(R(2,2));
                    if abs(abs(R(2,2))-1) < 10*eps
                        theta = 0;
                        psi = atan2(R(1,3),R(1,1));
                    else
                        theta = atan2(R(1,2)/sin(phi),R(3,2)/sin(phi));
                        psi = atan2(R(2,1)/sin(phi),-R(2,3)/sin(phi));
                    end
                case 'O.5-xzx'
                    phi = acos(R(1,1));
                    if abs(abs(R(1,1))-1) < 10*eps
                        theta = 0;
                        psi = atan2(R(3,2),R(3,3));
                    else
                        theta = atan2(R(3,1)/sin(phi),R(2,1)/sin(phi));
                        psi = atan2(R(1,3)/sin(phi),-R(1,2)/sin(phi));
                    end
                case 'O.6-xyx'
                    phi = acos(R(1,1));
                    if abs(abs(R(1,1))-1) < 10*eps
                        theta = 0;
                        psi = atan2(-R(2,3),R(2,2));
                    else
                        theta = atan2(R(2,1)/sin(phi),-R(3,1)/sin(phi));
                        psi = atan2(R(1,2)/sin(phi),R(1,3)/sin(phi));
                    end
                case 'A.1-xyz'
                    phi = -asin(R(3,1));
                    if abs(abs(R(3,1))-1) < 10*eps
                        theta = 0;
                        psi = atan2(-R(2,3),R(2,2));
                    else
                        theta = atan2(R(2,1)/cos(phi),R(1,1)/cos(phi));
                        psi = atan2(R(3,2)/cos(phi),R(3,3)/cos(phi));
                    end
                case 'A.2-yxz'
                    phi = asin(R(3,2));
                    if abs(abs(R(3,2))-1) < 10*eps
                        theta = 0;
                        psi = atan2(R(1,3),R(1,1));
                    else
                        theta = atan2(-R(1,2)/cos(phi),R(2,1)/cos(phi));
                        psi = atan2(-R(3,1)/cos(phi),R(3,3)/cos(phi));
                    end
                case 'A.3-xzy'
                    phi = asin(R(2,1));
                    if abs(abs(R(2,1))-1) < 10*eps
                        theta = 0;
                        psi = atan2(R(3,2),R(3,3));
                    else
                        theta = atan2(-R(3,1)/cos(phi),R(1,1)/cos(phi));
                        psi = atan2(-R(2,3)/cos(phi),R(2,2)/cos(phi));
                    end
                case 'A.4-zxy'
                    phi = -asin(R(2,3));
                    if abs(abs(R(2,3))-1) < 10*eps
                        theta = 0;
                        psi = atan2(-R(1,2),R(1,1));
                    else
                        theta = atan2(R(1,3)/cos(phi),R(3,3)/cos(phi));
                        psi = atan2(R(2,1)/cos(phi),R(2,2)/cos(phi));
                    end
                case 'A.5-yzx'
                    phi = -asin(R(1,2));
                    if abs(abs(R(1,2))-1) < 10*eps
                        theta = 0;
                        psi = atan2(-R(3,1),R(3,3));
                    else
                        theta = atan2(R(3,2)/cos(phi),R(2,2)/cos(phi));
                        psi = atan2(R(1,3)/cos(phi),R(1,1)/cos(phi));
                    end
                case 'A.6-zyx'
                    phi = asin(R(1,3));
                    if abs(abs(R(1,3))-1) < 10*eps
                        theta = 0;
                        psi = atan2(R(2,1),R(2,2));
                    else
                        theta = atan2(-R(2,3)/cos(phi),R(3,3)/cos(phi));
                        psi = atan2(-R(1,2)/cos(phi),R(1,1)/cos(phi));
                    end
            end
            
            angles = [theta phi psi];
            angles(abs(angles) < 10*eps) = 0;
        end
        
        function R = angles2rotmat(app, angles)
            tbt = app.TOATButton.Text;
            theta = angles(1);
            phi = angles(2);
            psi = angles(3);
            
            switch tbt
                case 'O.1-zyz'
                    R = [ cos(phi)*cos(psi)*cos(theta)-sin(psi)*sin(theta), -cos(psi)*sin(theta)-cos(phi)*cos(theta)*sin(psi),  cos(theta)*sin(phi);
                          cos(theta)*sin(psi)+cos(phi)*cos(psi)*sin(theta),  cos(psi)*cos(theta)-cos(phi)*sin(psi)*sin(theta),  sin(phi)*sin(theta);
                                                        -cos(psi)*sin(phi),                                 sin(phi)*sin(psi),             cos(phi)];
                case 'O.2-zxz'
                    R = [ cos(psi)*cos(theta)-cos(phi)*sin(psi)*sin(theta), -cos(theta)*sin(psi)-cos(phi)*cos(psi)*sin(theta),  sin(phi)*sin(theta);
                          cos(psi)*sin(theta)+cos(phi)*cos(theta)*sin(psi),  cos(phi)*cos(psi)*cos(theta)-sin(psi)*sin(theta), -cos(theta)*sin(phi);
                                                         sin(phi)*sin(psi),                                 cos(psi)*sin(phi),             cos(phi)];
                case 'O.3-yzy'
                    R = [ cos(phi)*cos(psi)*cos(theta)-sin(psi)*sin(theta), -cos(theta)*sin(phi),  cos(psi)*sin(theta)+cos(phi)*cos(theta)*sin(psi);
                                                         cos(psi)*sin(phi),             cos(phi),                                 sin(phi)*sin(psi);
                         -cos(theta)*sin(psi)-cos(phi)*cos(psi)*sin(theta),  sin(phi)*sin(theta),  cos(psi)*cos(theta)-cos(phi)*sin(psi)*sin(theta)];
                case 'O.4-yxy'
                    R = [ cos(psi)*cos(theta)-cos(phi)*sin(psi)*sin(theta),  sin(phi)*sin(theta),  cos(theta)*sin(psi)+cos(phi)*cos(psi)*sin(theta);
                                                         sin(phi)*sin(psi),             cos(phi),                                -cos(psi)*sin(phi);
                         -cos(psi)*sin(theta)-cos(phi)*cos(theta)*sin(psi),  cos(theta)*sin(phi),  cos(phi)*cos(psi)*cos(theta)-sin(psi)*sin(theta)];
                case 'O.5-xzx'
                    R = [            cos(phi),                                -cos(psi)*sin(phi),                                 sin(phi)*sin(psi);
                          cos(theta)*sin(phi),  cos(phi)*cos(psi)*cos(theta)-sin(psi)*sin(theta), -cos(psi)*sin(theta)-cos(phi)*cos(theta)*sin(psi);
                          sin(phi)*sin(theta),  cos(theta)*sin(psi)+cos(phi)*cos(psi)*sin(theta),  cos(psi)*cos(theta)-cos(phi)*sin(psi)*sin(theta)];
                case 'O.6-xyx'
                    R = [            cos(phi),                                 sin(phi)*sin(psi),                                 cos(psi)*sin(phi);
                          sin(phi)*sin(theta),  cos(psi)*cos(theta)-cos(phi)*sin(psi)*sin(theta), -cos(theta)*sin(psi)-cos(phi)*cos(psi)*sin(theta);
                         -cos(theta)*sin(phi),  cos(psi)*sin(theta)+cos(phi)*cos(theta)*sin(psi),  cos(phi)*cos(psi)*cos(theta)-sin(psi)*sin(theta)];
                case 'A.1-xyz'
                    R = [ cos(phi)*cos(theta),  cos(theta)*sin(phi)*sin(psi)-cos(psi)*sin(theta),  sin(psi)*sin(theta)+cos(psi)*cos(theta)*sin(phi);
                          cos(phi)*sin(theta),  cos(psi)*cos(theta)+sin(phi)*sin(psi)*sin(theta),  cos(psi)*sin(phi)*sin(theta)-cos(theta)*sin(psi);
                                    -sin(phi),                                 cos(phi)*sin(psi),                                 cos(phi)*cos(psi)];
                case 'A.2-yxz'
                    R = [ cos(psi)*cos(theta)-sin(phi)*sin(psi)*sin(theta), -cos(phi)*sin(theta),  cos(theta)*sin(psi)+cos(psi)*sin(phi)*sin(theta);
                          cos(psi)*sin(theta)+cos(theta)*sin(phi)*sin(psi),  cos(phi)*cos(theta),  sin(psi)*sin(theta)-cos(psi)*cos(theta)*sin(phi);
                                                        -cos(phi)*sin(psi),             sin(phi),                                 cos(phi)*cos(psi)];
                case 'A.3-xzy'
                    R = [ cos(phi)*cos(theta),  sin(psi)*sin(theta)-cos(psi)*cos(theta)*sin(phi),  cos(psi)*sin(theta)+cos(theta)*sin(phi)*sin(psi);
                                     sin(phi),                                 cos(phi)*cos(psi),                                -cos(phi)*sin(psi);
                         -cos(phi)*sin(theta),  cos(theta)*sin(psi)+cos(psi)*sin(phi)*sin(theta),  cos(psi)*cos(theta)-sin(phi)*sin(psi)*sin(theta)];
                case 'A.4-zxy'
                    R = [ cos(psi)*cos(theta)+sin(phi)*sin(psi)*sin(theta),  cos(psi)*sin(phi)*sin(theta)-cos(theta)*sin(psi),  cos(phi)*sin(theta);
                                                         cos(phi)*sin(psi),                                 cos(phi)*cos(psi),            -sin(phi);
                          cos(theta)*sin(phi)*sin(psi)-cos(psi)*sin(theta),  sin(psi)*sin(theta)+cos(psi)*cos(theta)*sin(phi),  cos(phi)*cos(theta)];
                case 'A.5-yzx'
                    R = [                                cos(phi)*cos(psi),            -sin(phi),                                 cos(phi)*sin(psi);
                          sin(psi)*sin(theta)+cos(psi)*cos(theta)*sin(phi),  cos(phi)*cos(theta),  cos(theta)*sin(phi)*sin(psi)-cos(psi)*sin(theta);
                          cos(psi)*sin(phi)*sin(theta)-cos(theta)*sin(psi),  cos(phi)*sin(theta),  cos(psi)*cos(theta)+sin(phi)*sin(psi)*sin(theta)];
                case 'A.6-zyx'
                    R = [                                cos(phi)*cos(psi),                                -cos(phi)*sin(psi),             sin(phi);
                          cos(theta)*sin(psi)+cos(psi)*sin(phi)*sin(theta),  cos(psi)*cos(theta)-sin(phi)*sin(psi)*sin(theta), -cos(phi)*sin(theta);
                          sin(psi)*sin(theta)-cos(psi)*cos(theta)*sin(phi),  cos(psi)*sin(theta)+cos(theta)*sin(phi)*sin(psi),  cos(phi)*cos(theta)];
            end
            
            R(abs(R)<10*eps) = 0;
        end
        
        function updateTOAngles(app)
            chmat = app.isRotMatValid(app.robot_params_temp.tom);
            if chmat
                angles = app.rotmat2angles(app.robot_params_temp.tom);
                if(app.TOAngleUnitButtonGroup.SelectedObject == app.TORadButton)
                    if(hasSymType(sym(angles(1)),'constant & ~number'))
                        app.TOThetaEditField.Value = string(sym(angles(1)));
                    else
                        app.TOThetaEditField.Value = strip(strip(num2str(angles(1),'%.9f'),'right','0'),'right','.');
                    end
                    if(hasSymType(sym(angles(2)),'constant & ~number'))
                        app.TOPhiEditField.Value = string(sym(angles(2)));
                    else
                        app.TOPhiEditField.Value = strip(strip(num2str(angles(2),'%.9f'),'right','0'),'right','.');
                    end
                    if(hasSymType(sym(angles(3)),'constant & ~number'))
                        app.TOPsiEditField.Value = string(sym(angles(3)));
                    else
                        app.TOPsiEditField.Value = strip(strip(num2str(angles(3),'%.9f'),'right','0'),'right','.');
                    end
                else
                    app.TOThetaEditField.Value = strip(strip(num2str(angles(1)*180/pi,'%.9f'),'right','0'),'right','.');
                    app.TOPhiEditField.Value = strip(strip(num2str(angles(2)*180/pi,'%.9f'),'right','0'),'right','.');
                    app.TOPsiEditField.Value = strip(strip(num2str(angles(3)*180/pi,'%.9f'),'right','0'),'right','.');
                end
            else
                app.TOThetaEditField.Value = 'NaN';
                app.TOPhiEditField.Value = 'NaN';
                app.TOPsiEditField.Value = 'NaN';
            end
        end
        
        function toa = getTOAngles(app)
        	toa = NaN(1,3);
        	
        	if app.isNumValueValid(app.TOThetaEditField.Value)
        		toa(1) = eval(app.TOThetaEditField.Value);
        	end
        	if app.isNumValueValid(app.TOPhiEditField.Value)
        		toa(2) = eval(app.TOPhiEditField.Value);
        	end
        	if app.isNumValueValid(app.TOPsiEditField.Value)
        		toa(3) = eval(app.TOPsiEditField.Value);
        	end
            
            if app.TOAngleUnitButtonGroup.SelectedObject == app.TODegreeButton
                toa = toa*pi/180;
            end
        end
        
        function updateTOMatrix(app)
            cha = app.vals_ok(str2double(app.TOThetaEditField.Tag)) && ...
                  app.vals_ok(str2double(app.TOPhiEditField.Tag)) && ...
                  app.vals_ok(str2double(app.TOPsiEditField.Tag));
            if cha
                for i = 1 : 3
                    for j = 1 : 3
                       app.(sprintf('TOM%d%dEditField',i,j)).Value = strip(strip(num2str(app.robot_params_temp.tom(i,j),'%.9f'),'right','0'),'right','.');
                    end
                end
            else
                for i = 1 : 3
                    for j = 1 : 3
                       app.(sprintf('TOM%d%dEditField',i,j)).Value = 'NaN';
                    end
                end
            end
        end
        
        function check = isPathToModelsValid(app, path)
            check = true;
            if isfolder(path)
                lpath = fullfile(path,'links');
                nlinks = sum(cellfun(@(name)(contains(name,'.stl','IgnoreCase',true)), {dir(lpath).name}));
                if nlinks == 0
                    check = false;
                end
            else
                check = false;
            end
        end
        
        function timer_func(app, obj, event)
            app.ApplyButton.FontColor = 'black';
            app.RxEditField.BackgroundColor = 'white';
            app.RyEditField.BackgroundColor = 'white';
            app.RzEditField.BackgroundColor = 'white';
        end
        
        function timer_stop_func(app, obj, event)
            delete(obj);
        end
        
        function loadRobotName(app)
            app.RobotNameEditField.Value = app.caller_app.robot_params.name;
        end
        
        function loadDHKinematicParameters(app)
            if strcmp(app.caller_app.robot_params.dh_angle_unit, 'rad')
                app.DHAngleUnitButtonGroup.SelectedObject = app.DHRadButton;
                for i = 1:6
                    for j = 1:5
                        if(hasSymType(sym(app.caller_app.robot_params.dh(i,j)),'constant & ~number'))
                            app.DHKinematicParamsGridLayout.Children(eval(sprintf('(%d-1)*5+11+%d',i,j))).Value = string(sym(app.caller_app.robot_params.dh(i,j)));
                        else
                            app.DHKinematicParamsGridLayout.Children(eval(sprintf('(%d-1)*5+11+%d',i,j))).Value = strip(strip(num2str(app.caller_app.robot_params.dh(i,j),'%.9f'),'right','0'),'right','.');
                        end
                    end
                end
            else
                app.DHAngleUnitButtonGroup.SelectedObject = app.DHDegreeButton;
                for i = 1:6
                    for j = 1:5
                        if j == 1 || j == 5
                            app.DHKinematicParamsGridLayout.Children(eval(sprintf('(%d-1)*5+11+%d',i,j))).Value = strip(strip(num2str(app.caller_app.robot_params.dh(i,j)*180/pi,'%.9f'),'right','0'),'right','.');
                        else
                            app.DHKinematicParamsGridLayout.Children(eval(sprintf('(%d-1)*5+11+%d',i,j))).Value = strip(strip(num2str(app.caller_app.robot_params.dh(i,j),'%.9f'),'right','0'),'right','.');
                        end
                    end
                end
            end
        end
        
        function loadQLims(app)
            if strcmp(app.caller_app.robot_params.qlim_angle_unit, 'rad')
                app.QLimsAngleUnitButtonGroup.SelectedObject = app.QLimsRadButton;
                for i = 1:6
                    for j = 1:2
                        if(hasSymType(sym(app.caller_app.robot_params.qlim(i,j)),'constant & ~number'))
                            app.QLimsGridLayout.Children(eval(sprintf('(%d-1)*2+8+%d',i,j))).Value = string(sym(app.caller_app.robot_params.qlim(i,j)));
                        else
                            app.QLimsGridLayout.Children(eval(sprintf('(%d-1)*2+8+%d',i,j))).Value = strip(strip(num2str(app.caller_app.robot_params.qlim(i,j),'%.9f'),'right','0'),'right','.');
                        end
                    end
                end
            else
                app.QLimsAngleUnitButtonGroup.SelectedObject = app.QLimsDegreeButton;
                for i = 1:6
                    for j = 1:2
                        app.QLimsGridLayout.Children(eval(sprintf('(%d-1)*2+8+%d',i,j))).Value = strip(strip(num2str(app.caller_app.robot_params.qlim(i,j)*180/pi,'%.9f'),'right','0'),'right','.');
                    end
                end
            end
            
        end
        
        function loadRobotRepeatabilityValue(app)
            if(hasSymType(sym(app.caller_app.robot_params.rep),'constant & ~number'))
                app.RobotRepeatabilityEditField.Value = string(sym(app.caller_app.robot_params.rep));
            else
                app.RobotRepeatabilityEditField.Value = strip(strip(num2str(app.caller_app.robot_params.rep,'%.9f'),'right','0'),'right','.');
            end
        end
        
        function loadToolTransformValues(app)
            for i = 1:4
                for j = 1:4
                    if(hasSymType(sym(app.caller_app.robot_params.tool(i,j)),'constant & ~number'))
                        app.(sprintf('TT%d%dEditField',i,j)).Value = string(sym(app.caller_app.robot_params.tool(i,j)));
                    else
                        app.(sprintf('TT%d%dEditField',i,j)).Value = strip(strip(num2str(app.caller_app.robot_params.tool(i,j),'%.9f'),'right','0'),'right','.');
                    end
                end
            end
        end
        
        function loadToolOrientationValues(app)
            if strcmp(app.caller_app.robot_params.to_angle_unit, 'rad')
                app.TOAngleUnitButtonGroup.SelectedObject = app.TORadButton;
            else
                app.TOAngleUnitButtonGroup.SelectedObject = app.TODegreeButton;
            end
            for i = 1:3
                for j = 1:3
                    app.(sprintf('TOM%d%dEditField',i,j)).Value = strip(strip(num2str(app.caller_app.robot_params.tom(i,j),'%.9f'),'right','0'),'right','.');
                end
            end
            app.updateTOAngles();
        end
        
        function loadReferentPositionValue(app)
            app.RxEditField.Value = strip(strip(num2str(app.caller_app.robot_params.Tr(1,4),'%.9f'),'right','0'),'right','.');
            app.RyEditField.Value = strip(strip(num2str(app.caller_app.robot_params.Tr(2,4),'%.9f'),'right','0'),'right','.');
            app.RzEditField.Value = strip(strip(num2str(app.caller_app.robot_params.Tr(3,4),'%.9f'),'right','0'),'right','.');
        end
        
        function loadPathToModels(app)
            app.PathToModelsEditField.Value = app.caller_app.robot_params.models_pth;
        end
        
        function qfound = calculateRefPosInnerCoordinates(app)
            % Odredjivanje qr na osnovu Tr
            qfound = false;
            q0 = app.robot_params_temp.qr;
            qnum = 0;
            while ~qfound && qnum < 30 % dok nije pronadjen odgovarajuci vektor unutrasnjih koordinata
                [qc, err] = app.robot_params_temp.R.ikcon(app.robot_params_temp.Tr, q0); % izracunaj inverznu kinematiku za polozaj koji posmatramo
                if(err < app.robot_params_temp.rep) % ukoliko su pronadjene odgovarajuce unutrasnje koordinate
                    app.robot_params_temp.qr = qc;
                    qfound = true;
                else % u suprotnom
                    q0 = app.caller_app.genric(app.robot_params_temp.R.n, app.robot_params_temp.R.qlim); % ukoliko nije, generisi novi pocetni pogodak unutrasnjih koordinata i probaj ponovo
                    qnum = qnum + 1;
                end
            end
            if ~qfound
               error('Izabrana referentna pozicija nije dostizna.'); 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mapp)
            app.caller_app = mapp;
            
            app.robot_params_temp = app.caller_app.robot_params;
            
            app.loadRobotName();
            app.loadDHKinematicParameters();
            app.loadQLims();
            app.loadRobotRepeatabilityValue();
            app.loadToolTransformValues();
            app.loadToolOrientationValues();
            app.loadReferentPositionValue();
            app.loadPathToModels();
            
            app.vals_ok(1:77) = true;
        end

        % Button pushed function: DefaultValuesButton
        function DefaultValuesButtonPushed(app, event)
            app.setDefaultRobotName();
            app.setDefaultDHKinematicParameters();
            app.setDefaultQLims();
            app.setDefaultRobotRepeatabilityValue();
            app.setDefaultToolTransformValues();
            app.setDefaultToolOrientationValues();
            app.setDefaultReferentPositionValue();
            app.setDefaultPathToModels();
        end

        % Size changed function: RobotParamsUIFigure
        function RobotParamsUIFigureSizeChanged(app, event)
            cws = app.RobotParamsUIFigure.Position(3:4);
            if cws(1) < app.dws(1)
                cws(1) = app.dws(1);
            end
            if cws(2) < app.dws(2)
                cws(2) = app.dws(2);
            end
            cws2 = [cws,cws];
            
            app.UnitsInfoLabel.Position(1:2) = [20,568]./app.dws.*cws;
            app.UnitsInfoLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.RobotNameLabel.Position(1:2) = [20,528]./app.dws.*cws;
            app.RobotNameLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.RobotNameEditField.Position(1:2:end) = [sum(app.RobotNameLabel.Position(1:2:end)),(301+42)/app.dws(1)*cws(1)-42];
            app.RobotNameEditField.Position(2:2:end) = [528,26]/app.dws(2)*cws(2);
            app.DHKinematicParamsLabel.Position(1:2) = [20,492]./app.dws.*cws;
            app.DHKinematicParamsLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.DHAngleUnitLabel.Position(1:2) = [20,468]./app.dws.*cws;
            app.DHAngleUnitLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.DHAngleUnitButtonGroup.Position(1:2) = [sum(app.DHAngleUnitLabel.Position(1:2:end)),468/app.dws(2)*cws(2)];
            app.DHRadButton.Position(4) = 22/app.dws(2)*cws(2);
            app.DHDegreeButton.Position(4) = 22/app.dws(2)*cws(2);
            app.DHKinematicParamsPanel.Position = [20,274,343,190]./app.dws2.*cws2;
            app.QLimsLabel.Position(1:2) = [20,238]./app.dws.*cws;
            app.QLimsLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.QLimsAngleUnitLabel.Position(1:2) = [20,214]./app.dws.*cws;
            app.QLimsAngleUnitLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.QLimsAngleUnitButtonGroup.Position(1:2) = [sum(app.QLimsAngleUnitLabel.Position(1:2:end)),214/app.dws(2)*cws(2)];
            app.QLimsRadButton.Position(4) = 22/app.dws(2)*cws(2);
            app.QLimsDegreeButton.Position(4) = 22/app.dws(2)*cws(2);
            app.QLimsPanel.Position = [20,20,144,190]./app.dws2.*cws2;
            app.RobotRepeatabilityLabel.Position(1:2) = [232,238]./app.dws.*cws;
            app.RobotRepeatabilityLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.RobotRepeatabilityEditField.Position = [232,210,130,26]./app.dws2.*cws2;
            app.DefaultValuesButton.Position = [390,528,127,52]./app.dws2.*cws2;
            app.ApplyButton.Position = [528,528,127,52]./app.dws2.*cws2;
            app.ToolTransformLabel.Position(1:2) = [390,492]./app.dws.*cws;
            app.ToolTransformLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.Ox6y6z6ToToolTipLabel.Position(1:2) = [sum(app.ToolTransformLabel.Position(1:2:end)),492/app.dws(2)*cws(2)];
            app.Ox6y6z6ToToolTipLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.ToolTransformPanel.Position = [390,379,229,109]./app.dws2.*cws2;
            app.ToolOrientationLabel.Position(1:2) = [390,342]./app.dws.*cws;
            app.ToolOrientationLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.ToolOrientationDescLabel.Position(1:2) = [sum(app.ToolOrientationLabel.Position(1:2:end)),322/app.dws(2)*cws(2)];
            app.ToolOrientationDescLabel.Position(4) = 42/app.dws(2)*cws(2);
            app.ToolOrientationSelectionMethodLabel.Position(1:2) = [390,318]./app.dws.*cws;
            app.ToolOrientationSelectionMethodLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.ToolOrientationSelectionMethodDropDown.Position = [390,296,136,22]./app.dws2.*cws2;
            app.ToolOrientationAnglesTypeLabel.Position(1:2) = [530,296]./app.dws.*cws;
            app.ToolOrientationAnglesTypeLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.EulerAnglesTypeDropDown.Position(1:2:end) = [sum(app.ToolOrientationAnglesTypeLabel.Position(1:2:end)),(100+25)/app.dws(1)*cws(1)-25];
            app.EulerAnglesTypeDropDown.Position(2:2:end) = [296,22]/app.dws(2)*cws(2);
            app.CardanAnglesTypeDropDown.Position(1:2:end) = [sum(app.ToolOrientationAnglesTypeLabel.Position(1:2:end)),(100+25)/app.dws(1)*cws(1)-25];
            app.CardanAnglesTypeDropDown.Position(2:2:end) = [296,22]/app.dws(2)*cws(2);
            app.TOAngleUnitLabel.Position(1:2) = [390,270]./app.dws.*cws;
            app.TOAngleUnitLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.TOAngleUnitButtonGroup.Position(1:2) = [sum(app.TOAngleUnitLabel.Position(1:2:end)),270/app.dws(2)*cws(2)];
            app.TORadButton.Position(4) = 22/app.dws(2)*cws(2);
            app.TODegreeButton.Position(4) = 22/app.dws(2)*cws(2);
            app.ToolOrientationMatrixPanel.Position = [390,182,172,82]./app.dws2.*cws2;
            app.TOThetaLabel.Position(1:2) = [574,240]./app.dws.*cws;
            app.TOThetaLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.TOThetaEditField.Position(1:2:end) = [sum(app.TOThetaLabel.Position(1:2:end)),(56+25)/app.dws(1)*cws(1)-25];
            app.TOThetaEditField.Position(2:2:end) = [240,26]/app.dws(2)*cws(2);
            app.TOPhiLabel.Position(1:2) = [574,210]./app.dws.*cws;
            app.TOPhiLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.TOPhiEditField.Position(1:2:end) = [sum(app.TOPhiLabel.Position(1:2:end)),(56+25)/app.dws(1)*cws(1)-25];
            app.TOPhiEditField.Position(2:2:end) = [210,26]/app.dws(2)*cws(2);
            app.TOPsiLabel.Position(1:2) = [574,180]./app.dws.*cws;
            app.TOPsiLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.TOPsiEditField.Position(1:2:end) = [sum(app.TOPsiLabel.Position(1:2:end)),(56+25)/app.dws(1)*cws(1)-25];
            app.TOPsiEditField.Position(2:2:end) = [180,26]/app.dws(2)*cws(2);
            app.TOATButton.Position(1:2:end) = app.TOThetaEditField.Position(1:2:end);
            app.TOATButton.Position(2:2:end) = [270,22]/app.dws(2)*cws(2);
            app.RefPosLabel.Position(1:2) = [390,144]./app.dws.*cws;
            app.RefPosLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.RxLabel.Position(1:2) = [390,116]./app.dws.*cws;
            app.RxLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.RxEditField.Position(1:2:end) = [sum(app.RxLabel.Position(1:2:end)),(56+25)/app.dws(1)*cws(1)-25];
            app.RxEditField.Position(2:2:end) = [116,26]/app.dws(2)*cws(2);
            app.RyLabel.Position(1:2) = [475,116]./app.dws.*cws;
            app.RyLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.RyEditField.Position(1:2:end) = [sum(app.RyLabel.Position(1:2:end)),(56+25)/app.dws(1)*cws(1)-25];
            app.RyEditField.Position(2:2:end) = [116,26]/app.dws(2)*cws(2);
            app.RzLabel.Position(1:2) = [560,116]./app.dws.*cws;
            app.RzLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.RzEditField.Position(1:2:end) = [sum(app.RzLabel.Position(1:2:end)),(56+25)/app.dws(1)*cws(1)-25];
            app.RzEditField.Position(2:2:end) = [116,26]/app.dws(2)*cws(2);
            app.PathToModelsLabel.Position(1:2) = [390,80]./app.dws.*cws;
            app.PathToModelsLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.PathToModelsButton.Position = [390,50,127,26]./app.dws2.*cws2;
            app.PathToModelsEditField.Position = [390,20,265,26]./app.dws2.*cws2;
            app.RobotPreviewLabel.Position = [216,36,39,28]./app.dws2.*cws2;
            app.LinkedCoordinateSystemsLabel.Position = [298,22,64,42]./app.dws2.*cws2;
            if app.im_enl
                app.RobotPreviewImage.Position = [1,1,cws];
                app.LinkedCoordinateSystemsImage.Position = [1,1,cws];
            else
                app.RobotPreviewImage.Position = [183,66,104,130]./app.dws2.*cws2;
                app.LinkedCoordinateSystemsImage.Position = [297,66,65,130]./app.dws2.*cws2;
            end
            app.CloseImagePreviewButton.Position(1:2) = [625,550]./app.dws.*cws;
        end

        % Image clicked function: RobotPreviewImage
        function RobotPreviewImageClicked(app, event)
            if ~app.im_enl
                app.im_enl = true;
                cws = app.RobotParamsUIFigure.Position(3:4);
                if cws(1) < app.dws(1)
                    cws(1) = app.dws(1);
                end
                if cws(2) < app.dws(2)
                   cws(2) = app.dws(2);
                end
                app.RobotPreviewImage.Visible = true;
                app.LinkedCoordinateSystemsImage.Visible = false;
                app.RobotPreviewImage.BackgroundColor = 'white';
                app.RobotPreviewImage.Position(1:2) = [1,1];
                app.RobotPreviewImage.Position(3) = cws(1);
                app.RobotPreviewImage.Position(4) = cws(2);
                app.CloseImagePreviewButton.Visible = true;
            end
        end

        % Image clicked function: LinkedCoordinateSystemsImage
        function LinkedCoordinateSystemsImageClicked(app, event)
            if ~app.im_enl
                app.im_enl = true;
                cws = app.RobotParamsUIFigure.Position(3:4);
                if cws(1) < app.dws(1)
                    cws(1) = app.dws(1);
                end
                if cws(2) < app.dws(2)
                   cws(2) = app.dws(2);
                end
                app.LinkedCoordinateSystemsImage.Visible = true;
                app.RobotPreviewImage.Visible = false;
                app.LinkedCoordinateSystemsImage.BackgroundColor = 'white';
                app.LinkedCoordinateSystemsImage.Position(1:2) = [1,1];
                app.LinkedCoordinateSystemsImage.Position(3) = cws(1);
                app.LinkedCoordinateSystemsImage.Position(4) = cws(2);
                app.CloseImagePreviewButton.Visible = true;
            end
        end

        % Button pushed function: CloseImagePreviewButton
        function CloseImagePreviewButtonPushed(app, event)
            cws = app.RobotParamsUIFigure.Position(3:4);
            if cws(1) < app.dws(1)
                cws(1) = app.dws(1);
            end
            if cws(2) < app.dws(2)
               cws(2) = app.dws(2);
            end
            cws2 = [cws,cws];
            app.im_enl = false;
            app.RobotPreviewImage.Visible = true;
            app.LinkedCoordinateSystemsImage.Visible = true;
            app.RobotPreviewImage.BackgroundColor = 'none';
            app.LinkedCoordinateSystemsImage.BackgroundColor = 'none';
            app.RobotPreviewImage.Position = [183,66,104,130]./app.dws2.*cws2;
            app.LinkedCoordinateSystemsImage.Position = [297,66,65,130]./app.dws2.*cws2;
            app.CloseImagePreviewButton.Visible = false;
        end

        % Callback function
        function ReferentPoseSelectionMethodButtonGroupSelectionChanged(app, event)
            rpddv = app.ReferentPoseDropDown.Value;
            selectedButton = app.ReferentPoseSelectionMethodButtonGroup.SelectedObject;
            if strcmp(rpddv,'za pisanje')
                if selectedButton == app.RPInnerCoordsButton
                    app.WritingReferentPoseICPanel.Visible = true;
                    app.WritingReferentPoseHTMPanel.Visible = false;
                    app.CalibrationReferentPoseICPanel.Visible = false;
                    app.CalibrationReferentPoseHTMPanel.Visible = false;
                else
                    app.WritingReferentPoseHTMPanel.Visible = true;
                    app.WritingReferentPoseICPanel.Visible = false;
                    app.CalibrationReferentPoseICPanel.Visible = false;
                    app.CalibrationReferentPoseHTMPanel.Visible = false;
                end
            else
                if selectedButton == app.RPInnerCoordsButton
                    app.CalibrationReferentPoseICPanel.Visible = true;
                    app.CalibrationReferentPoseHTMPanel.Visible = false;
                    app.WritingReferentPoseICPanel.Visible = false;
                    app.WritingReferentPoseHTMPanel.Visible = false;
                else
                    app.CalibrationReferentPoseHTMPanel.Visible = true;
                    app.CalibrationReferentPoseICPanel.Visible = false;
                    app.WritingReferentPoseHTMPanel.Visible = false;
                    app.WritingReferentPoseICPanel.Visible = false;
                end
            end
        end

        % Close request function: RobotParamsUIFigure
        function RobotParamsUIFigureCloseRequest(app, event)
            app.caller_app.RobotParamsButton.Enable = true;
            delete(app);
        end

        % Value changing function: theta01EditField
        function theta01EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.theta01EditField);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if all(app.vals_ok)
                app.ApplyButton.FontColor = [0.9 0.7 0.0];
                app.RobotParamsUIFigure.Pointer = 'watch';
                pause(0.2);
                
                app.robot_params_temp.R = SerialLink(app.robot_params_temp.dh, 'name', app.robot_params_temp.name, 'offset', app.robot_params_temp.offset, 'qlim', app.robot_params_temp.qlim, 'tool', app.robot_params_temp.tool);
                qfound = app.calculateRefPosInnerCoordinates();
                
                if qfound
                    app.caller_app.robot_params = app.robot_params_temp;
                    app.ApplyButton.FontColor = [0.4 0.7 0.1];
                else
                    app.ApplyButton.FontColor = [0.9 0.3,0.3];
                    app.RxEditField.BackgroundColor = [1 0.7 0.7];
                    app.RyEditField.BackgroundColor = [1 0.7 0.7];
                    app.RzEditField.BackgroundColor = [1 0.7 0.7];
                end
                app.RobotParamsUIFigure.Pointer = 'arrow';
            else
                app.ApplyButton.FontColor = [0.9 0.3 0.3];
            end
            app.btn_timer = timer('StartDelay',1, 'ExecutionMode','singleShot');
            app.btn_timer.TimerFcn = @app.timer_func;
            app.btn_timer.StopFcn = @app.timer_stop_func;
            start(app.btn_timer);
        end

        % Value changing function: d01EditField
        function d01EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.d01EditField);
        end

        % Value changing function: a01EditField
        function a01EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.a01EditField);
        end

        % Value changing function: alpha01EditField
        function alpha01EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.alpha01EditField);
        end

        % Value changing function: s01EditField
        function s01EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value) && app.isSValid(str2double(event.Value)), app.s01EditField);
        end

        % Value changing function: theta12EditField
        function theta12EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.theta12EditField);
        end

        % Value changing function: d12EditField
        function d12EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.d12EditField);
        end

        % Value changing function: a12EditField
        function a12EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.a12EditField);
        end

        % Value changing function: alpha12EditField
        function alpha12EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.alpha12EditField);
        end

        % Value changing function: s12EditField
        function s12EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value) && app.isSValid(str2double(event.Value)), app.s12EditField);
        end

        % Value changing function: theta23EditField
        function theta23EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.theta23EditField);
        end

        % Value changing function: d23EditField
        function d23EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.d23EditField);
        end

        % Value changing function: a23EditField
        function a23EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.a23EditField);
        end

        % Value changing function: alpha23EditField
        function alpha23EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.alpha23EditField);
        end

        % Value changing function: s23EditField
        function s23EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value) && app.isSValid(str2double(event.Value)), app.s23EditField);
        end

        % Value changing function: theta34EditField
        function theta34EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.theta34EditField);
        end

        % Value changing function: d34EditField
        function d34EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.d34EditField);
        end

        % Value changing function: a34EditField
        function a34EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.a34EditField);
        end

        % Value changing function: alpha34EditField
        function alpha34EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.alpha34EditField);
        end

        % Value changing function: s34EditField
        function s34EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value) && app.isSValid(str2double(event.Value)), app.s34EditField);
        end

        % Value changing function: theta45EditField
        function theta45EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.theta45EditField);
        end

        % Value changing function: d45EditField
        function d45EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.d45EditField);
        end

        % Value changing function: a45EditField
        function a45EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.a45EditField);
        end

        % Value changing function: alpha45EditField
        function alpha45EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.alpha45EditField);
        end

        % Value changing function: s45EditField
        function s45EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value) && app.isSValid(str2double(event.Value)), app.s45EditField);
        end

        % Value changing function: theta56EditField
        function theta56EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.theta56EditField);
        end

        % Value changing function: d56EditField
        function d56EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.d56EditField);
        end

        % Value changing function: a56EditField
        function a56EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.a56EditField);
        end

        % Value changing function: alpha56EditField
        function alpha56EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.alpha56EditField);
        end

        % Value changing function: s56EditField
        function s56EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value) && app.isSValid(str2double(event.Value)), app.s56EditField);
        end

        % Value changing function: q0minEditField
        function q0minEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q0minEditField);
        end

        % Value changing function: q0maxEditField
        function q0maxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q0maxEditField);
        end

        % Value changing function: q1minEditField
        function q1minEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q1minEditField);
        end

        % Value changing function: q1maxEditField
        function q1maxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q1maxEditField);
        end

        % Value changing function: q2minEditField
        function q2minEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q2minEditField);
        end

        % Value changing function: q2maxEditField
        function q2maxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q2maxEditField);
        end

        % Value changing function: q3minEditField
        function q3minEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q3minEditField);
        end

        % Value changing function: q3maxEditField
        function q3maxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q3maxEditField);
        end

        % Value changing function: q4minEditField
        function q4minEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q4minEditField);
        end

        % Value changing function: q4maxEditField
        function q4maxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q4maxEditField);
        end

        % Value changing function: q5minEditField
        function q5minEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q5minEditField);
        end

        % Value changing function: q5maxEditField
        function q5maxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.q5maxEditField);
        end

        % Value changing function: TT11EditField
        function TT11EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT11EditField);
        end

        % Value changing function: TT12EditField
        function TT12EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT12EditField);
        end

        % Value changing function: TT13EditField
        function TT13EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT13EditField);
        end

        % Value changing function: TT14EditField
        function TT14EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT14EditField);
        end

        % Value changing function: TT21EditField
        function TT21EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT21EditField);
        end

        % Value changing function: TT22EditField
        function TT22EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT22EditField);
        end

        % Value changing function: TT23EditField
        function TT23EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT23EditField);
        end

        % Value changing function: TT24EditField
        function TT24EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT24EditField);
        end

        % Value changing function: TT31EditField
        function TT31EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT31EditField);
        end

        % Value changing function: TT32EditField
        function TT32EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT32EditField);
        end

        % Value changing function: TT33EditField
        function TT33EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT33EditField);
        end

        % Value changing function: TT34EditField
        function TT34EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT34EditField);
        end

        % Value changing function: TT41EditField
        function TT41EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT41EditField);
        end

        % Value changing function: TT42EditField
        function TT42EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT42EditField);
        end

        % Value changing function: TT43EditField
        function TT43EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT43EditField);
        end

        % Value changing function: TT44EditField
        function TT44EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TT44EditField);
        end

        % Value changing function: RobotRepeatabilityEditField
        function RobotRepeatabilityEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.RobotRepeatabilityEditField);
        end

        % Value changed function: 
        % ToolOrientationSelectionMethodDropDown
        function ToolOrientationSelectionMethodDropDownValueChanged(app, event)
            sv = app.ToolOrientationSelectionMethodDropDown.Value;
            
            switch sv
                case 'Matrica rotacije'
                    app.ToolOrientationAnglesTypeLabel.Visible = false;
                    app.EulerAnglesTypeDropDown.Visible = false;
                    app.CardanAnglesTypeDropDown.Visible = false;
                    
                    app.TOThetaEditField.Editable = false;
                    app.TOPhiEditField.Editable = false;
                    app.TOPsiEditField.Editable = false;
                    
                    app.TOM11EditField.Editable = true;
                    app.TOM12EditField.Editable = true;
                    app.TOM13EditField.Editable = true;
                    app.TOM21EditField.Editable = true;
                    app.TOM22EditField.Editable = true;
                    app.TOM23EditField.Editable = true;
                    app.TOM31EditField.Editable = true;
                    app.TOM32EditField.Editable = true;
                    app.TOM33EditField.Editable = true;
                    
                    app.TOATButton.Enable = true;
                    
                    app.updateTOAngles();
                    
                case 'Ojlerovi uglovi'
                    app.ToolOrientationAnglesTypeLabel.Visible = true;
                    app.EulerAnglesTypeDropDown.Visible = true;
                    app.CardanAnglesTypeDropDown.Visible = false;
                    
                    app.TOThetaEditField.Editable = true;
                    app.TOPhiEditField.Editable = true;
                    app.TOPsiEditField.Editable = true;
                    
                    app.TOM11EditField.Editable = false;
                    app.TOM12EditField.Editable = false;
                    app.TOM13EditField.Editable = false;
                    app.TOM21EditField.Editable = false;
                    app.TOM22EditField.Editable = false;
                    app.TOM23EditField.Editable = false;
                    app.TOM31EditField.Editable = false;
                    app.TOM32EditField.Editable = false;
                    app.TOM33EditField.Editable = false;
                    
                    app.TOATButton.Enable = false;
                    
                    pv = app.EulerAnglesTypeDropDown.Value;
                    switch pv
                        case 'z→y''→z'''' / z0←y0←z0'
                            app.TOATButton.Text = 'O.1-zyz';
                        case 'z→x''→z'''' / z0←x0←z0'
                            app.TOATButton.Text = 'O.2-zxz';
                        case 'y→z''→y'''' / y0←z0←y0'
                            app.TOATButton.Text = 'O.3-yzy';
                        case 'y→x''→y'''' / y0←x0←y0'
                            app.TOATButton.Text = 'O.4-yxy';
                        case 'x→z''→x'''' / x0←z0←x0'
                            app.TOATButton.Text = 'O.5-xzx';
                        case 'x→y''→x'''' / x0←y0←x0'
                            app.TOATButton.Text = 'O.6-xyx';
                    end
                    
                    app.setTOMValueFromTOA();
                    app.updateTOMatrix();
                    
                case 'Aeronautički uglovi'
                    app.ToolOrientationAnglesTypeLabel.Visible = true;
                    app.CardanAnglesTypeDropDown.Visible = true;
                    app.EulerAnglesTypeDropDown.Visible = false;
                    
                    app.TOThetaEditField.Editable = true;
                    app.TOPhiEditField.Editable = true;
                    app.TOPsiEditField.Editable = true;
                    
                    app.TOM11EditField.Editable = false;
                    app.TOM12EditField.Editable = false;
                    app.TOM13EditField.Editable = false;
                    app.TOM21EditField.Editable = false;
                    app.TOM22EditField.Editable = false;
                    app.TOM23EditField.Editable = false;
                    app.TOM31EditField.Editable = false;
                    app.TOM32EditField.Editable = false;
                    app.TOM33EditField.Editable = false;
                    
                    app.TOATButton.Enable = false;
                    
                    pv = app.CardanAnglesTypeDropDown.Value;
                    switch pv
                        case 'z→y''→x'''' / z0←y0←x0 - ROLL-PITCH-YAW'
                            app.TOATButton.Text = 'A.1-xyz';
                        case 'z→x''→y'''' / z0←x0←y0'
                            app.TOATButton.Text = 'A.2-yxz';
                        case 'y→z''→x'''' / y0←z0←x0'
                            app.TOATButton.Text = 'A.3-xzy';
                        case 'y→x''→z'''' / y0←x0←z0'
                            app.TOATButton.Text = 'A.4-zxy';
                        case 'x→z''→y'''' / x0←z0←y0'
                            app.TOATButton.Text = 'A.5-yzx';
                        case 'x→y''→z'''' / x0←y0←z0 - YAW-PITCH-ROLL'
                            app.TOATButton.Text = 'A.6-zyx';
                    end
                    
                    app.setTOMValueFromTOA();
                    app.updateTOMatrix();
            end
            
        end

        % Value changed function: EulerAnglesTypeDropDown
        function EulerAnglesTypeDropDownValueChanged(app, event)
            sv = app.EulerAnglesTypeDropDown.Value;
            
            switch sv
                case 'z→y''→z'''' / z0←y0←z0'
                    app.TOATButton.Text = 'O.1-zyz';
                case 'z→x''→z'''' / z0←x0←z0'
                    app.TOATButton.Text = 'O.2-zxz';
                case 'y→z''→y'''' / y0←z0←y0'
                    app.TOATButton.Text = 'O.3-yzy';
                case 'y→x''→y'''' / y0←x0←y0'
                    app.TOATButton.Text = 'O.4-yxy';
                case 'x→z''→x'''' / x0←z0←x0'
                    app.TOATButton.Text = 'O.5-xzx';
                case 'x→y''→x'''' / x0←y0←x0'
                    app.TOATButton.Text = 'O.6-xyx';
            end
            
            app.setTOMValueFromTOA();
            app.updateTOMatrix();
        end

        % Value changed function: CardanAnglesTypeDropDown
        function CardanAnglesTypeDropDownValueChanged(app, event)
            sv = app.CardanAnglesTypeDropDown.Value;
            
            switch sv
                case 'z→y''→x'''' / z0←y0←x0 - ROLL-PITCH-YAW'
                    app.TOATButton.Text = 'A.1-xyz';
                case 'z→x''→y'''' / z0←x0←y0'
                    app.TOATButton.Text = 'A.2-yxz';
                case 'y→z''→x'''' / y0←z0←x0'
                    app.TOATButton.Text = 'A.3-xzy';
                case 'y→x''→z'''' / y0←x0←z0'
                    app.TOATButton.Text = 'A.4-zxy';
                case 'x→z''→y'''' / x0←z0←y0'
                    app.TOATButton.Text = 'A.5-yzx';
                case 'x→y''→z'''' / x0←y0←z0 - YAW-PITCH-ROLL'
                    app.TOATButton.Text = 'A.6-zyx';
            end
            
            app.setTOMValueFromTOA();
            app.updateTOMatrix();
        end

        % Value changing function: TOM11EditField
        function TOM11EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM11EditField);
        end

        % Value changing function: TOM12EditField
        function TOM12EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM12EditField);
        end

        % Value changing function: TOM13EditField
        function TOM13EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM13EditField);
        end

        % Value changing function: TOM21EditField
        function TOM21EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM21EditField);
        end

        % Value changing function: TOM22EditField
        function TOM22EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM22EditField);
        end

        % Value changing function: TOM23EditField
        function TOM23EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM23EditField);
        end

        % Value changing function: TOM31EditField
        function TOM31EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM31EditField);
        end

        % Value changing function: TOM32EditField
        function TOM32EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM32EditField);
        end

        % Value changing function: TOM33EditField
        function TOM33EditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOM33EditField);
        end

        % Value changing function: TOThetaEditField
        function TOThetaEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOThetaEditField);
        end

        % Value changing function: TOPhiEditField
        function TOPhiEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOPhiEditField);
        end

        % Value changing function: TOPsiEditField
        function TOPsiEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.TOPsiEditField);
        end

        % Value changing function: RxEditField
        function RxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.RxEditField);
        end

        % Value changing function: RyEditField
        function RyEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.RyEditField);
        end

        % Value changing function: RzEditField
        function RzEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.RzEditField);
        end

        % Value changed function: TOM11EditField
        function TOM11EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM11EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM12EditField
        function TOM12EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM12EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM13EditField
        function TOM13EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM13EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM21EditField
        function TOM21EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM21EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM22EditField
        function TOM22EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM22EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM23EditField
        function TOM23EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM23EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM31EditField
        function TOM31EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM31EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM32EditField
        function TOM32EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM32EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOM33EditField
        function TOM33EditFieldValueChanged(app, event)
            app.setTOMValue(app.TOM33EditField);
            app.updateTOAngles();
            app.updateTOMState();
        end

        % Value changed function: TOThetaEditField
        function TOThetaEditFieldValueChanged(app, event)
            app.setTOMValueFromTOA();
            app.updateTOMatrix();
        end

        % Value changed function: TOPhiEditField
        function TOPhiEditFieldValueChanged(app, event)
            app.setTOMValueFromTOA();
            app.updateTOMatrix();
        end

        % Value changed function: TOPsiEditField
        function TOPsiEditFieldValueChanged(app, event)
            app.setTOMValueFromTOA();
            app.updateTOMatrix();
        end

        % Button pushed function: TOATButton
        function TOATButtonPushed(app, event)
            tt = app.TOATButton.Text;
            switch tt
                case 'O.1-zyz'
                    app.TOATButton.Text = 'O.2-zxz';
                case 'O.2-zxz'
                    app.TOATButton.Text = 'O.3-yzy';
                case 'O.3-yzy'
                    app.TOATButton.Text = 'O.4-yxy';
                case 'O.4-yxy'
                    app.TOATButton.Text = 'O.5-xzx';
                case 'O.5-xzx'
                    app.TOATButton.Text = 'O.6-xyx';
                case 'O.6-xyx'
                    app.TOATButton.Text = 'A.1-xyz';
                case 'A.1-xyz'
                    app.TOATButton.Text = 'A.2-yxz';
                case 'A.2-yxz'
                    app.TOATButton.Text = 'A.3-xzy';
                case 'A.3-xzy'
                    app.TOATButton.Text = 'A.4-zxy';
                case 'A.4-zxy'
                    app.TOATButton.Text = 'A.5-yzx';
                case 'A.5-yzx'
                    app.TOATButton.Text = 'A.6-zyx';
                case 'A.6-zyx'
                    app.TOATButton.Text = 'O.1-zyz';
            end
            app.updateTOAngles();
        end

        % Selection changed function: TOAngleUnitButtonGroup
        function TOAngleUnitButtonGroupSelectionChanged(app, event)
            sel = app.TOAngleUnitButtonGroup.SelectedObject;
            if sel == app.TORadButton
                app.robot_params_temp.to_angle_unit = 'rad';
            else
                app.robot_params_temp.to_angle_unit = 'deg';
            end
            
            sv = app.ToolOrientationSelectionMethodDropDown.Value;
            if strcmp(sv,'Matrica rotacije')
                app.updateTOAngles();
            else
                app.setTOMValueFromTOA();
                app.updateTOMatrix();
            end
        end

        % Button pushed function: PathToModelsButton
        function PathToModelsButtonPushed(app, event)
            pth = uigetdir();
            if ischar(pth)
                app.PathToModelsEditField.Value = pth;
                app.updateEFState(app.isPathToModelsValid(pth), app.PathToModelsEditField);
            end
        end

        % Value changed function: TT11EditField
        function TT11EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT11EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT12EditField
        function TT12EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT12EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT13EditField
        function TT13EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT13EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT21EditField
        function TT21EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT21EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT22EditField
        function TT22EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT22EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT23EditField
        function TT23EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT23EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT31EditField
        function TT31EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT31EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT32EditField
        function TT32EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT32EditField);
            app.updateTTRMState();
        end

        % Value changed function: TT33EditField
        function TT33EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT33EditField);
            app.updateTTRMState();
        end

        % Value changed function: PathToModelsEditField
        function PathToModelsEditFieldValueChanged(app, event)
            app.updateEFState(app.isPathToModelsValid(app.PathToModelsEditField.Value), app.PathToModelsEditField);
            if(app.vals_ok(str2double(app.PathToModelsEditField.Tag)))
                app.models_pth_temp = app.PathToModelsEditField.Value;
            end
        end

        % Value changed function: RobotNameEditField
        function RobotNameEditFieldValueChanged(app, event)
            app.robot_params_temp.name = app.RobotNameEditField.Value;
        end

        % Value changed function: theta01EditField
        function theta01EditFieldValueChanged(app, event)
            app.setDHValue(app.theta01EditField);
            app.setOffsetValue(app.theta01EditField);
        end

        % Value changed function: d01EditField
        function d01EditFieldValueChanged(app, event)
            app.setDHValue(app.d01EditField);
        end

        % Value changed function: a01EditField
        function a01EditFieldValueChanged(app, event)
            app.setDHValue(app.a01EditField);
        end

        % Value changed function: alpha01EditField
        function alpha01EditFieldValueChanged(app, event)
            app.setDHValue(app.alpha01EditField);
        end

        % Value changed function: s01EditField
        function s01EditFieldValueChanged(app, event)
            app.setDHValue(app.s01EditField);
        end

        % Value changed function: theta12EditField
        function theta12EditFieldValueChanged(app, event)
            app.setDHValue(app.theta12EditField);
            app.setOffsetValue(app.theta12EditField);
        end

        % Value changed function: d12EditField
        function d12EditFieldValueChanged(app, event)
            app.setDHValue(app.d12EditField);
        end

        % Value changed function: a12EditField
        function a12EditFieldValueChanged(app, event)
            app.setDHValue(app.a12EditField);
        end

        % Value changed function: alpha12EditField
        function alpha12EditFieldValueChanged(app, event)
            app.setDHValue(app.alpha12EditField);
        end

        % Value changed function: s12EditField
        function s12EditFieldValueChanged(app, event)
            app.setDHValue(app.s12EditField);
        end

        % Value changed function: theta23EditField
        function theta23EditFieldValueChanged(app, event)
            app.setDHValue(app.theta23EditField);
            app.setOffsetValue(app.theta23EditField);
        end

        % Value changed function: d23EditField
        function d23EditFieldValueChanged(app, event)
            app.setDHValue(app.d23EditField);
        end

        % Value changed function: a23EditField
        function a23EditFieldValueChanged(app, event)
            app.setDHValue(app.a23EditField);
        end

        % Value changed function: alpha23EditField
        function alpha23EditFieldValueChanged(app, event)
            app.setDHValue(app.alpha23EditField);
        end

        % Value changed function: s23EditField
        function s23EditFieldValueChanged(app, event)
            app.setDHValue(app.s23EditField);
        end

        % Value changed function: theta34EditField
        function theta34EditFieldValueChanged(app, event)
            app.setDHValue(app.theta34EditField);
            app.setOffsetValue(app.theta34EditField);
        end

        % Value changed function: d34EditField
        function d34EditFieldValueChanged(app, event)
            app.setDHValue(app.d34EditField);
        end

        % Value changed function: a34EditField
        function a34EditFieldValueChanged(app, event)
            app.setDHValue(app.a34EditField);
        end

        % Value changed function: alpha34EditField
        function alpha34EditFieldValueChanged(app, event)
            app.setDHValue(app.alpha34EditField);
        end

        % Value changed function: s34EditField
        function s34EditFieldValueChanged(app, event)
            app.setDHValue(app.s34EditField);
        end

        % Value changed function: theta45EditField
        function theta45EditFieldValueChanged(app, event)
            app.setDHValue(app.theta45EditField);
            app.setOffsetValue(app.theta45EditField);
        end

        % Value changed function: d45EditField
        function d45EditFieldValueChanged(app, event)
            app.setDHValue(app.d45EditField);
        end

        % Value changed function: a45EditField
        function a45EditFieldValueChanged(app, event)
            app.setDHValue(app.a45EditField);
        end

        % Value changed function: alpha45EditField
        function alpha45EditFieldValueChanged(app, event)
            app.setDHValue(app.alpha45EditField);
        end

        % Value changed function: s45EditField
        function s45EditFieldValueChanged(app, event)
            app.setDHValue(app.s45EditField);
        end

        % Value changed function: theta56EditField
        function theta56EditFieldValueChanged(app, event)
            app.setDHValue(app.theta56EditField);
            app.setOffsetValue(app.theta56EditField);
        end

        % Value changed function: d56EditField
        function d56EditFieldValueChanged(app, event)
            app.setDHValue(app.d56EditField);
        end

        % Value changed function: a56EditField
        function a56EditFieldValueChanged(app, event)
            app.setDHValue(app.a56EditField);
        end

        % Value changed function: alpha56EditField
        function alpha56EditFieldValueChanged(app, event)
            app.setDHValue(app.alpha56EditField);
        end

        % Value changed function: s56EditField
        function s56EditFieldValueChanged(app, event)
            app.setDHValue(app.s56EditField);
        end

        % Value changed function: q0minEditField
        function q0minEditFieldValueChanged(app, event)
            app.setQLim(app.q0minEditField);
        end

        % Value changed function: q0maxEditField
        function q0maxEditFieldValueChanged(app, event)
            app.setQLim(app.q0maxEditField);
        end

        % Value changed function: q1minEditField
        function q1minEditFieldValueChanged(app, event)
            app.setQLim(app.q1minEditField);
        end

        % Value changed function: q1maxEditField
        function q1maxEditFieldValueChanged(app, event)
            app.setQLim(app.q1maxEditField);
        end

        % Value changed function: q2minEditField
        function q2minEditFieldValueChanged(app, event)
            app.setQLim(app.q2minEditField);
        end

        % Value changed function: q2maxEditField
        function q2maxEditFieldValueChanged(app, event)
            app.setQLim(app.q2maxEditField);
        end

        % Value changed function: q3minEditField
        function q3minEditFieldValueChanged(app, event)
            app.setQLim(app.q3minEditField);
        end

        % Value changed function: q3maxEditField
        function q3maxEditFieldValueChanged(app, event)
            app.setQLim(app.q3maxEditField);
        end

        % Value changed function: q4minEditField
        function q4minEditFieldValueChanged(app, event)
            app.setQLim(app.q4minEditField);
        end

        % Value changed function: q4maxEditField
        function q4maxEditFieldValueChanged(app, event)
            app.setQLim(app.q4maxEditField);
        end

        % Value changed function: q5minEditField
        function q5minEditFieldValueChanged(app, event)
            app.setQLim(app.q5minEditField);
        end

        % Value changed function: q5maxEditField
        function q5maxEditFieldValueChanged(app, event)
            app.setQLim(app.q5maxEditField);
        end

        % Value changed function: RobotRepeatabilityEditField
        function RobotRepeatabilityEditFieldValueChanged(app, event)
            app.setRobotRepeatabilityValue();
        end

        % Value changed function: TT14EditField
        function TT14EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT14EditField);
        end

        % Value changed function: TT24EditField
        function TT24EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT24EditField);
        end

        % Value changed function: TT34EditField
        function TT34EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT34EditField);
        end

        % Value changed function: TT41EditField
        function TT41EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT41EditField);
        end

        % Value changed function: TT42EditField
        function TT42EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT42EditField);
        end

        % Value changed function: TT43EditField
        function TT43EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT43EditField);
        end

        % Value changed function: TT44EditField
        function TT44EditFieldValueChanged(app, event)
            app.setToolTransformValue(app.TT44EditField);
        end

        % Value changed function: RxEditField
        function RxEditFieldValueChanged(app, event)
            app.setReferentPoseValue(app.RxEditField);
        end

        % Value changed function: RyEditField
        function RyEditFieldValueChanged(app, event)
            app.setReferentPoseValue(app.RyEditField);
        end

        % Value changed function: RzEditField
        function RzEditFieldValueChanged(app, event)
            app.setReferentPoseValue(app.RzEditField);
        end

        % Selection changed function: DHAngleUnitButtonGroup
        function DHAngleUnitButtonGroupSelectionChanged(app, event)
            sel = app.DHAngleUnitButtonGroup.SelectedObject;
            if sel == app.DHRadButton
                app.robot_params_temp.dh_angle_unit = 'rad';
                app.robot_params_temp.dh(:,1:3:6) = app.robot_params_temp.dh(:,1:3:6)*180/pi;
            else
                app.robot_params_temp.dh_angle_unit = 'deg';
                app.robot_params_temp.dh(:,1:3:6) = app.robot_params_temp.dh(:,1:3:6)*pi/180;
            end
        end

        % Selection changed function: QLimsAngleUnitButtonGroup
        function QLimsAngleUnitButtonGroupSelectionChanged(app, event)
            sel = app.QLimsAngleUnitButtonGroup.SelectedObject;
            if sel == app.QLimsRadButton
                app.robot_params_temp.qlim_angle_unit = 'rad';
                app.robot_params_temp.qlim = app.robot_params_temp.qlim*180/pi;
            else
                app.robot_params_temp.qlim_angle_unit = 'deg';
                app.robot_params_temp.qlim = app.robot_params_temp.qlim*pi/180;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotParamsUIFigure = uifigure('Visible', 'off');
            app.RobotParamsUIFigure.Name = 'Parametri robota';
            app.RobotParamsUIFigure.CloseRequestFcn = createCallbackFcn(app, @RobotParamsUIFigureCloseRequest, true);
            app.RobotParamsUIFigure.SizeChangedFcn = createCallbackFcn(app, @RobotParamsUIFigureSizeChanged, true);
            app.UnitsInfoLabel = uilabel(app.RobotParamsUIFigure);
            app.UnitsInfoLabel.Text = '*Jedinica za dužinu je metar, a za ugao radijan ili stepen.';
            app.RobotNameLabel = uilabel(app.RobotParamsUIFigure);
            app.RobotNameLabel.Text = 'Naziv';
            app.RobotNameEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.RobotNameEditField.ValueChangedFcn = createCallbackFcn(app, @RobotNameEditFieldValueChanged, true);
            app.RobotNameEditField.Value = 'Mitsubishi RV-2SDB';
            app.DHAngleUnitButtonGroup = uibuttongroup(app.RobotParamsUIFigure);
            app.DHAngleUnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DHAngleUnitButtonGroupSelectionChanged, true);
            app.DHRadButton = uiradiobutton(app.DHAngleUnitButtonGroup);
            app.DHRadButton.Text = 'rad';
            app.DHRadButton.Value = true;
            app.DHDegreeButton = uiradiobutton(app.DHAngleUnitButtonGroup);
            app.DHDegreeButton.Text = 'stepen';
            app.DHAngleUnitLabel = uilabel(app.RobotParamsUIFigure);
            app.DHAngleUnitLabel.Text = 'Jedinica za ugao';
            app.QLimsPanel = uipanel(app.RobotParamsUIFigure);
            app.QLimsGridLayout = uigridlayout(app.QLimsPanel);
            app.qminLabel = uilabel(app.QLimsGridLayout);
            app.qminLabel.Text = 'qmin';
            app.qmaxLabel = uilabel(app.QLimsGridLayout);
            app.qmaxLabel.Text = 'qmax';
            app.q0Label = uilabel(app.QLimsGridLayout);
            app.q0Label.Text = 'q0';
            app.q1Label = uilabel(app.QLimsGridLayout);
            app.q1Label.Text = 'q1';
            app.q2Label = uilabel(app.QLimsGridLayout);
            app.q2Label.Text = 'q2';
            app.q3Label = uilabel(app.QLimsGridLayout);
            app.q3Label.Text = 'q3';
            app.q4Label = uilabel(app.QLimsGridLayout);
            app.q4Label.Text = 'q4';
            app.q5Label = uilabel(app.QLimsGridLayout);
            app.q5Label.Text = 'q5';
            app.q0minEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q0minEditField.ValueChangedFcn = createCallbackFcn(app, @q0minEditFieldValueChanged, true);
            app.q0minEditField.ValueChangingFcn = createCallbackFcn(app, @q0minEditFieldValueChanging, true);
            app.q0minEditField.Tag = '31';
            app.q0minEditField.Value = '-240';
            app.q0maxEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q0maxEditField.ValueChangedFcn = createCallbackFcn(app, @q0maxEditFieldValueChanged, true);
            app.q0maxEditField.ValueChangingFcn = createCallbackFcn(app, @q0maxEditFieldValueChanging, true);
            app.q0maxEditField.Tag = '32';
            app.q0maxEditField.Value = '240';
            app.q1minEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q1minEditField.ValueChangedFcn = createCallbackFcn(app, @q1minEditFieldValueChanged, true);
            app.q1minEditField.ValueChangingFcn = createCallbackFcn(app, @q1minEditFieldValueChanging, true);
            app.q1minEditField.Tag = '33';
            app.q1minEditField.Value = '-120';
            app.q1maxEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q1maxEditField.ValueChangedFcn = createCallbackFcn(app, @q1maxEditFieldValueChanged, true);
            app.q1maxEditField.ValueChangingFcn = createCallbackFcn(app, @q1maxEditFieldValueChanging, true);
            app.q1maxEditField.Tag = '34';
            app.q1maxEditField.Value = '120';
            app.q2minEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q2minEditField.ValueChangedFcn = createCallbackFcn(app, @q2minEditFieldValueChanged, true);
            app.q2minEditField.ValueChangingFcn = createCallbackFcn(app, @q2minEditFieldValueChanging, true);
            app.q2minEditField.Tag = '35';
            app.q2minEditField.Value = '0';
            app.q2maxEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q2maxEditField.ValueChangedFcn = createCallbackFcn(app, @q2maxEditFieldValueChanged, true);
            app.q2maxEditField.ValueChangingFcn = createCallbackFcn(app, @q2maxEditFieldValueChanging, true);
            app.q2maxEditField.Tag = '36';
            app.q2maxEditField.Value = '160';
            app.q3minEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q3minEditField.ValueChangedFcn = createCallbackFcn(app, @q3minEditFieldValueChanged, true);
            app.q3minEditField.ValueChangingFcn = createCallbackFcn(app, @q3minEditFieldValueChanging, true);
            app.q3minEditField.Tag = '37';
            app.q3minEditField.Value = '-200';
            app.q3maxEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q3maxEditField.ValueChangedFcn = createCallbackFcn(app, @q3maxEditFieldValueChanged, true);
            app.q3maxEditField.ValueChangingFcn = createCallbackFcn(app, @q3maxEditFieldValueChanging, true);
            app.q3maxEditField.Tag = '38';
            app.q3maxEditField.Value = '200';
            app.q4minEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q4minEditField.ValueChangedFcn = createCallbackFcn(app, @q4minEditFieldValueChanged, true);
            app.q4minEditField.ValueChangingFcn = createCallbackFcn(app, @q4minEditFieldValueChanging, true);
            app.q4minEditField.Tag = '39';
            app.q4minEditField.Value = '-120';
            app.q4maxEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q4maxEditField.ValueChangedFcn = createCallbackFcn(app, @q4maxEditFieldValueChanged, true);
            app.q4maxEditField.ValueChangingFcn = createCallbackFcn(app, @q4maxEditFieldValueChanging, true);
            app.q4maxEditField.Tag = '40';
            app.q4maxEditField.Value = '120';
            app.q5minEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q5minEditField.ValueChangedFcn = createCallbackFcn(app, @q5minEditFieldValueChanged, true);
            app.q5minEditField.ValueChangingFcn = createCallbackFcn(app, @q5minEditFieldValueChanging, true);
            app.q5minEditField.Tag = '41';
            app.q5minEditField.Value = '-360';
            app.q5maxEditField = uieditfield(app.QLimsGridLayout, 'text');
            app.q5maxEditField.ValueChangedFcn = createCallbackFcn(app, @q5maxEditFieldValueChanged, true);
            app.q5maxEditField.ValueChangingFcn = createCallbackFcn(app, @q5maxEditFieldValueChanging, true);
            app.q5maxEditField.Tag = '42';
            app.q5maxEditField.Value = '360';
            app.QLimsLabel = uilabel(app.RobotParamsUIFigure);
            app.QLimsLabel.Text = 'Ograničenja u zglobovima';
            app.QLimsAngleUnitButtonGroup = uibuttongroup(app.RobotParamsUIFigure);
            app.QLimsAngleUnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QLimsAngleUnitButtonGroupSelectionChanged, true);
            app.QLimsRadButton = uiradiobutton(app.QLimsAngleUnitButtonGroup);
            app.QLimsRadButton.Text = 'rad';
            app.QLimsDegreeButton = uiradiobutton(app.QLimsAngleUnitButtonGroup);
            app.QLimsDegreeButton.Text = 'stepen';
            app.QLimsDegreeButton.Value = true;
            app.QLimsAngleUnitLabel = uilabel(app.RobotParamsUIFigure);
            app.QLimsAngleUnitLabel.Text = 'Jedinica za ugao';
            app.DHKinematicParamsLabel = uilabel(app.RobotParamsUIFigure);
            app.DHKinematicParamsLabel.Text = 'DH tabela kinematičkih parametara';
            app.DHKinematicParamsPanel = uipanel(app.RobotParamsUIFigure);
            app.DHKinematicParamsGridLayout = uigridlayout(app.DHKinematicParamsPanel);
            app.thetaLabel = uilabel(app.DHKinematicParamsGridLayout);
            app.thetaLabel.Text = 'θ';
            app.dLabel = uilabel(app.DHKinematicParamsGridLayout);
            app.dLabel.Text = 'd';
            app.aLabel = uilabel(app.DHKinematicParamsGridLayout);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.DHKinematicParamsGridLayout);
            app.alphaLabel.Text = 'α';
            app.sLabel = uilabel(app.DHKinematicParamsGridLayout);
            app.sLabel.Text = 's';
            app.cs01Label = uilabel(app.DHKinematicParamsGridLayout);
            app.cs01Label.Text = '0 → 1';
            app.cs12Label = uilabel(app.DHKinematicParamsGridLayout);
            app.cs12Label.Text = '1 → 2';
            app.cs23Label = uilabel(app.DHKinematicParamsGridLayout);
            app.cs23Label.Text = '2 → 3';
            app.cs34Label = uilabel(app.DHKinematicParamsGridLayout);
            app.cs34Label.Text = '3 → 4';
            app.cs45Label = uilabel(app.DHKinematicParamsGridLayout);
            app.cs45Label.Text = '4 → 5';
            app.cs56Label = uilabel(app.DHKinematicParamsGridLayout);
            app.cs56Label.Text = '5 → 6';
            app.theta01EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.theta01EditField.ValueChangedFcn = createCallbackFcn(app, @theta01EditFieldValueChanged, true);
            app.theta01EditField.ValueChangingFcn = createCallbackFcn(app, @theta01EditFieldValueChanging, true);
            app.theta01EditField.Tag = '1';
            app.theta01EditField.Value = '0';
            app.d01EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.d01EditField.ValueChangedFcn = createCallbackFcn(app, @d01EditFieldValueChanged, true);
            app.d01EditField.ValueChangingFcn = createCallbackFcn(app, @d01EditFieldValueChanging, true);
            app.d01EditField.Tag = '2';
            app.d01EditField.Value = '0.295';
            app.a01EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.a01EditField.ValueChangedFcn = createCallbackFcn(app, @a01EditFieldValueChanged, true);
            app.a01EditField.ValueChangingFcn = createCallbackFcn(app, @a01EditFieldValueChanging, true);
            app.a01EditField.Tag = '3';
            app.a01EditField.Value = '0';
            app.alpha01EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.alpha01EditField.ValueChangedFcn = createCallbackFcn(app, @alpha01EditFieldValueChanged, true);
            app.alpha01EditField.ValueChangingFcn = createCallbackFcn(app, @alpha01EditFieldValueChanging, true);
            app.alpha01EditField.Tag = '4';
            app.alpha01EditField.Value = '-pi/2';
            app.s01EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.s01EditField.ValueChangedFcn = createCallbackFcn(app, @s01EditFieldValueChanged, true);
            app.s01EditField.ValueChangingFcn = createCallbackFcn(app, @s01EditFieldValueChanging, true);
            app.s01EditField.Tag = '5';
            app.s01EditField.Value = '0';
            app.theta12EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.theta12EditField.ValueChangedFcn = createCallbackFcn(app, @theta12EditFieldValueChanged, true);
            app.theta12EditField.ValueChangingFcn = createCallbackFcn(app, @theta12EditFieldValueChanging, true);
            app.theta12EditField.Tag = '6';
            app.theta12EditField.Value = '-pi/2';
            app.d12EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.d12EditField.ValueChangedFcn = createCallbackFcn(app, @d12EditFieldValueChanged, true);
            app.d12EditField.ValueChangingFcn = createCallbackFcn(app, @d12EditFieldValueChanging, true);
            app.d12EditField.Tag = '7';
            app.d12EditField.Value = '0';
            app.a12EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.a12EditField.ValueChangedFcn = createCallbackFcn(app, @a12EditFieldValueChanged, true);
            app.a12EditField.ValueChangingFcn = createCallbackFcn(app, @a12EditFieldValueChanging, true);
            app.a12EditField.Tag = '8';
            app.a12EditField.Value = '0.23';
            app.alpha12EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.alpha12EditField.ValueChangedFcn = createCallbackFcn(app, @alpha12EditFieldValueChanged, true);
            app.alpha12EditField.ValueChangingFcn = createCallbackFcn(app, @alpha12EditFieldValueChanging, true);
            app.alpha12EditField.Tag = '9';
            app.alpha12EditField.Value = '0';
            app.s12EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.s12EditField.ValueChangedFcn = createCallbackFcn(app, @s12EditFieldValueChanged, true);
            app.s12EditField.ValueChangingFcn = createCallbackFcn(app, @s12EditFieldValueChanging, true);
            app.s12EditField.Tag = '10';
            app.s12EditField.Value = '0';
            app.theta23EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.theta23EditField.ValueChangedFcn = createCallbackFcn(app, @theta23EditFieldValueChanged, true);
            app.theta23EditField.ValueChangingFcn = createCallbackFcn(app, @theta23EditFieldValueChanging, true);
            app.theta23EditField.Tag = '11';
            app.theta23EditField.Value = 'pi/2';
            app.d23EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.d23EditField.ValueChangedFcn = createCallbackFcn(app, @d23EditFieldValueChanged, true);
            app.d23EditField.ValueChangingFcn = createCallbackFcn(app, @d23EditFieldValueChanging, true);
            app.d23EditField.Tag = '12';
            app.d23EditField.Value = '0';
            app.a23EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.a23EditField.ValueChangedFcn = createCallbackFcn(app, @a23EditFieldValueChanged, true);
            app.a23EditField.ValueChangingFcn = createCallbackFcn(app, @a23EditFieldValueChanging, true);
            app.a23EditField.Tag = '13';
            app.a23EditField.Value = '-0.05';
            app.alpha23EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.alpha23EditField.ValueChangedFcn = createCallbackFcn(app, @alpha23EditFieldValueChanged, true);
            app.alpha23EditField.ValueChangingFcn = createCallbackFcn(app, @alpha23EditFieldValueChanging, true);
            app.alpha23EditField.Tag = '14';
            app.alpha23EditField.Value = 'pi/2';
            app.s23EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.s23EditField.ValueChangedFcn = createCallbackFcn(app, @s23EditFieldValueChanged, true);
            app.s23EditField.ValueChangingFcn = createCallbackFcn(app, @s23EditFieldValueChanging, true);
            app.s23EditField.Tag = '15';
            app.s23EditField.Value = '0';
            app.theta34EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.theta34EditField.ValueChangedFcn = createCallbackFcn(app, @theta34EditFieldValueChanged, true);
            app.theta34EditField.ValueChangingFcn = createCallbackFcn(app, @theta34EditFieldValueChanging, true);
            app.theta34EditField.Tag = '16';
            app.theta34EditField.Value = '0';
            app.d34EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.d34EditField.ValueChangedFcn = createCallbackFcn(app, @d34EditFieldValueChanged, true);
            app.d34EditField.ValueChangingFcn = createCallbackFcn(app, @d34EditFieldValueChanging, true);
            app.d34EditField.Tag = '17';
            app.d34EditField.Value = '0.27';
            app.a34EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.a34EditField.ValueChangedFcn = createCallbackFcn(app, @a34EditFieldValueChanged, true);
            app.a34EditField.ValueChangingFcn = createCallbackFcn(app, @a34EditFieldValueChanging, true);
            app.a34EditField.Tag = '18';
            app.a34EditField.Value = '0';
            app.alpha34EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.alpha34EditField.ValueChangedFcn = createCallbackFcn(app, @alpha34EditFieldValueChanged, true);
            app.alpha34EditField.ValueChangingFcn = createCallbackFcn(app, @alpha34EditFieldValueChanging, true);
            app.alpha34EditField.Tag = '19';
            app.alpha34EditField.Value = '-pi/2';
            app.s34EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.s34EditField.ValueChangedFcn = createCallbackFcn(app, @s34EditFieldValueChanged, true);
            app.s34EditField.ValueChangingFcn = createCallbackFcn(app, @s34EditFieldValueChanging, true);
            app.s34EditField.Tag = '20';
            app.s34EditField.Value = '0';
            app.theta45EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.theta45EditField.ValueChangedFcn = createCallbackFcn(app, @theta45EditFieldValueChanged, true);
            app.theta45EditField.ValueChangingFcn = createCallbackFcn(app, @theta45EditFieldValueChanging, true);
            app.theta45EditField.Tag = '21';
            app.theta45EditField.Value = '0';
            app.d45EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.d45EditField.ValueChangedFcn = createCallbackFcn(app, @d45EditFieldValueChanged, true);
            app.d45EditField.ValueChangingFcn = createCallbackFcn(app, @d45EditFieldValueChanging, true);
            app.d45EditField.Tag = '22';
            app.d45EditField.Value = '0';
            app.a45EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.a45EditField.ValueChangedFcn = createCallbackFcn(app, @a45EditFieldValueChanged, true);
            app.a45EditField.ValueChangingFcn = createCallbackFcn(app, @a45EditFieldValueChanging, true);
            app.a45EditField.Tag = '23';
            app.a45EditField.Value = '0';
            app.alpha45EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.alpha45EditField.ValueChangedFcn = createCallbackFcn(app, @alpha45EditFieldValueChanged, true);
            app.alpha45EditField.ValueChangingFcn = createCallbackFcn(app, @alpha45EditFieldValueChanging, true);
            app.alpha45EditField.Tag = '24';
            app.alpha45EditField.Value = 'pi/2';
            app.s45EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.s45EditField.ValueChangedFcn = createCallbackFcn(app, @s45EditFieldValueChanged, true);
            app.s45EditField.ValueChangingFcn = createCallbackFcn(app, @s45EditFieldValueChanging, true);
            app.s45EditField.Tag = '25';
            app.s45EditField.Value = '0';
            app.theta56EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.theta56EditField.ValueChangedFcn = createCallbackFcn(app, @theta56EditFieldValueChanged, true);
            app.theta56EditField.ValueChangingFcn = createCallbackFcn(app, @theta56EditFieldValueChanging, true);
            app.theta56EditField.Tag = '26';
            app.theta56EditField.Value = '0';
            app.d56EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.d56EditField.ValueChangedFcn = createCallbackFcn(app, @d56EditFieldValueChanged, true);
            app.d56EditField.ValueChangingFcn = createCallbackFcn(app, @d56EditFieldValueChanging, true);
            app.d56EditField.Tag = '27';
            app.d56EditField.Value = '0.07';
            app.a56EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.a56EditField.ValueChangedFcn = createCallbackFcn(app, @a56EditFieldValueChanged, true);
            app.a56EditField.ValueChangingFcn = createCallbackFcn(app, @a56EditFieldValueChanging, true);
            app.a56EditField.Tag = '28';
            app.a56EditField.Value = '0';
            app.alpha56EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.alpha56EditField.ValueChangedFcn = createCallbackFcn(app, @alpha56EditFieldValueChanged, true);
            app.alpha56EditField.ValueChangingFcn = createCallbackFcn(app, @alpha56EditFieldValueChanging, true);
            app.alpha56EditField.Tag = '29';
            app.alpha56EditField.Value = '0';
            app.s56EditField = uieditfield(app.DHKinematicParamsGridLayout, 'text');
            app.s56EditField.ValueChangedFcn = createCallbackFcn(app, @s56EditFieldValueChanged, true);
            app.s56EditField.ValueChangingFcn = createCallbackFcn(app, @s56EditFieldValueChanging, true);
            app.s56EditField.Tag = '30';
            app.s56EditField.Value = '0';
            app.RobotRepeatabilityLabel = uilabel(app.RobotParamsUIFigure);
            app.RobotRepeatabilityLabel.Text = 'Ponovljivost robota';
            app.RobotRepeatabilityEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.RobotRepeatabilityEditField.ValueChangedFcn = createCallbackFcn(app, @RobotRepeatabilityEditFieldValueChanged, true);
            app.RobotRepeatabilityEditField.ValueChangingFcn = createCallbackFcn(app, @RobotRepeatabilityEditFieldValueChanging, true);
            app.RobotRepeatabilityEditField.Tag = '43';
            app.RobotRepeatabilityEditField.Value = '0.00002';
            app.DefaultValuesButton = uibutton(app.RobotParamsUIFigure, 'push');
            app.DefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultValuesButtonPushed, true);
            app.DefaultValuesButton.Text = {'Podrazumijevane'; 'vrijednosti'};
            app.ApplyButton = uibutton(app.RobotParamsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Primijeni';
            app.ToolTransformLabel = uilabel(app.RobotParamsUIFigure);
            app.ToolTransformLabel.Text = 'Transformacija alata';
            app.Ox6y6z6ToToolTipLabel = uilabel(app.RobotParamsUIFigure);
            app.Ox6y6z6ToToolTipLabel.Text = '(Ox6y6z6 → vrh alata)';
            app.ToolTransformPanel = uipanel(app.RobotParamsUIFigure);
            app.ToolTransformGridLayout = uigridlayout(app.ToolTransformPanel);
            app.TT11EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT11EditField.ValueChangedFcn = createCallbackFcn(app, @TT11EditFieldValueChanged, true);
            app.TT11EditField.ValueChangingFcn = createCallbackFcn(app, @TT11EditFieldValueChanging, true);
            app.TT11EditField.Tag = '44';
            app.TT11EditField.Value = '1';
            app.TT12EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT12EditField.ValueChangedFcn = createCallbackFcn(app, @TT12EditFieldValueChanged, true);
            app.TT12EditField.ValueChangingFcn = createCallbackFcn(app, @TT12EditFieldValueChanging, true);
            app.TT12EditField.Tag = '45';
            app.TT12EditField.Value = '0';
            app.TT13EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT13EditField.ValueChangedFcn = createCallbackFcn(app, @TT13EditFieldValueChanged, true);
            app.TT13EditField.ValueChangingFcn = createCallbackFcn(app, @TT13EditFieldValueChanging, true);
            app.TT13EditField.Tag = '46';
            app.TT13EditField.Value = '0';
            app.TT14EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT14EditField.ValueChangedFcn = createCallbackFcn(app, @TT14EditFieldValueChanged, true);
            app.TT14EditField.ValueChangingFcn = createCallbackFcn(app, @TT14EditFieldValueChanging, true);
            app.TT14EditField.Tag = '47';
            app.TT14EditField.Value = '0.03';
            app.TT21EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT21EditField.ValueChangedFcn = createCallbackFcn(app, @TT21EditFieldValueChanged, true);
            app.TT21EditField.ValueChangingFcn = createCallbackFcn(app, @TT21EditFieldValueChanging, true);
            app.TT21EditField.Tag = '48';
            app.TT21EditField.Value = '0';
            app.TT22EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT22EditField.ValueChangedFcn = createCallbackFcn(app, @TT22EditFieldValueChanged, true);
            app.TT22EditField.ValueChangingFcn = createCallbackFcn(app, @TT22EditFieldValueChanging, true);
            app.TT22EditField.Tag = '49';
            app.TT22EditField.Value = '1';
            app.TT23EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT23EditField.ValueChangedFcn = createCallbackFcn(app, @TT23EditFieldValueChanged, true);
            app.TT23EditField.ValueChangingFcn = createCallbackFcn(app, @TT23EditFieldValueChanging, true);
            app.TT23EditField.Tag = '50';
            app.TT23EditField.Value = '0';
            app.TT24EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT24EditField.ValueChangedFcn = createCallbackFcn(app, @TT24EditFieldValueChanged, true);
            app.TT24EditField.ValueChangingFcn = createCallbackFcn(app, @TT24EditFieldValueChanging, true);
            app.TT24EditField.Tag = '51';
            app.TT24EditField.Value = '0';
            app.TT31EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT31EditField.ValueChangedFcn = createCallbackFcn(app, @TT31EditFieldValueChanged, true);
            app.TT31EditField.ValueChangingFcn = createCallbackFcn(app, @TT31EditFieldValueChanging, true);
            app.TT31EditField.Tag = '52';
            app.TT31EditField.Value = '0';
            app.TT32EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT32EditField.ValueChangedFcn = createCallbackFcn(app, @TT32EditFieldValueChanged, true);
            app.TT32EditField.ValueChangingFcn = createCallbackFcn(app, @TT32EditFieldValueChanging, true);
            app.TT32EditField.Tag = '53';
            app.TT32EditField.Value = '0';
            app.TT33EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT33EditField.ValueChangedFcn = createCallbackFcn(app, @TT33EditFieldValueChanged, true);
            app.TT33EditField.ValueChangingFcn = createCallbackFcn(app, @TT33EditFieldValueChanging, true);
            app.TT33EditField.Tag = '54';
            app.TT33EditField.Value = '1';
            app.TT34EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT34EditField.ValueChangedFcn = createCallbackFcn(app, @TT34EditFieldValueChanged, true);
            app.TT34EditField.ValueChangingFcn = createCallbackFcn(app, @TT34EditFieldValueChanging, true);
            app.TT34EditField.Tag = '55';
            app.TT34EditField.Value = '0.176';
            app.TT41EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT41EditField.ValueChangedFcn = createCallbackFcn(app, @TT41EditFieldValueChanged, true);
            app.TT41EditField.ValueChangingFcn = createCallbackFcn(app, @TT41EditFieldValueChanging, true);
            app.TT41EditField.Tag = '56';
            app.TT41EditField.Value = '0';
            app.TT42EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT42EditField.ValueChangedFcn = createCallbackFcn(app, @TT42EditFieldValueChanged, true);
            app.TT42EditField.ValueChangingFcn = createCallbackFcn(app, @TT42EditFieldValueChanging, true);
            app.TT42EditField.Tag = '57';
            app.TT42EditField.Value = '0';
            app.TT43EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT43EditField.ValueChangedFcn = createCallbackFcn(app, @TT43EditFieldValueChanged, true);
            app.TT43EditField.ValueChangingFcn = createCallbackFcn(app, @TT43EditFieldValueChanging, true);
            app.TT43EditField.Tag = '58';
            app.TT43EditField.Value = '0';
            app.TT44EditField = uieditfield(app.ToolTransformGridLayout, 'text');
            app.TT44EditField.ValueChangedFcn = createCallbackFcn(app, @TT44EditFieldValueChanged, true);
            app.TT44EditField.ValueChangingFcn = createCallbackFcn(app, @TT44EditFieldValueChanging, true);
            app.TT44EditField.Tag = '59';
            app.TT44EditField.Value = '1';
            app.CardanAnglesTypeDropDown = uidropdown(app.RobotParamsUIFigure);
            app.CardanAnglesTypeDropDown.Items = {'z→y''→x'''' / z0←y0←x0 - ROLL-PITCH-YAW', 'z→x''→y'''' / z0←x0←y0', 'y→z''→x'''' / y0←z0←x0', 'y→x''→z'''' / y0←x0←z0', 'x→z''→y'''' / x0←z0←y0', 'x→y''→z'''' / x0←y0←z0 - YAW-PITCH-ROLL'};
            app.CardanAnglesTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CardanAnglesTypeDropDownValueChanged, true);
            app.CardanAnglesTypeDropDown.Visible = 'off';
            app.CardanAnglesTypeDropDown.Value = 'z→y''→x'''' / z0←y0←x0 - ROLL-PITCH-YAW';
            app.EulerAnglesTypeDropDown = uidropdown(app.RobotParamsUIFigure);
            app.EulerAnglesTypeDropDown.Items = {'z→y''→z'''' / z0←y0←z0', 'z→x''→z'''' / z0←x0←z0', 'y→z''→y'''' / y0←z0←y0', 'y→x''→y'''' / y0←x0←y0', 'x→z''→x'''' / x0←z0←x0', 'x→y''→x'''' / x0←y0←x0'};
            app.EulerAnglesTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @EulerAnglesTypeDropDownValueChanged, true);
            app.EulerAnglesTypeDropDown.Visible = 'off';
            app.EulerAnglesTypeDropDown.Value = 'z→y''→z'''' / z0←y0←z0';
            app.ToolOrientationAnglesTypeLabel = uilabel(app.RobotParamsUIFigure);
            app.ToolOrientationAnglesTypeLabel.Visible = 'off';
            app.ToolOrientationAnglesTypeLabel.Text = 'Tip';
            app.TOATButton = uibutton(app.RobotParamsUIFigure, 'push');
            app.TOATButton.ButtonPushedFcn = createCallbackFcn(app, @TOATButtonPushed, true);
            app.TOATButton.Text = 'O.1-zyz';
            app.ToolOrientationDescLabel = uilabel(app.RobotParamsUIFigure);
            app.ToolOrientationDescLabel.Text = {'(u odnosu na referentni'; 'koordinatni sistem'; 'u toku pisanja)'};
            app.TOPsiEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.TOPsiEditField.ValueChangedFcn = createCallbackFcn(app, @TOPsiEditFieldValueChanged, true);
            app.TOPsiEditField.ValueChangingFcn = createCallbackFcn(app, @TOPsiEditFieldValueChanging, true);
            app.TOPsiEditField.Tag = '71';
            app.TOPsiEditField.Editable = 'off';
            app.TOPsiEditField.Value = 'pi/2';
            app.TOPhiEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.TOPhiEditField.ValueChangedFcn = createCallbackFcn(app, @TOPhiEditFieldValueChanged, true);
            app.TOPhiEditField.ValueChangingFcn = createCallbackFcn(app, @TOPhiEditFieldValueChanging, true);
            app.TOPhiEditField.Tag = '70';
            app.TOPhiEditField.Editable = 'off';
            app.TOPhiEditField.Value = 'pi';
            app.TOThetaEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.TOThetaEditField.ValueChangedFcn = createCallbackFcn(app, @TOThetaEditFieldValueChanged, true);
            app.TOThetaEditField.ValueChangingFcn = createCallbackFcn(app, @TOThetaEditFieldValueChanging, true);
            app.TOThetaEditField.Tag = '69';
            app.TOThetaEditField.Editable = 'off';
            app.TOThetaEditField.Value = '0';
            app.TOPsiLabel = uilabel(app.RobotParamsUIFigure);
            app.TOPsiLabel.Text = 'ψ';
            app.TOPhiLabel = uilabel(app.RobotParamsUIFigure);
            app.TOPhiLabel.Text = 'φ';
            app.TOThetaLabel = uilabel(app.RobotParamsUIFigure);
            app.TOThetaLabel.Text = 'θ';
            app.TOAngleUnitLabel = uilabel(app.RobotParamsUIFigure);
            app.TOAngleUnitLabel.Text = 'Jedinica za ugao';
            app.TOAngleUnitButtonGroup = uibuttongroup(app.RobotParamsUIFigure);
            app.TOAngleUnitButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TOAngleUnitButtonGroupSelectionChanged, true);
            app.TORadButton = uiradiobutton(app.TOAngleUnitButtonGroup);
            app.TORadButton.Text = 'rad';
            app.TORadButton.Value = true;
            app.TODegreeButton = uiradiobutton(app.TOAngleUnitButtonGroup);
            app.TODegreeButton.Text = 'stepen';
            app.ToolOrientationSelectionMethodDropDown = uidropdown(app.RobotParamsUIFigure);
            app.ToolOrientationSelectionMethodDropDown.Items = {'Matrica rotacije', 'Ojlerovi uglovi', 'Aeronautički uglovi'};
            app.ToolOrientationSelectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ToolOrientationSelectionMethodDropDownValueChanged, true);
            app.ToolOrientationSelectionMethodDropDown.Value = 'Matrica rotacije';
            app.ToolOrientationLabel = uilabel(app.RobotParamsUIFigure);
            app.ToolOrientationLabel.Text = 'Orijentacija alata';
            app.ToolOrientationMatrixPanel = uipanel(app.RobotParamsUIFigure);
            app.ToolOrientationMatrixGridLayout = uigridlayout(app.ToolOrientationMatrixPanel);
            app.TOM11EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM11EditField.ValueChangedFcn = createCallbackFcn(app, @TOM11EditFieldValueChanged, true);
            app.TOM11EditField.ValueChangingFcn = createCallbackFcn(app, @TOM11EditFieldValueChanging, true);
            app.TOM11EditField.Tag = '60';
            app.TOM11EditField.Value = '0';
            app.TOM12EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM12EditField.ValueChangedFcn = createCallbackFcn(app, @TOM12EditFieldValueChanged, true);
            app.TOM12EditField.ValueChangingFcn = createCallbackFcn(app, @TOM12EditFieldValueChanging, true);
            app.TOM12EditField.Tag = '61';
            app.TOM12EditField.Value = '1';
            app.TOM13EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM13EditField.ValueChangedFcn = createCallbackFcn(app, @TOM13EditFieldValueChanged, true);
            app.TOM13EditField.ValueChangingFcn = createCallbackFcn(app, @TOM13EditFieldValueChanging, true);
            app.TOM13EditField.Tag = '62';
            app.TOM13EditField.Value = '0';
            app.TOM21EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM21EditField.ValueChangedFcn = createCallbackFcn(app, @TOM21EditFieldValueChanged, true);
            app.TOM21EditField.ValueChangingFcn = createCallbackFcn(app, @TOM21EditFieldValueChanging, true);
            app.TOM21EditField.Tag = '63';
            app.TOM21EditField.Value = '1';
            app.TOM22EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM22EditField.ValueChangedFcn = createCallbackFcn(app, @TOM22EditFieldValueChanged, true);
            app.TOM22EditField.ValueChangingFcn = createCallbackFcn(app, @TOM22EditFieldValueChanging, true);
            app.TOM22EditField.Tag = '64';
            app.TOM22EditField.Value = '0';
            app.TOM23EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM23EditField.ValueChangedFcn = createCallbackFcn(app, @TOM23EditFieldValueChanged, true);
            app.TOM23EditField.ValueChangingFcn = createCallbackFcn(app, @TOM23EditFieldValueChanging, true);
            app.TOM23EditField.Tag = '65';
            app.TOM23EditField.Value = '0';
            app.TOM31EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM31EditField.ValueChangedFcn = createCallbackFcn(app, @TOM31EditFieldValueChanged, true);
            app.TOM31EditField.ValueChangingFcn = createCallbackFcn(app, @TOM31EditFieldValueChanging, true);
            app.TOM31EditField.Tag = '66';
            app.TOM31EditField.Value = '0';
            app.TOM32EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM32EditField.ValueChangedFcn = createCallbackFcn(app, @TOM32EditFieldValueChanged, true);
            app.TOM32EditField.ValueChangingFcn = createCallbackFcn(app, @TOM32EditFieldValueChanging, true);
            app.TOM32EditField.Tag = '67';
            app.TOM32EditField.Value = '0';
            app.TOM33EditField = uieditfield(app.ToolOrientationMatrixGridLayout, 'text');
            app.TOM33EditField.ValueChangedFcn = createCallbackFcn(app, @TOM33EditFieldValueChanged, true);
            app.TOM33EditField.ValueChangingFcn = createCallbackFcn(app, @TOM33EditFieldValueChanging, true);
            app.TOM33EditField.Tag = '68';
            app.TOM33EditField.Value = '-1';
            app.ToolOrientationSelectionMethodLabel = uilabel(app.RobotParamsUIFigure);
            app.ToolOrientationSelectionMethodLabel.Text = 'Način odabira';
            app.RefPosLabel = uilabel(app.RobotParamsUIFigure);
            app.RefPosLabel.Text = 'Referentna pozicija';
            app.RxLabel = uilabel(app.RobotParamsUIFigure);
            app.RxLabel.Text = 'Rx';
            app.RxEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.RxEditField.ValueChangedFcn = createCallbackFcn(app, @RxEditFieldValueChanged, true);
            app.RxEditField.ValueChangingFcn = createCallbackFcn(app, @RxEditFieldValueChanging, true);
            app.RxEditField.Tag = '72';
            app.RxEditField.Value = '-0.075';
            app.RyLabel = uilabel(app.RobotParamsUIFigure);
            app.RyLabel.Text = 'Ry';
            app.RyEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.RyEditField.ValueChangedFcn = createCallbackFcn(app, @RyEditFieldValueChanged, true);
            app.RyEditField.ValueChangingFcn = createCallbackFcn(app, @RyEditFieldValueChanging, true);
            app.RyEditField.Tag = '73';
            app.RyEditField.Value = '0.21';
            app.RzLabel = uilabel(app.RobotParamsUIFigure);
            app.RzLabel.Text = 'Rz';
            app.RzEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.RzEditField.ValueChangedFcn = createCallbackFcn(app, @RzEditFieldValueChanged, true);
            app.RzEditField.ValueChangingFcn = createCallbackFcn(app, @RzEditFieldValueChanging, true);
            app.RzEditField.Tag = '74';
            app.RzEditField.Value = '0.3';
            app.PathToModelsEditField = uieditfield(app.RobotParamsUIFigure, 'text');
            app.PathToModelsEditField.ValueChangedFcn = createCallbackFcn(app, @PathToModelsEditFieldValueChanged, true);
            app.PathToModelsEditField.Tag = '75';
            app.PathToModelsEditField.Value = './models';
            app.PathToModelsButton = uibutton(app.RobotParamsUIFigure, 'push');
            app.PathToModelsButton.ButtonPushedFcn = createCallbackFcn(app, @PathToModelsButtonPushed, true);
            app.PathToModelsButton.Text = 'Izaberi';
            app.PathToModelsLabel = uilabel(app.RobotParamsUIFigure);
            app.PathToModelsLabel.Text = 'Putanja do modela';
            app.RobotPreviewLabel = uilabel(app.RobotParamsUIFigure);
            app.RobotPreviewLabel.Text = {'Prikaz'; 'robota'};
            app.LinkedCoordinateSystemsLabel = uilabel(app.RobotParamsUIFigure);
            app.LinkedCoordinateSystemsLabel.Text = {'Vezani'; 'koordinatni'; 'sistemi'};
            app.RobotPreviewImage = uiimage(app.RobotParamsUIFigure);
            app.RobotPreviewImage.ImageClickedFcn = createCallbackFcn(app, @RobotPreviewImageClicked, true);
            app.RobotPreviewImage.ImageSource = 'robot_preview.png';
            app.LinkedCoordinateSystemsImage = uiimage(app.RobotParamsUIFigure);
            app.LinkedCoordinateSystemsImage.ImageClickedFcn = createCallbackFcn(app, @LinkedCoordinateSystemsImageClicked, true);
            app.LinkedCoordinateSystemsImage.ImageSource = 'linked_coordinate_systems.png';
            app.CloseImagePreviewButton = uibutton(app.RobotParamsUIFigure, 'push');
            app.CloseImagePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @CloseImagePreviewButtonPushed, true);
            app.CloseImagePreviewButton.Visible = 'off';
            app.CloseImagePreviewButton.Text = 'X';
            % Show the figure after all components are created
            app.RobotParamsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_params(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotParamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotParamsUIFigure)
        end
    end
end