classdef robot_transfer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotTransferUIFigure      matlab.ui.Figure
        ConnectionToRobotPanel     matlab.ui.container.Panel
        ConnectionToRobotLabel     matlab.ui.control.Label
        IPLabel                    matlab.ui.control.Label
        IPEditField                matlab.ui.control.EditField
        PortLabel                  matlab.ui.control.Label
        PortEditField              matlab.ui.control.NumericEditField
        BufferSizeLabel            matlab.ui.control.Label
        InputBufferSizeLabel       matlab.ui.control.Label
        InputBufferSizeEditField   matlab.ui.control.NumericEditField
        OutputBufferSizeLabel      matlab.ui.control.Label
        OutputBufferSizeEditField  matlab.ui.control.NumericEditField
        DefaultValuesButton        matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        ConnectToRobotButton       matlab.ui.control.Button
        DisconnectFromRobotButton  matlab.ui.control.Button
        TransferToRobotButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        caller_app; % Glavna aplikacija
        dws = [480,290]; % Podrazumijevana velicina prozora
        dws2 = [480,290,480,290];
        crb_timer; % Promjena boje teksta ConnectToRobotButton-a u
                   % zavisnosti da li je konekcija sa robotom uspjesna
        wr_timer; % waitrobot timer
        wr_check; % waitrobot provjera
        t2r_timer; % Promjena boje teksta TransferToRobotButton-a u
                   % zavisnosti da li je uspjesno izvrsen prenos
        t; % tcpip objekat konekcije sa robotskim kontrolerom
        running; % da li je pokrenut robot
        RCE = MException('waitrobot:noResponse', 'Robot controller is not responsive.');
    end
    
    methods (Access = private)
        
        function timer_func(app, obj, event, btn)
            btn.FontColor = 'black';
        end
        
        function timer_stop_func(app, obj, event)
            delete(obj);
        end
        
        function wr_timer_func(app, obj, event)
            app.wr_check = false;
        end
        
        function waitrobot(app)
            check = false;
            app.wr_check = true;
            app.wr_timer = timer('StartDelay',2, 'ExecutionMode','singleShot');
            app.wr_timer.TimerFcn = @app.wr_timer_func;
            app.wr_timer.StopFcn = @app.timer_stop_func;
            start(app.wr_timer);
        	in = '';
        	while app.wr_check
                if ~contains(in, 'QoK')
            		len = app.t.BytesAvailable;
            		if(len > 0)
            			in = [in char(fread(app.t, 1))'];
            		end
                else
                    check = true;
                    break;
                end
        	end
            if ~check
                throw(app.RCE);
            end
        end
        
        function initializeConnection(app)
            % IP adresa i port robotskog kontrolera 10.1.1.120:10001
            app.t = tcpip(app.IPEditField.Value, ...
                          app.PortEditField.Value);
            app.t.InputBufferSize = app.InputBufferSizeEditField.Value;
            app.t.OutputBufferSize = app.OutputBufferSizeEditField.Value;
        end
        
        function closeConnection(app)
        	fwrite(app.t,'1; 1;CNTLOFF');
            app.waitrobot();
            fwrite(app.t,'1; 1;SRVOFF');
            app.waitrobot();
            fclose(app.t);
        end
        
        function openConnection(app)
            fopen(app.t);
            fwrite(app.t,'1; 1;RSTALRM');
            app.waitrobot();
            fwrite(app.t,'1; 1;STATE');
            app.waitrobot();
            fwrite(app.t,'1; 1;CNTLON');
            app.waitrobot();
            fwrite(app.t,'1; 1;SRVON');
            app.waitrobot();
        
            len = app.t.BytesAvailable;
            if(len > 0)
                st = fread(app.t, len);
                char(st)'
            end
        
            fwrite(app.t,['1; 1;PPOSF']);    
            app.waitrobot();
            len = t.BytesAvailable;
            if(len > 0)
                st = fread(app.t, len);
                char(st)'
            end
        
            fwrite(t,['1; 1;JPOSF']);    
            app.waitrobot();
            len = t.BytesAvailable;
            if(len > 0)
                st = fread(app.t, len);
                char(st)'
            end
            
            pause(2);
        end
        
        function robotcontrol(app)
        	% ucitavamo program sa robota
        	fprintf(app.t,'1; 1;NEW');
        	fprintf("%s\n", '1; 1;NEW');
        	app.waitrobot();
        	fprintf(app.t,'1; 1;LOAD=RITAN');
        	fprintf("%s\n", '1; 1;LOAD=RITAN');
        	app.waitrobot();
        	fprintf(app.t,'1; 1;PRTVERLISTL');
        	fprintf("%s\n", '1; 1;PRTVERLISTL');
        	app.waitrobot();
        	fprintf(app.t,'1; 1;PRTVEREMDAT');
        	fprintf("%s\n", '1; 1;PRTVEREMDAT');
        	app.waitrobot();
        	
        	% brisemo stari program na robotu
        	fprintf(app.t,'1; 9;LISTL<');
        	fprintf("%s\n", '1; 9;LISTL<');
        	app.waitrobot();
        	prog = '';
        	lines = 0;
        	len = app.t.BytesAvailable;
        	if(len > 0)
        		prog = [char(fread(app.t, len))'];
        	end
        	temp = extractAfter(prog,';');
        	lines = lines + str2num(extractBefore(temp,';'));
        	while(str2num(extractAfter(temp,';')) == 1)
        		fprintf(app.t, '1; 9;LISTL+1');
        		fprintf("%s\n", '1; 9;LISTL+1');
        		app.waitrobot();
        		len = app.t.BytesAvailable;
        		if(len > 0)
        			prog = [char(fread(app.t, len))'];
        		end
        		temp = extractAfter(prog,';');
        		lines = lines + str2num(extractBefore(temp,';'));
        	end
        	for i = 1 : lines-1
        		prog = ['1; 9;EMDAT1' str2num('0xb')];
        		fprintf(app.t, prog);
        		app.waitrobot();
        	end
        	fprintf(app.t, '1; 9;EMDAT1');
        	app.waitrobot();
        	
        	% upisujemo nove polozaje robota
        	for i = 1 : size(app.caller_app.posrot,2) % za svaku rijec
        		for j = 1 : size(app.caller_app.posrot{1,i}, 2) % za svaki karakter u rijeci
        			for k = 1 : size(app.caller_app.posrot{1,i}{1,j},2) % za svaki polozaj (poziciju i rotaciju) u karakteru
        				str = '';
        				for m = 1 : 6
        					str = [str num2str(app.caller_app.posrot{1,i}{1,j}(m,k),'%.3f')];
        					if m < 6
        						str = [str ', '];
        					end
        				end
        				cm = ['1; 9;EMDATPR_W' num2str(i) 'C' num2str(j) 'N' num2str(k) ' = (' str ')(6, 0)'];
        				fprintf(app.t, cm);
        				fprintf("%s\n", cm)
        				app.waitrobot();
        			end
        		end
        	end
        	
        	% formiramo MELFA BASIC V program
        	prog = ['1; 9;EMDAT' '1 OVRD ' num2str(app.caller_app.robotcontrol_params.override, '%.1f') str2num('0xb') ...
        						 '2 JOVRD ' num2str(app.caller_app.robotcontrol_params.joverride, '%.1f') str2num('0xb') ...
        						 '3 SPD ' num2str(app.caller_app.robotcontrol_params.speed, '%.3f') str2num('0xb') ...
        						 '4 ACCEL ' num2str(app.caller_app.robotcontrol_params.accel,'%d') ',' num2str(app.caller_app.robotcontrol_params.decel,'%d') str2num('0xb')];
        	fprintf(t, prog);
        	fprintf("%s\n", prog)
        	waitrobot();
        	lnum = 5; % tekuci broj linije u programu
        	for i = 1 : size(app.caller_app.posrot,2) % za svaku rijec
        		for j = 1 : size(app.caller_app.posrot{1,i}, 2) % za svaki karakter u rijeci
        			for k = 1 : size(app.caller_app.posrot{1,i}{1,j},2) % za svaki polozaj (pozicija i rotacija) u karakteru
        				prog = ['1; 9;EMDAT' num2str(lnum, '%d') ' ' app.caller_app.robotcontrol_params.interpolation_cmd ' PR_W' num2str(i) 'C' num2str(j) 'N' num2str(k) str2num('0xb')];
        				fprintf(app.t, prog);
        				fprintf("%s\n", prog);
        				app.waitrobot();
        				lnum = lnum + 1;
        			end
        		end
        	end
        	prog = ['1; 9;EMDAT' num2str(lnum, '%d') ' END'];
        	fprintf(app.t, prog);
        	fprintf("%s\n", prog);
        	app.waitrobot();
        	
        	fprintf(app.t,'1; 1;SAVE');
        	fprintf("%s\n", '1; 1;SAVE');
        	app.waitrobot();
        	fprintf(app.t,'1; 1;RUNRITAN;1');
        	fprintf("%s\n", '1; 1;RUNRITAN;1');
        	app.waitrobot();
    	end
        
        
        function changed = connParamsChanged(app)
            changed = false;
            if(~strcmp(app.t.RemoteHost, app.IPEditField.Value) || ...
               ~strcmp(app.t.RemotePort, app.PortEditField.Value) || ...
               ~strcmp(app.t.InputBufferSize, app.InputBufferSizeEditField.Value) || ...
               ~strcmp(app.t.OutputBufferSize, app.OutputBufferSizeEditField.Value))
                changed = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mapp)
            app.caller_app = mapp;
            
            app.IPEditField.Value = app.caller_app.robotconnection_params.ip;
            app.PortEditField.Value = app.caller_app.robotconnection_params.port;
            app.InputBufferSizeEditField.Value = app.caller_app.robotconnection_params.ibs;
            app.OutputBufferSizeEditField.Value = app.caller_app.robotconnection_params.obs;
            
            app.initializeConnection();
        end

        % Size changed function: RobotTransferUIFigure
        function RobotTransferUIFigureSizeChanged(app, event)
            cws = app.RobotTransferUIFigure.Position(3:4);
            if(cws(1) > app.dws(1))
                app.RobotTransferUIFigure.Position(3) = app.dws(1);
            end
            if(cws(2) > app.dws(2))
                app.RobotTransferUIFigure.Position(4) = app.dws(2);
            end
        end

        % Button pushed function: DefaultValuesButton
        function DefaultValuesButtonPushed(app, event)
            app.IPEditField.Value = '10.1.1.120';
            app.PortEditField.Value = 10001;
            app.InputBufferSizeEditField.Value = 1024;
            app.OutputBufferSizeEditField.Value = 1024;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.caller_app.robotconnection_params.ip = app.IPEditField.Value;
            app.caller_app.robotconnection_params.port = app.PortEditField.Value;
            app.caller_app.robotconnection_params.ibs = app.InputBufferSizeEditField.Value;
            app.caller_app.robotconnection_params.obs = app.OutputBufferSizeEditField.Value;
        end

        % Button pushed function: ConnectToRobotButton
        function ConnectToRobotButtonPushed(app, event)
            app.crb_timer = timer('StartDelay',1, 'ExecutionMode','singleShot');
            app.crb_timer.TimerFcn = {@app.timer_func, app.ConnectToRobotButton};
            app.crb_timer.StopFcn = @app.timer_stop_func;
            
            if app.connParamsChanged()
                app.ConnectToRobotButton.FontColor = [0.9 0.7 0.0];
                app.RobotTransferUIFigure.Pointer = 'watch';
                pause(0.2);
                if strcmp(app.t.Status,'open')
                    try
                        app.closeConnection();
                    catch ex
                        fclose(app.t);
                        app.ConnectToRobotButton.FontColor = [0.9 0.3 0.3];
                        app.RobotTransferUIFigure.Pointer = 'arrow';
                        pause(0.2);
                        start(app.crb_timer);
                        throw(ex);
                    end
                end
                app.initializeConnection();
                try
                    app.openConnection();
                catch ex
                    app.ConnectToRobotButton.FontColor = [0.9 0.3 0.3];
                    app.RobotTransferUIFigure.Pointer = 'arrow';
                    pause(0.2);
                    start(app.crb_timer);
                    throw(ex);
                end
                app.ConnectToRobotButton.FontColor = [0.4 0.7 0.1];
                app.RobotTransferUIFigure.Pointer = 'arrow';
                pause(0.2);
                start(app.crb_timer);
            elseif strcmp(app.t.Status, 'closed')
                app.ConnectToRobotButton.FontColor = [0.9 0.7 0.0];
                app.RobotTransferUIFigure.Pointer = 'watch';
                app.pause(0.2);
                try
                    app.openConnection();
                catch ex
                    app.ConnectToRobotButton.FontColor = [0.9 0.3 0.3];
                    app.RobotTransferUIFigure.Pointer = 'arrow';
                    pause(0.2);
                    start(app.crb_timer);
                    throw(ex);
                end
                app.ConnectToRobotButton.FontColor = [0.4 0.7 0.1];
                app.RobotTransferUIFigure.Pointer = 'arrow';
                pause(0.2);
                start(app.crb_timer);
            end
        end

        % Button pushed function: DisconnectFromRobotButton
        function DisconnectFromRobotButtonPushed(app, event)
            if app.t.Status == 'open'
                try
                    app.closeConnection();
                catch ex
                    fclose(app.t);
                    throw(ex);
                end
            end
        end

        % Button pushed function: TransferToRobotButton
        function TransferToRobotButtonPushed(app, event)
            if app.t.Status == 'open'
                app.robotcontrol();
            end
        end

        % Close request function: RobotTransferUIFigure
        function RobotTransferUIFigureCloseRequest(app, event)
            app.caller_app.RobotTransferButton.Enable = true;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotTransferUIFigure = uifigure('Visible', 'off');
            app.RobotTransferUIFigure.Name = 'Prenos na robota';
            app.RobotTransferUIFigure.CloseRequestFcn = createCallbackFcn(app, @RobotTransferUIFigureCloseRequest, true);
            app.RobotTransferUIFigure.SizeChangedFcn = createCallbackFcn(app, @RobotTransferUIFigureSizeChanged, true);
            app.ConnectionToRobotPanel = uipanel(app.RobotTransferUIFigure);
            app.ConnectionToRobotLabel = uilabel(app.ConnectionToRobotPanel);
            app.ConnectionToRobotLabel.Text = 'Konekcija sa robotom';
            app.IPLabel = uilabel(app.ConnectionToRobotPanel);
            app.IPLabel.Text = 'IP adresa';
            app.IPEditField = uieditfield(app.ConnectionToRobotPanel, 'text');
            app.IPEditField.Value = '10.1.1.120';
            app.PortLabel = uilabel(app.ConnectionToRobotPanel);
            app.PortLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ConnectionToRobotPanel, 'numeric');
            app.PortEditField.Limits = [1 65535];
            app.PortEditField.ValueDisplayFormat = '%d';
            app.PortEditField.Value = 10001;
            app.BufferSizeLabel = uilabel(app.ConnectionToRobotPanel);
            app.BufferSizeLabel.Text = 'Veličina bafera';
            app.InputBufferSizeLabel = uilabel(app.ConnectionToRobotPanel);
            app.InputBufferSizeLabel.Text = 'ulazni';
            app.InputBufferSizeEditField = uieditfield(app.ConnectionToRobotPanel, 'numeric');
            app.InputBufferSizeEditField.Limits = [1 2147483647];
            app.InputBufferSizeEditField.ValueDisplayFormat = '%d';
            app.InputBufferSizeEditField.Value = 1024;
            app.OutputBufferSizeLabel = uilabel(app.ConnectionToRobotPanel);
            app.OutputBufferSizeLabel.Text = 'izlazni';
            app.OutputBufferSizeEditField = uieditfield(app.ConnectionToRobotPanel, 'numeric');
            app.OutputBufferSizeEditField.Limits = [1 2147483647];
            app.OutputBufferSizeEditField.ValueDisplayFormat = '%d';
            app.OutputBufferSizeEditField.Value = 1024;
            app.DefaultValuesButton = uibutton(app.ConnectionToRobotPanel, 'push');
            app.DefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultValuesButtonPushed, true);
            app.DefaultValuesButton.Text = {'Podrazumijevane'; 'vrijednosti'};
            app.ApplyButton = uibutton(app.ConnectionToRobotPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Primijeni';
            app.ConnectToRobotButton = uibutton(app.ConnectionToRobotPanel, 'push');
            app.ConnectToRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToRobotButtonPushed, true);
            app.ConnectToRobotButton.Text = 'Uspostavi konekciju';
            app.DisconnectFromRobotButton = uibutton(app.ConnectionToRobotPanel, 'push');
            app.DisconnectFromRobotButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectFromRobotButtonPushed, true);
            app.DisconnectFromRobotButton.Text = 'Prekini konekciju';
            app.TransferToRobotButton = uibutton(app.RobotTransferUIFigure, 'push');
            app.TransferToRobotButton.ButtonPushedFcn = createCallbackFcn(app, @TransferToRobotButtonPushed, true);
            app.TransferToRobotButton.Text = 'Prenesi na robota';
            % Show the figure after all components are created
            app.RobotTransferUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_transfer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotTransferUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotTransferUIFigure)
        end
    end
end