classdef robotcontrol_params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RobotControlParamsUIFigure   matlab.ui.Figure
        OVRDLabel                    matlab.ui.control.Label
        JOVRDLabel                   matlab.ui.control.Label
        SPDLabel                     matlab.ui.control.Label
        ACCELLabel                   matlab.ui.control.Label
        DECELLabel                   matlab.ui.control.Label
        InterpolationMethodLabel     matlab.ui.control.Label
        OVRDSpinner                  matlab.ui.control.Spinner
        JOVRDSpinner                 matlab.ui.control.Spinner
        SPDSpinner                   matlab.ui.control.Spinner
        ACCELSpinner                 matlab.ui.control.Spinner
        DECELSpinner                 matlab.ui.control.Spinner
        InterpolationMethodDropDown  matlab.ui.control.DropDown
        DefaultValuesButton          matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        caller_app; % Glavna aplikacija
        dws = [337,330]; % Podrazumijevana velicina prozora
        btn_timer; % Promjena boje teksta ApplyButton-a radi
                   % vizuelnog efekta
    end
    
    methods (Access = private)
        
        function timer_func(app, obj, event, btn)
            btn.FontColor = 'black';
        end
        
        function timer_stop_func(app, obj, event)
            delete(obj);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mapp)
            app.caller_app = mapp;
            
            app.OVRDSpinner.Value = app.caller_app.robotcontrol_params.ovrd;
            app.JOVRDSpinner.Value = app.caller_app.robotcontrol_params.jovrd;
            app.SPDSpinner.Value = app.caller_app.robotcontrol_params.spd;
            app.ACCELSpinner.Value = app.caller_app.robotcontrol_params.accel;
            app.DECELSpinner.Value = app.caller_app.robotcontrol_params.decel;
            app.InterpolationMethodDropDown.Value = app.caller_app.robotcontrol_params.interpolation_cmd;
        end

        % Size changed function: RobotControlParamsUIFigure
        function RobotControlParamsUIFigureSizeChanged(app, event)
            cws = app.RobotControlParamsUIFigure.Position(3:4);
            if(cws(1) > app.dws(1))
                app.RobotControlParamsUIFigure.Position(3) = app.dws(1);
            end
            if(cws(2) > app.dws(2))
                app.RobotControlParamsUIFigure.Position(4) = app.dws(2);
            end
        end

        % Value changed function: OVRDSpinner
        function OVRDSpinnerValueChanged(app, event)
            value = app.OVRDSpinner.Value;
            app.OVRDSpinner.Value = round(value,1);
        end

        % Value changed function: JOVRDSpinner
        function JOVRDSpinnerValueChanged(app, event)
            value = app.JOVRDSpinner.Value;
            app.JOVRDSpinner.Value = round(value,1);
        end

        % Value changed function: SPDSpinner
        function SPDSpinnerValueChanged(app, event)
            value = app.SPDSpinner.Value;
            app.SPDSpinner.Value = round(value,4);
        end

        % Close request function: RobotControlParamsUIFigure
        function RobotControlParamsUIFigureCloseRequest(app, event)
            app.caller_app.RobotControlParamsButton.Enable = true;
            delete(app)
        end

        % Window button motion function: RobotControlParamsUIFigure
        function RobotControlParamsUIFigureWindowButtonMotion(app, event)
            cp = event.Source.CurrentPoint;
            if cp(1) >= 31 && cp(1) <= 170 && cp(2) >= 286 && cp(2) <= 308
                app.OVRDLabel.FontColor = [0.93,0.69,0.13];
            else
                app.OVRDLabel.FontColor = 'black';
                if cp(1) >= 27 && cp(1) <= 173 && cp(2) >= 246 && cp(2) <= 268
                    app.JOVRDLabel.FontColor = [0.93,0.69,0.13];
                else
                    app.JOVRDLabel.FontColor = 'black';
                    if cp(1) >= 55 && cp(1) <= 145 && cp(2) >= 206 && cp(2) <= 228
                        app.SPDLabel.FontColor = [0.93,0.69,0.13];
                    else
                        app.SPDLabel.FontColor = 'black';
                        if cp(1) >= 37 && cp(1) <= 163 && cp(2) >= 166 && cp(2) <= 188
                            app.ACCELLabel.FontColor = [0.93,0.69,0.13];
                        else
                            app.ACCELLabel.FontColor = 'black';
                            if cp(1) >= 34 && cp(1) <= 167 && cp(2) >= 126 && cp(2) <= 148
                                app.DECELLabel.FontColor = [0.93,0.69,0.13];
                            else
                                app.DECELLabel.FontColor = 'black';
                                if cp(1) >= 59 && cp(1) <= 143 && cp(2) >= 86 && cp(2) <= 108
                                    app.InterpolationMethodLabel.FontColor = [0.93,0.69,0.13];
                                else
                                    app.InterpolationMethodLabel.FontColor = 'black';
                                end
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: DefaultValuesButton
        function DefaultValuesButtonPushed(app, event)
            app.OVRDSpinner.Value = 100;
            app.JOVRDSpinner.Value = 100;
            app.SPDSpinner.Value = 100;
            app.ACCELSpinner.Value = 2;
            app.DECELSpinner.Value = 2;
            app.InterpolationMethodDropDown.Value = 'MVS';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ApplyButton.FontColor = [0.9 0.7 0.0];
            app.RobotControlParamsUIFigure.Pointer = 'watch';
            
            app.caller_app.robotcontrol_params.ovrd = app.OVRDSpinner.Value;
            app.caller_app.robotcontrol_params.jovrd = app.JOVRDSpinner.Value;
            app.caller_app.robotcontrol_params.spd = app.SPDSpinner.Value;
            app.caller_app.robotcontrol_params.accel = app.ACCELSpinner.Value;
            app.caller_app.robotcontrol_params.decel = app.DECELSpinner.Value;
            app.caller_app.robotcontrol_params.interpolation_cmd = app.InterpolationMethodDropDown.Value;
            
            app.ApplyButton.FontColor = [0.4 0.7 0.1];
            app.RobotControlParamsUIFigure.Pointer = 'arrow';
            
            app.btn_timer = timer('StartDelay',1, 'ExecutionMode','singleShot');
            app.btn_timer.TimerFcn = {@app.timer_func, app.ApplyButton};
            app.btn_timer.StopFcn = @app.timer_stop_func;
            start(app.btn_timer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RobotControlParamsUIFigure = uifigure('Visible', 'off');
            app.RobotControlParamsUIFigure.Name = 'Parametri upravljanja robotom';
            app.RobotControlParamsUIFigure.CloseRequestFcn = createCallbackFcn(app, @RobotControlParamsUIFigureCloseRequest, true);
            app.RobotControlParamsUIFigure.SizeChangedFcn = createCallbackFcn(app, @RobotControlParamsUIFigureSizeChanged, true);
            app.RobotControlParamsUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @RobotControlParamsUIFigureWindowButtonMotion, true);
            app.OVRDLabel = uilabel(app.RobotControlParamsUIFigure);
            app.OVRDLabel.Tooltip = {'Ograničenje brzine za sve metode interpolacije'};
            app.OVRDLabel.Text = 'Ograničenje (OVRD)';
            app.JOVRDLabel = uilabel(app.RobotControlParamsUIFigure);
            app.JOVRDLabel.Tooltip = {'Ograničenje brzine za interpolaciju u unutrašnjim koordinatama'};
            app.JOVRDLabel.Text = 'Ograničenje (JOVRD)';
            app.SPDLabel = uilabel(app.RobotControlParamsUIFigure);
            app.SPDLabel.Tooltip = {'Apsolutna brzina za pravolinijsku i kružnu interpolaciju'};
            app.SPDLabel.Text = 'Brzina (SPD)';
            app.ACCELLabel = uilabel(app.RobotControlParamsUIFigure);
            app.ACCELLabel.Tooltip = {'Postotak maksimalnog ubrzanja'};
            app.ACCELLabel.Text = 'Ubrzanje (ACCEL)';
            app.DECELLabel = uilabel(app.RobotControlParamsUIFigure);
            app.DECELLabel.Tooltip = {'Postotak maksimalnog usporenja'};
            app.DECELLabel.Text = 'Usporenje (DECEL)';
            app.InterpolationMethodLabel = uilabel(app.RobotControlParamsUIFigure);
            app.InterpolationMethodLabel.Tooltip = {'MOV - interpolacija u unutrašnjim koordinatama'; 'MVS - pravolinijska prostorna interpolacija'};
            app.InterpolationMethodLabel.Text = 'Interpolacija';
            app.OVRDSpinner = uispinner(app.RobotControlParamsUIFigure);
            app.OVRDSpinner.Step = 0.1;
            app.OVRDSpinner.Limits = [0 100];
            app.OVRDSpinner.ValueDisplayFormat = '%.3g %%';
            app.OVRDSpinner.ValueChangedFcn = createCallbackFcn(app, @OVRDSpinnerValueChanged, true);
            app.OVRDSpinner.Tooltip = {''};
            app.OVRDSpinner.Value = 100;
            app.JOVRDSpinner = uispinner(app.RobotControlParamsUIFigure);
            app.JOVRDSpinner.Step = 0.1;
            app.JOVRDSpinner.Limits = [0 100];
            app.JOVRDSpinner.ValueDisplayFormat = '%.3g %%';
            app.JOVRDSpinner.ValueChangedFcn = createCallbackFcn(app, @JOVRDSpinnerValueChanged, true);
            app.JOVRDSpinner.Value = 100;
            app.SPDSpinner = uispinner(app.RobotControlParamsUIFigure);
            app.SPDSpinner.Limits = [0 Inf];
            app.SPDSpinner.ValueDisplayFormat = '%.10g mm/s';
            app.SPDSpinner.ValueChangedFcn = createCallbackFcn(app, @SPDSpinnerValueChanged, true);
            app.SPDSpinner.Tooltip = {''};
            app.SPDSpinner.Value = 100;
            app.ACCELSpinner = uispinner(app.RobotControlParamsUIFigure);
            app.ACCELSpinner.Limits = [0 100];
            app.ACCELSpinner.ValueDisplayFormat = '%.0f %%';
            app.ACCELSpinner.Value = 2;
            app.DECELSpinner = uispinner(app.RobotControlParamsUIFigure);
            app.DECELSpinner.Limits = [0 100];
            app.DECELSpinner.ValueDisplayFormat = '%.0f %%';
            app.DECELSpinner.Value = 2;
            app.InterpolationMethodDropDown = uidropdown(app.RobotControlParamsUIFigure);
            app.InterpolationMethodDropDown.Items = {'MOV', 'MVS'};
            app.InterpolationMethodDropDown.Tooltip = {''};
            app.InterpolationMethodDropDown.Value = 'MVS';
            app.DefaultValuesButton = uibutton(app.RobotControlParamsUIFigure, 'push');
            app.DefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultValuesButtonPushed, true);
            app.DefaultValuesButton.Text = {'Podrazumijevane'; 'vrijednosti'};
            app.ApplyButton = uibutton(app.RobotControlParamsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Primijeni';
            % Show the figure after all components are created
            app.RobotControlParamsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotcontrol_params(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RobotControlParamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RobotControlParamsUIFigure)
        end
    end
end