classdef workspace_params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WorkspaceParamsUIFigure      matlab.ui.Figure
        UnitsInfoLabel               matlab.ui.control.Label
        DefaultValuesButton          matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        UpperLeftBorderPointLabel    matlab.ui.control.Label
        GxLabel                      matlab.ui.control.Label
        GxEditField                  matlab.ui.control.EditField
        GyLabel                      matlab.ui.control.Label
        GyEditField                  matlab.ui.control.EditField
        GzLabel                      matlab.ui.control.Label
        GzEditField                  matlab.ui.control.EditField
        WritingSurfaceSizeLabel      matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        xEditField                   matlab.ui.control.EditField
        yLabel                       matlab.ui.control.Label
        yEditField                   matlab.ui.control.EditField
        HeightLabel                  matlab.ui.control.Label
        WidthLabel                   matlab.ui.control.Label
        CheckWSDimensionsButton      matlab.ui.control.Button
        CheckWSDimensions1Lamp       matlab.ui.control.Lamp
        CheckWSDimensions2Lamp       matlab.ui.control.Lamp
        CheckWSDimensions3Lamp       matlab.ui.control.Lamp
        CheckWSDimensions4Lamp       matlab.ui.control.Lamp
        MarginsLabel                 matlab.ui.control.Label
        aLabel                       matlab.ui.control.Label
        aEditField                   matlab.ui.control.EditField
        bLabel                       matlab.ui.control.Label
        bEditField                   matlab.ui.control.EditField
        cLabel                       matlab.ui.control.Label
        cEditField                   matlab.ui.control.EditField
        dLabel                       matlab.ui.control.Label
        dEditField                   matlab.ui.control.EditField
        MarginsAutomaticValuesLabel  matlab.ui.control.Label
        aSwitch                      matlab.ui.control.Switch
        bSwitch                      matlab.ui.control.Switch
        cSwitch                      matlab.ui.control.Switch
        dSwitch                      matlab.ui.control.Switch
        StartingFeedsLabel           matlab.ui.control.Label
        hLabel                       matlab.ui.control.Label
        hEditField                   matlab.ui.control.EditField
        vLabel                       matlab.ui.control.Label
        vEditField                   matlab.ui.control.EditField
        ToolSafeDistanceFromWritingSurfacePanel  matlab.ui.container.Panel
        ToolSafeDistanceFromWritingSurfaceLabel  matlab.ui.control.Label
        ToolSafeDistanceFromWSEditField  matlab.ui.control.EditField
        WritingSurfaceImage          matlab.ui.control.Image
        CloseImagePreviewButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        caller_app; % Glavna aplikacija
        dws = [685,445]; % Podrazumijevana velicina prozora
        dws2 = [685,445,685,445];
        workspace_params_temp; % Trenutni parametri radnog prostora
        im_enl = false; % Da li je povecana slika
        vals_ok;  % Da li su korektne sve vrijednosti parametara
        ab_timer; % Promjena boje teksta ApplyButton-a u zavisnosti
                  % da li je operacija uspjesno izvrsena
        ws_size_checked = true; % Da li je izvrsena provjera velicine
                                % povrsine za pisanje
    end
    
    methods (Access = private)
        
        function timer_func(app, obj, event, btn)
            btn.FontColor = 'black';
        end
        
        function timer_stop_func(app, obj, event)
            delete(obj);
        end
        
        function check = isNumValueValid(app, val)
            mval = erase(val,{'pi','sin'});
            check = ~contains(mval,{'%',',','i'});
            try
                mval = eval(val);
            catch
                check = false;
            end
        end
        
        function updateEFState(app,check,ef)
            if check
                ef.BackgroundColor = 'white';
                app.vals_ok(str2double(ef.Tag)) = true;
            else
                ef.BackgroundColor = [1 0.7 0.7];
                app.vals_ok(str2double(ef.Tag)) = false;
            end
        end
        
        function q = genric(app, n, qlim)
            q = zeros(1,n);
            for i = 1 : n
                q(i) = rand*(qlim(i,2)-qlim(i,1)) + qlim(1);
            end
        end
        
        function check = checkWSDimensions(app)
            app.resetLampColors();
            app.ws_size_checked = true;
            if all(app.vals_ok(1:5))
                app.vals_ok(13) = true;
                P(:,:,1:4) = repmat(eye(4),[1 1 4]);
                P(1:3,1:3,1:4) = repmat(app.caller_app.robot_params.tom,[1 1 4]);
                P(1:3,4,1:4) = reshape(P(1:3,4,1:4),[3 4]) + repmat(app.workspace_params_temp.g(1:3,4), [1 4]) + ...
                               [0  app.workspace_params_temp.ws_size(1)                                     0  app.workspace_params_temp.ws_size(1); ...
                                0                                     0  app.workspace_params_temp.ws_size(2)  app.workspace_params_temp.ws_size(2); ...
                                0                                     0                                     0                                     0];
                q0 = app.genric(app.caller_app.robot_params.R.n, app.caller_app.robot_params.R.qlim); % generisanje pocetnog pogotka unutrasnjih koordinata
            	inum = 0; % brojac iteracija
                qfound = false; % pokazatelj da li je pronadjen odgovarajuci vektor unutrasnjih koordinata
            	for k = 1 : size(P,3) % za svaku granicnu tacku
                    switch k
                        case 1
                            app.CheckWSDimensions1Lamp.Color = [1.00,0.90,0.00];
                        case 2
                            app.CheckWSDimensions2Lamp.Color = [1.00,0.90,0.00];
                        case 3
                            app.CheckWSDimensions3Lamp.Color = [1.00,0.90,0.00];
                        case 4
                            app.CheckWSDimensions4Lamp.Color = [1.00,0.90,0.00];
                    end
                    pause(0.5);
                    while ~qfound % dok nije pronadjen odgovarajuci vektor unutrasnjih koordinata
                        qc = app.caller_app.robot_params.R.ikine(P(:,:,k), 'q0', q0, 'tol', app.caller_app.robot_params.rep, 'ilimit', 1000, 'quiet'); % izracunaj unutrasnje koordinate za trenutnu granicnu tacku
                        if(numcols(qc) == 6) % ukoliko su pronadjene odgovarajuce unutrasnje koordinate
                            inum = 0;
                            qfound = true;
                            switch k
                                case 1
                                    app.CheckWSDimensions1Lamp.Color = [0.25,0.90,0.25];
                                case 2
                                    app.CheckWSDimensions2Lamp.Color = [0.25,0.90,0.25];
                                case 3
                                    app.CheckWSDimensions3Lamp.Color = [0.25,0.90,0.25];
                                case 4
                                    app.CheckWSDimensions4Lamp.Color = [0.25,0.90,0.25];
                            end
                        else
                            if(inum > 30) % provjeri da li je broj pokusaja presao granicu, ukoliko jeste, trenutnu poziciju nije moguce dostici
                                switch k
                                    case 1
                                        % Nije moguce postici zeljenu poziciju i orjentaciju u gornjem lijevom uglu radne povrsine
                                        app.CheckWSDimensions1Lamp.Color = [1.00,0.25,0.25];
                                    case 2
                                        % Nije moguce postici zeljenu poziciju i orjentaciju u donjem lijevom uglu radne povrsine
                                        app.CheckWSDimensions2Lamp.Color = [1.00,0.25,0.25];
                                    case 3
                                        % Nije moguce postici zeljenu poziciju i orjentaciju u gornjem desnom uglu radne povrsine
                                        app.CheckWSDimensions3Lamp.Color = [1.00,0.25,0.25];
                                    case 4
                                       % Nije moguce postici zeljenu poziciju i orjentaciju u donjem desnom uglu radne povrsine
                                        app.CheckWSDimensions4Lamp.Color = [1.00,0.25,0.25];
                                end
                                app.vals_ok(13) = false;
                                break;
                            end
                            q0 = app.genric(app.caller_app.robot_params.R.n, app.caller_app.robot_params.R.qlim); % ukoliko nije, generisi novi pocetni pogodak unutrasnjih koordinata i probaj ponovo
                            inum = inum + 1;
                        end
                    end
                    inum = 0;
                    qfound = false;
            	end
            else
                app.vals_ok(13) = false;
            end
            check = app.vals_ok(13);
        end
        
        function resetLampColors(app)
            app.CheckWSDimensions1Lamp.Color = [0.65,0.65,0.65];
            app.CheckWSDimensions2Lamp.Color = [0.65,0.65,0.65];
            app.CheckWSDimensions3Lamp.Color = [0.65,0.65,0.65];
            app.CheckWSDimensions4Lamp.Color = [0.65,0.65,0.65];
        end
        
        function setLampColorsGreen(app)
            app.CheckWSDimensions1Lamp.Color = [0.25,0.90,0.25];
            app.CheckWSDimensions2Lamp.Color = [0.25,0.90,0.25];
            app.CheckWSDimensions3Lamp.Color = [0.25,0.90,0.25];
            app.CheckWSDimensions4Lamp.Color = [0.25,0.90,0.25];
        end
        
        function setGValue(app, ef)
            tag = str2double(ef.Tag);
            if app.vals_ok(tag)
                app.workspace_params_temp.g(tag,4) = eval(ef.Value);
            else
                app.workspace_params_temp.g(tag,4) = NaN;
            end
        end
        
        function setWSSizeValue(app, ef)
            tag = str2double(ef.Tag);
            mtag = tag-3;
            if app.vals_ok(tag)
                app.workspace_params_temp.ws_size(mtag) = eval(ef.Value);
            else
                app.workspace_params_temp.ws_size(mtag) = NaN;
            end
        end
        
        function checkWSDimensionsDifference(app)
            if (~isequal(app.workspace_params_temp.g, app.caller_app.workspace_params.g) || ~isequal(app.workspace_params_temp.ws_size, app.caller_app.workspace_params.ws_size))
                app.vals_ok(13) = false;
                app.ws_size_checked = false;
                app.resetLampColors();
            else
                app.vals_ok(13) = true;
                app.ws_size_checked = true;
                app.setLampColorsGreen();
            end
        end
        
        function createWS(app)
            filepath = fullfile(app.caller_app.robot_params.models_pth,'extras','extra01.STL');
            % app.workspace_params_temp.g(1,4)
            % app.workspace_params_temp.g(2,4)
            % app.workspace_params_temp.g(3,4)
            % app.workspace_params_temp.ws_size(1)
            % app.workspace_params_temp.ws_size(2)
            
            x1 = [app.workspace_params_temp.g(1,4);
                  app.workspace_params_temp.g(1,4)+app.workspace_params_temp.ws_size(1);
                  app.workspace_params_temp.g(1,4)+app.workspace_params_temp.ws_size(1);
                  app.workspace_params_temp.g(1,4)];
            y1 = [app.workspace_params_temp.g(2,4);
                  app.workspace_params_temp.g(2,4);
                  app.workspace_params_temp.g(2,4)+app.workspace_params_temp.ws_size(2);
                  app.workspace_params_temp.g(2,4)+app.workspace_params_temp.ws_size(2)];
            z1 = zeros(4,1) + app.workspace_params_temp.g(3,4);
            points = [x1 y1 z1];
            faces = [1 2 3;
                     3 4 1];
            
            app.caller_app.robot_params.R.points{app.caller_app.robot_params.R.n+2} = points;
            app.caller_app.robot_params.R.faces{app.caller_app.robot_params.R.n+2} = faces;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mapp)
            app.caller_app = mapp;
            
            app.workspace_params_temp = app.caller_app.workspace_params;
            
            app.GxEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.g(1,4),'%.9f'),'right','0'),'right','.');
            app.GyEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.g(2,4),'%.9f'),'right','0'),'right','.');
            app.GzEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.g(3,4),'%.9f'),'right','0'),'right','.');
            app.xEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.ws_size(1),'%.9f'),'right','0'),'right','.');
            app.yEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.ws_size(2),'%.9f'),'right','0'),'right','.');
            app.aEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.hlm,'%.9f'),'right','0'),'right','.');
            app.bEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.hrm,'%.9f'),'right','0'),'right','.');
            app.cEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.vtm,'%.9f'),'right','0'),'right','.');
            app.dEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.vbm,'%.9f'),'right','0'),'right','.');
            if app.caller_app.workspace_params.hlm_sw
                app.aSwitch.Value = 'Da';
                app.aEditField.Editable = false;
            else
                app.aSwitch.Value = 'Ne';
                app.aEditField.Editable = true;
            end
            if app.caller_app.workspace_params.hrm_sw
                app.bSwitch.Value = 'Da';
                app.bEditField.Editable = false;
            else
                app.bSwitch.Value = 'Ne';
                app.bEditField.Editable = true;
            end
            if app.caller_app.workspace_params.vtm_sw
                app.cSwitch.Value = 'Da';
                app.cEditField.Editable = false;
            else
                app.cSwitch.Value = 'Ne';
                app.cEditField.Editable = true;
            end
            if app.caller_app.workspace_params.vbm_sw
                app.dSwitch.Value = 'Da';
                app.dEditField.Editable = false;
            else
                app.dSwitch.Value = 'Ne';
                app.dEditField.Editable = true;
            end
            app.hEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.hf0-app.caller_app.workspace_params.hlm,'%.9f'),'right','0'),'right','.');
            app.vEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.vf0-app.caller_app.workspace_params.vtm-app.caller_app.tl_params.h,'%.9f'),'right','0'),'right','.');
            app.ToolSafeDistanceFromWSEditField.Value = strip(strip(num2str(app.caller_app.workspace_params.d,'%.9f'),'right','0'),'right','.');
            
            app.vals_ok(1:13) = true;
        end

        % Close request function: WorkspaceParamsUIFigure
        function WorkspaceParamsUIFigureCloseRequest(app, event)
            app.caller_app.WorkspaceParamsButton.Enable = true;
            delete(app)
        end

        % Size changed function: WorkspaceParamsUIFigure
        function WorkspaceParamsUIFigureSizeChanged(app, event)
            cws = app.WorkspaceParamsUIFigure.Position(3:4);
            if cws(1) < app.dws(1)
                cws(1) = app.dws(1);
            end
            if cws(2) < app.dws(2)
                cws(2) = app.dws(2);
            end
            cws2 = [cws,cws];
            
            app.UnitsInfoLabel.Position(1:2) = [20,413]./app.dws.*cws;
            app.UnitsInfoLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.DefaultValuesButton.Position = [20,20,142,52]./app.dws2.*cws2;
            app.ApplyButton.Position = [178,20,142,52]./app.dws2.*cws2;
            app.UpperLeftBorderPointLabel.Position(1:2) = [345,405]./app.dws.*cws;
            app.UpperLeftBorderPointLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.GxLabel.Position(1:2) = [345,375]./app.dws.*cws;
            app.GxLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.GxEditField.Position(1:2:end) = [sum(app.GxLabel.Position(1:2:end)),(67+25)/app.dws(1)*cws(1)-25];
            app.GxEditField.Position(2:2:end) = [375,26]/app.dws(2)*cws(2);
            app.GyLabel.Position(1:2) = [441,375]./app.dws.*cws;
            app.GyLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.GyEditField.Position(1:2:end) = [sum(app.GyLabel.Position(1:2:end)),(67+25)/app.dws(1)*cws(1)-25];
            app.GyEditField.Position(2:2:end) = [375,26]/app.dws(2)*cws(2);
            app.GzLabel.Position(1:2) = [537,375]./app.dws.*cws;
            app.GzLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.GzEditField.Position(1:2:end) = [sum(app.GzLabel.Position(1:2:end)),(67+25)/app.dws(1)*cws(1)-25];
            app.GzEditField.Position(2:2:end) = [375,26]/app.dws(2)*cws(2);
            app.WritingSurfaceSizeLabel.Position(1:2) = [345,339]./app.dws.*cws;
            app.WritingSurfaceSizeLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.xLabel.Position(1:2) = [345,309]./app.dws.*cws;
            app.xLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.xEditField.Position(1:2:end) = [sum(app.xLabel.Position(1:2:end)),(67+25)/app.dws(1)*cws(1)-25];
            app.xEditField.Position(2:2:end) = [309,26]/app.dws(2)*cws(2);
            app.HeightLabel.Position(1:2:end) = app.xEditField.Position(1:2:end);
            app.HeightLabel.Position(2:2:end) = [287,22]/app.dws(2)*cws(2);
            app.yLabel.Position(1:2) = [441,311]./app.dws.*cws;
            app.yLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.yEditField.Position(1:2:end) = [sum(app.yLabel.Position(1:2:end)),(67+25)/app.dws(1)*cws(1)-25];
            app.yEditField.Position(2:2:end) = [311,26]/app.dws(2)*cws(2);
            app.WidthLabel.Position(1:2:end) = app.yEditField.Position(1:2:end);
            app.WidthLabel.Position(2:2:end) = [287,22]/app.dws(2)*cws(2);
            app.CheckWSDimensionsButton.Position = [568,309,75,40]./app.dws2.*cws2;
            app.CheckWSDimensions1Lamp.Position(3:4) = min(20/app.dws(1)*cws(1),20/app.dws(2)*cws(2));
            app.CheckWSDimensions1Lamp.Position(1) = app.CheckWSDimensionsButton.Position(1)-2/app.dws(1)*cws(1)-app.CheckWSDimensions1Lamp.Position(4);
            app.CheckWSDimensions1Lamp.Position(2) = 329/app.dws(2)*cws(2);
            app.CheckWSDimensions2Lamp.Position(3:4) = min(20/app.dws(1)*cws(1),20/app.dws(2)*cws(2));
            app.CheckWSDimensions2Lamp.Position(1) = app.CheckWSDimensionsButton.Position(1)-2/app.dws(1)*cws(1)-app.CheckWSDimensions2Lamp.Position(4);
            app.CheckWSDimensions2Lamp.Position(2) = 309/app.dws(2)*cws(2);
            app.CheckWSDimensions3Lamp.Position(3:4) = min(20/app.dws(1)*cws(1),20/app.dws(2)*cws(2));
            app.CheckWSDimensions3Lamp.Position(1) = app.CheckWSDimensionsButton.Position(1)+app.CheckWSDimensionsButton.Position(3)+2/app.dws(1)*cws(1);
            app.CheckWSDimensions3Lamp.Position(2) = 329/app.dws(2)*cws(2);
            app.CheckWSDimensions4Lamp.Position(3:4) = min(20/app.dws(1)*cws(1),20/app.dws(2)*cws(2));
            app.CheckWSDimensions4Lamp.Position(1) = app.CheckWSDimensionsButton.Position(1)+app.CheckWSDimensionsButton.Position(3)+2/app.dws(1)*cws(1);
            app.CheckWSDimensions4Lamp.Position(2) = 309/app.dws(2)*cws(2);
            app.MarginsLabel.Position(1:2) = [345,250]./app.dws.*cws;
            app.MarginsLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.aLabel.Position(1:2) = [355,222]./app.dws.*cws;
            app.aLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.aEditField.Position(1:2:end) = [sum(app.aLabel.Position(1:2:end)),(80+90)/app.dws(1)*cws(1)-90];
            app.aEditField.Position(2:2:end) = [222,26]/app.dws(2)*cws(2);
            app.bLabel.Position(1:2) = [355,196]./app.dws.*cws;
            app.bLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.bEditField.Position(1:2:end) = [sum(app.bLabel.Position(1:2:end)),(80+90)/app.dws(1)*cws(1)-90];
            app.bEditField.Position(2:2:end) = [196,26]/app.dws(2)*cws(2);
            app.cLabel.Position(1:2) = [355,170]./app.dws.*cws;
            app.cLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.cEditField.Position(1:2:end) = [sum(app.cLabel.Position(1:2:end)),(80+90)/app.dws(1)*cws(1)-90];
            app.cEditField.Position(2:2:end) = [170,26]/app.dws(2)*cws(2);
            app.dLabel.Position(1:2) = [355,144]./app.dws.*cws;
            app.dLabel.Position(4) = 26/app.dws(2)*cws(2);
            app.dEditField.Position(1:2:end) = [sum(app.dLabel.Position(1:2:end)),(80+90)/app.dws(1)*cws(1)-90];
            app.dEditField.Position(2:2:end) = [144,26]/app.dws(2)*cws(2);
            app.MarginsAutomaticValuesLabel.Position(1:2) = [504,252]./app.dws.*cws;
            app.MarginsAutomaticValuesLabel.Position(3) = 165+(165/app.dws(1)*cws(1)-165)/1.2;
            app.MarginsAutomaticValuesLabel.Position(4) = 28/app.dws(2)*cws(2);
            app.aSwitch.Position(1:2) = [560,222]./app.dws.*cws;
            app.aSwitch.Position(3:4) = round(26/app.dws(2)*cws(2))*[2,1];
            app.bSwitch.Position(1:2) = [560,196]./app.dws.*cws;
            app.bSwitch.Position(3:4) = round(26/app.dws(2)*cws(2))*[2,1];
            app.cSwitch.Position(1:2) = [560,170]./app.dws.*cws;
            app.cSwitch.Position(3:4) = round(26/app.dws(2)*cws(2))*[2,1];
            app.dSwitch.Position(1:2) = [560,144]./app.dws.*cws;
            app.dSwitch.Position(3:4) = round(26/app.dws(2)*cws(2))*[2,1];
            app.StartingFeedsLabel.Position(1:2) = [345,108]./app.dws.*cws;
            app.StartingFeedsLabel.Position(4) = 22/app.dws(2)*cws(2);
            app.hLabel.Position(1:2:end) = [sum(app.StartingFeedsLabel.Position(1:2:end))+10,90/app.dws(1)*cws(1)];
            app.hLabel.Position(2:2:end) = [108,22]/app.dws(2)*cws(2);
            app.hEditField.Position(1:2:end) = app.hLabel.Position(1:2:end);
            app.hEditField.Position(2:2:end) = [80,26]/app.dws(2)*cws(2);
%             app.hEditField.Position(1:2:end) = [sum(app.hLabel.Position(1:2:end)),(80+90)/app.dws(1)*cws(1)-90];
%             app.hEditField.Position(2:2:end) = [106,26]/app.dws(2)*cws(2);
            app.vLabel.Position(1:2:end) = [sum(app.hLabel.Position(1:2:end))+10,90/app.dws(1)*cws(1)];
            app.vLabel.Position(2:2:end) = [108,22]/app.dws(2)*cws(2);
            app.vEditField.Position(1:2:end) = app.vLabel.Position(1:2:end);
            app.vEditField.Position(2:2:end) = [80,26]/app.dws(2)*cws(2);
%             app.vEditField.Position(1:2:end) = [sum(app.vLabel.Position(1:2:end)),(80+90)/app.dws(1)*cws(1)-90];
%             app.vEditField.Position(2:2:end) = [80,26]/app.dws(2)*cws(2);
            app.ToolSafeDistanceFromWritingSurfacePanel.Position = [345,20,320,46]./app.dws2.*cws2;
            app.ToolSafeDistanceFromWritingSurfaceLabel.Position(1:2) = [10,7]./app.dws.*cws;
            app.ToolSafeDistanceFromWritingSurfaceLabel.Position(4) = 33/app.dws(2)*cws(2);
            app.ToolSafeDistanceFromWSEditField.Position(1:2:end) = [sum(app.ToolSafeDistanceFromWritingSurfaceLabel.Position(1:2:end)),(135+165)/app.dws(1)*cws(1)-165];
            app.ToolSafeDistanceFromWSEditField.Position(2:2:end) = [10,26]/app.dws(2)*cws(2);
            if app.im_enl
                app.WritingSurfaceImage.Position = [1,1,cws];
            else
                app.WritingSurfaceImage.Position = [20,103,300,300]./app.dws2.*cws2;
            end
            app.CloseImagePreviewButton.Position(1:2) = [635,395]./app.dws.*cws;
        end

        % Image clicked function: WritingSurfaceImage
        function WritingSurfaceImageClicked(app, event)
            if ~app.im_enl
                app.im_enl = true;
                cws = app.WorkspaceParamsUIFigure.Position(3:4);
                if cws(1) < app.dws(1)
                    cws(1) = app.dws(1);
                end
                if cws(2) < app.dws(2)
                   cws(2) = app.dws(2);
                end
                app.WritingSurfaceImage.Position(1:2) = [1,1];
                app.WritingSurfaceImage.Position(3) = cws(1);
                app.WritingSurfaceImage.Position(4) = cws(2);
                app.CloseImagePreviewButton.Visible = true;
            end
        end

        % Button pushed function: CloseImagePreviewButton
        function CloseImagePreviewButtonPushed(app, event)
            cws = app.WorkspaceParamsUIFigure.Position(3:4);
            if cws(1) < app.dws(1)
                cws(1) = app.dws(1);
            end
            if cws(2) < app.dws(2)
               cws(2) = app.dws(2);
            end
            cws2 = [cws,cws];
            app.im_enl = false;
            app.WritingSurfaceImage.Position = [20,103,300,300]./app.dws2.*cws2;
            app.CloseImagePreviewButton.Visible = false;
        end

        % Button pushed function: DefaultValuesButton
        function DefaultValuesButtonPushed(app, event)
            app.GxEditField.Value = '-0.075';
            app.GyEditField.Value = '0.21';
            app.GzEditField.Value = '0.007';
            app.xEditField.Value = '0.297';
            app.yEditField.Value = '0.21';
            app.aEditField.Editable = false;
            app.bEditField.Editable = false;
            app.cEditField.Editable = false;
            app.dEditField.Editable = false;
            app.aEditField.Value = '0.00967619';
            app.bEditField.Value = '0.00967619';
            app.cEditField.Value = '0.00967619';
            app.dEditField.Value = '0.00967619';
            app.hEditField.Value = '0';
            app.vEditField.Value = '0';
            app.aSwitch.Value = 'Da';
            app.bSwitch.Value = 'Da';
            app.cSwitch.Value = 'Da';
            app.dSwitch.Value = 'Da';
            app.ToolSafeDistanceFromWSEditField.Value = '0.01';
        end

        % Value changed function: aSwitch
        function aSwitchValueChanged(app, event)
            value = app.aSwitch.Value;
            if value == "Da"
                app.aEditField.Editable = false;
                app.workspace_params_temp.hf0 = app.workspace_params_temp.hf0 - app.workspace_params_temp.hlm;
                app.workspace_params_temp.hlm = app.caller_app.tl_params.sp;
                app.workspace_params_temp.hf0 = app.workspace_params_temp.hf0 + app.workspace_params_temp.hlm;
                app.aEditField.Value = strip(strip(num2str(app.workspace_params_temp.hlm,'%.9f'),'right','0'),'right','.');
                app.workspace_params_temp.hlm_sw = true;
            else
                app.aEditField.Editable = true;
                app.workspace_params_temp.hlm_sw = false;
            end
        end

        % Value changed function: bSwitch
        function bSwitchValueChanged(app, event)
            value = app.bSwitch.Value;
            if value == "Da"
                app.bEditField.Editable = false;
                app.workspace_params_temp.hrm = app.caller_app.tl_params.sp;
                app.bEditField.Value = strip(strip(num2str(app.workspace_params_temp.hrm,'%.9f'),'right','0'),'right','.');
                app.workspace_params_temp.hrm_sw = true;
            else
                app.bEditField.Editable = true;
                app.workspace_params_temp.hrm_sw = false;
            end
        end

        % Value changed function: cSwitch
        function cSwitchValueChanged(app, event)
            value = app.cSwitch.Value;
            if value == "Da"
                app.cEditField.Editable = false;
                app.workspace_params_temp.vf0 = app.workspace_params_temp.vf0 - app.workspace_params_temp.vtm;
                app.workspace_params_temp.vtm = app.caller_app.tl_params.sp;
                app.workspace_params_temp.vf0 = app.workspace_params_temp.vf0 + app.workspace_params_temp.vtm;
                app.cEditField.Value = strip(strip(num2str(app.workspace_params_temp.vtm,'%.9f'),'right','0'),'right','.');
                app.workspace_params_temp.vtm_sw = true;
            else
                app.cEditField.Editable = true;
                app.workspace_params_temp.vtm_sw = false;
            end
        end

        % Value changed function: dSwitch
        function dSwitchValueChanged(app, event)
            value = app.dSwitch.Value;
            if value == "Da"
                app.dEditField.Editable = false;
                app.workspace_params_temp.vbm = app.caller_app.tl_params.sp;
                app.dEditField.Value = strip(strip(num2str(app.workspace_params_temp.vbm,'%.9f'),'right','0'),'right','.');
                app.workspace_params_temp.vbm_sw = true;
            else
                app.dEditField.Editable = true;
                app.workspace_params_temp.vbm_sw = false;
            end
        end

        % Button pushed function: CheckWSDimensionsButton
        function CheckWSDimensionsButtonPushed(app, event)
            app.WorkspaceParamsUIFigure.Pointer = 'watch';
            app.checkWSDimensions();
            app.WorkspaceParamsUIFigure.Pointer = 'arrow';
        end

        % Value changing function: ToolSafeDistanceFromWSEditField
        function ToolSafeDistanceFromWSEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.ToolSafeDistanceFromWSEditField);
        end

        % Value changing function: GxEditField
        function GxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.GxEditField);
        end

        % Value changing function: GyEditField
        function GyEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.GyEditField);
        end

        % Value changing function: GzEditField
        function GzEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.GzEditField);
        end

        % Value changing function: xEditField
        function xEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.xEditField);
        end

        % Value changing function: yEditField
        function yEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.yEditField);
        end

        % Value changing function: aEditField
        function aEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.aEditField);
        end

        % Value changing function: bEditField
        function bEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.bEditField);
        end

        % Value changing function: cEditField
        function cEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.cEditField);
        end

        % Value changing function: dEditField
        function dEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.dEditField);
        end

        % Value changing function: hEditField
        function hEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.hEditField);
        end

        % Value changing function: vEditField
        function vEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.vEditField);
        end

        % Callback function
        function CxEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.CxEditField);
        end

        % Callback function
        function CyEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.CyEditField);
        end

        % Callback function
        function CzEditFieldValueChanging(app, event)
            app.updateEFState(app.isNumValueValid(event.Value), app.CzEditField);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if all(app.vals_ok(1:12))
                if(app.vals_ok(13))
                    app.ApplyButton.FontColor = [0.9 0.7 0.0];
                    app.WorkspaceParamsUIFigure.Pointer = 'watch';
                    pause(0.2);
                    app.caller_app.workspace_params = app.workspace_params_temp;
                    app.createWS();
                    app.ApplyButton.FontColor = [0.4 0.7 0.1];
                    app.WorkspaceParamsUIFigure.Pointer = 'arrow';
                elseif ~app.ws_size_checked
                    app.ApplyButton.FontColor = [0.9 0.7 0.0];
                    app.WorkspaceParamsUIFigure.Pointer = 'watch';
                    check = app.checkWSDimensions();
                    if check
                        app.caller_app.workspace_params = app.workspace_params_temp;
                        app.createWS();
                        app.ApplyButton.FontColor = [0.4 0.7 0.1];
                    else
                        app.ApplyButton.FontColor = [0.9,0.3,0.3];
                    end
                    app.WorkspaceParamsUIFigure.Pointer = 'arrow';
                else
                    app.ApplyButton.FontColor = [0.9,0.3,0.3];
                end
            else
                app.ApplyButton.FontColor = [0.9,0.3,0.3];
            end
            app.ab_timer = timer('StartDelay',1, 'ExecutionMode','singleShot');
            app.ab_timer.TimerFcn = {@app.timer_func, app.ApplyButton};
            app.ab_timer.StopFcn = @app.timer_stop_func;
            start(app.ab_timer);
        end

        % Value changed function: GxEditField
        function GxEditFieldValueChanged(app, event)
            app.setGValue(app.GxEditField);
            app.checkWSDimensionsDifference();
        end

        % Value changed function: GyEditField
        function GyEditFieldValueChanged(app, event)
            app.setGValue(app.GyEditField);
            app.checkWSDimensionsDifference();
        end

        % Value changed function: GzEditField
        function GzEditFieldValueChanged(app, event)
            app.setGValue(app.GzEditField);
            app.checkWSDimensionsDifference();
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            app.setWSSizeValue(app.xEditField);
            app.checkWSDimensionsDifference();
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.setWSSizeValue(app.yEditField);
            app.checkWSDimensionsDifference();
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            if app.vals_ok(str2double(app.aEditField.Tag))
                app.workspace_params_temp.hf0 = app.workspace_params_temp.hf0 - app.workspace_params_temp.hlm;
                app.workspace_params_temp.hlm = eval(app.aEditField.Value);
                app.workspace_params_temp.hf0 = app.workspace_params_temp.hf0 + app.workspace_params_temp.hlm;
            else
                app.workspace_params_temp.hlm = NaN;
                app.workspace_params_temp.hf0 = NaN;
            end
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            if app.vals_ok(str2double(app.bEditField.Tag))
                app.workspace_params_temp.hrm = eval(app.bEditField.Value);
            else
                app.workspace_params_temp.hrm = NaN;
            end
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            if app.vals_ok(str2double(app.cEditField.Tag))
                app.workspace_params_temp.vf0 = app.workspace_params_temp.vf0 - app.workspace_params_temp.vtm;
                app.workspace_params_temp.vtm = eval(app.cEditField.Value);
                app.workspace_params_temp.vf0 = app.workspace_params_temp.vf0 + app.workspace_params_temp.vtm;
            else
                app.workspace_params_temp.vtm = NaN;
                app.workspace_params_temp.vf0 = NaN;
            end
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            if app.vals_ok(str2double(app.dEditField.Tag))
                app.workspace_params_temp.vbm = eval(app.dEditField.Value);
            else
                app.workspace_params_temp.vbm = NaN;
            end
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            if app.vals_ok(str2double(app.hEditField.Tag))
                app.workspace_params_temp.hf0 = eval(app.hEditField.Value) + app.workspace_params_temp.hlm;
            else
                app.workspace_params_temp.hf0 = NaN;
            end
        end

        % Value changed function: vEditField
        function vEditFieldValueChanged(app, event)
            if app.vals_ok(str2double(app.vEditField.Tag))
                app.workspace_params_temp.vf0 = eval(app.vEditField.Value) + app.workspace_params_temp.vtm + app.caller_app.tl_params.h;
            else
                app.workspace_params_temp.vf0 = NaN;
            end
        end

        % Value changed function: ToolSafeDistanceFromWSEditField
        function ToolSafeDistanceFromWSEditFieldValueChanged(app, event)
            if app.vals_ok(str2double(app.ToolSafeDistanceFromWSEditField.Tag))
                app.workspace_params_temp.d = eval(app.ToolSafeDistanceFromWSEditField.Value);
            else
                app.workspace_params_temp.d = NaN;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WorkspaceParamsUIFigure = uifigure('Visible', 'off');
            app.WorkspaceParamsUIFigure.Name = 'Parametri radnog prostora';
            app.WorkspaceParamsUIFigure.CloseRequestFcn = createCallbackFcn(app, @WorkspaceParamsUIFigureCloseRequest, true);
            app.WorkspaceParamsUIFigure.SizeChangedFcn = createCallbackFcn(app, @WorkspaceParamsUIFigureSizeChanged, true);
            app.UnitsInfoLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.UnitsInfoLabel.Text = '*Jedinica za dužinu je metar.';
            app.DefaultValuesButton = uibutton(app.WorkspaceParamsUIFigure, 'push');
            app.DefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultValuesButtonPushed, true);
            app.DefaultValuesButton.Text = {'Podrazumijevane'; 'vrijednosti'};
            app.ApplyButton = uibutton(app.WorkspaceParamsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Primijeni';
            app.UpperLeftBorderPointLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.UpperLeftBorderPointLabel.Text = 'Gornja lijeva granična tačka površine za pisanje';
            app.GxLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.GxLabel.Text = 'Gx';
            app.GxEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.GxEditField.ValueChangedFcn = createCallbackFcn(app, @GxEditFieldValueChanged, true);
            app.GxEditField.ValueChangingFcn = createCallbackFcn(app, @GxEditFieldValueChanging, true);
            app.GxEditField.Tag = '1';
            app.GxEditField.Value = '-0.075';
            app.GyLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.GyLabel.Text = 'Gy';
            app.GyEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.GyEditField.ValueChangedFcn = createCallbackFcn(app, @GyEditFieldValueChanged, true);
            app.GyEditField.ValueChangingFcn = createCallbackFcn(app, @GyEditFieldValueChanging, true);
            app.GyEditField.Tag = '2';
            app.GyEditField.Value = '0.21';
            app.GzLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.GzLabel.Text = 'Gz';
            app.GzEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.GzEditField.ValueChangedFcn = createCallbackFcn(app, @GzEditFieldValueChanged, true);
            app.GzEditField.ValueChangingFcn = createCallbackFcn(app, @GzEditFieldValueChanging, true);
            app.GzEditField.Tag = '3';
            app.GzEditField.Value = '0.007';
            app.WritingSurfaceSizeLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.WritingSurfaceSizeLabel.Text = 'Veličina površine za pisanje';
            app.xLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.xLabel.Text = '  x';
            app.xEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.ValueChangingFcn = createCallbackFcn(app, @xEditFieldValueChanging, true);
            app.xEditField.Tag = '4';
            app.xEditField.Value = '0.297';
            app.yLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.yLabel.Text = '  y';
            app.yEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.yEditField.ValueChangingFcn = createCallbackFcn(app, @yEditFieldValueChanging, true);
            app.yEditField.Tag = '5';
            app.yEditField.Value = '0.21';
            app.HeightLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.HeightLabel.Text = 'visina';
            app.WidthLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.WidthLabel.Text = 'širina';
            app.CheckWSDimensionsButton = uibutton(app.WorkspaceParamsUIFigure, 'push');
            app.CheckWSDimensionsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckWSDimensionsButtonPushed, true);
            app.CheckWSDimensionsButton.Text = {'Provjeri'; 'validnost'};
            app.CheckWSDimensions1Lamp = uilamp(app.WorkspaceParamsUIFigure);
            app.CheckWSDimensions2Lamp = uilamp(app.WorkspaceParamsUIFigure);
            app.CheckWSDimensions3Lamp = uilamp(app.WorkspaceParamsUIFigure);
            app.CheckWSDimensions4Lamp = uilamp(app.WorkspaceParamsUIFigure);
            app.MarginsLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.MarginsLabel.Text = 'Margine';
            app.aLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.aLabel.Text = 'a - lijeva';
            app.aEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.ValueChangingFcn = createCallbackFcn(app, @aEditFieldValueChanging, true);
            app.aEditField.Tag = '6';
            app.aEditField.Editable = 'off';
            app.aEditField.Value = '0.009676190476799998';
            app.bLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.bLabel.Text = 'b - desna';
            app.bEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.ValueChangingFcn = createCallbackFcn(app, @bEditFieldValueChanging, true);
            app.bEditField.Tag = '7';
            app.bEditField.Editable = 'off';
            app.bEditField.Value = '0.009676190476799998';
            app.cLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.cLabel.Text = 'c - gornja';
            app.cEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.cEditField.ValueChangingFcn = createCallbackFcn(app, @cEditFieldValueChanging, true);
            app.cEditField.Tag = '8';
            app.cEditField.Editable = 'off';
            app.cEditField.Value = '0.009676190476799998';
            app.dLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.dLabel.Text = 'd - donja';
            app.dEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.dEditField.ValueChangingFcn = createCallbackFcn(app, @dEditFieldValueChanging, true);
            app.dEditField.Tag = '9';
            app.dEditField.Editable = 'off';
            app.dEditField.Value = '0.009676190476799998';
            app.MarginsAutomaticValuesLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.MarginsAutomaticValuesLabel.Text = {'Odredi na osnovu parametara'; 'tehničkog pisma'};
            app.aSwitch = uiswitch(app.WorkspaceParamsUIFigure, 'slider');
            app.aSwitch.Items = {'Ne', 'Da'};
            app.aSwitch.ValueChangedFcn = createCallbackFcn(app, @aSwitchValueChanged, true);
            app.aSwitch.Value = 'Da';
            app.bSwitch = uiswitch(app.WorkspaceParamsUIFigure, 'slider');
            app.bSwitch.Items = {'Ne', 'Da'};
            app.bSwitch.ValueChangedFcn = createCallbackFcn(app, @bSwitchValueChanged, true);
            app.bSwitch.Value = 'Da';
            app.cSwitch = uiswitch(app.WorkspaceParamsUIFigure, 'slider');
            app.cSwitch.Items = {'Ne', 'Da'};
            app.cSwitch.ValueChangedFcn = createCallbackFcn(app, @cSwitchValueChanged, true);
            app.cSwitch.Value = 'Da';
            app.dSwitch = uiswitch(app.WorkspaceParamsUIFigure, 'slider');
            app.dSwitch.Items = {'Ne', 'Da'};
            app.dSwitch.ValueChangedFcn = createCallbackFcn(app, @dSwitchValueChanged, true);
            app.dSwitch.Value = 'Da';
            app.StartingFeedsLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.StartingFeedsLabel.Text = 'Početni pomaci';
            app.hLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.hLabel.Text = 'h - horizontalni';
            app.hEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.hEditField.ValueChangingFcn = createCallbackFcn(app, @hEditFieldValueChanging, true);
            app.hEditField.Tag = '10';
            app.hEditField.Value = '0';
            app.vLabel = uilabel(app.WorkspaceParamsUIFigure);
            app.vLabel.Text = 'v - vertikalni';
            app.vEditField = uieditfield(app.WorkspaceParamsUIFigure, 'text');
            app.vEditField.ValueChangedFcn = createCallbackFcn(app, @vEditFieldValueChanged, true);
            app.vEditField.ValueChangingFcn = createCallbackFcn(app, @vEditFieldValueChanging, true);
            app.vEditField.Tag = '11';
            app.vEditField.Value = '0';
            app.ToolSafeDistanceFromWritingSurfacePanel = uipanel(app.WorkspaceParamsUIFigure);
            app.ToolSafeDistanceFromWritingSurfaceLabel = uilabel(app.ToolSafeDistanceFromWritingSurfacePanel);
            app.ToolSafeDistanceFromWritingSurfaceLabel.Text = {'Sigurna udaljenost vrha'; 'alata od podloge'};
            app.ToolSafeDistanceFromWSEditField = uieditfield(app.ToolSafeDistanceFromWritingSurfacePanel, 'text');
            app.ToolSafeDistanceFromWSEditField.ValueChangedFcn = createCallbackFcn(app, @ToolSafeDistanceFromWSEditFieldValueChanged, true);
            app.ToolSafeDistanceFromWSEditField.ValueChangingFcn = createCallbackFcn(app, @ToolSafeDistanceFromWSEditFieldValueChanging, true);
            app.ToolSafeDistanceFromWSEditField.Tag = '12';
            app.ToolSafeDistanceFromWSEditField.Value = '0.01';
            app.WritingSurfaceImage = uiimage(app.WorkspaceParamsUIFigure);
            app.WritingSurfaceImage.ImageClickedFcn = createCallbackFcn(app, @WritingSurfaceImageClicked, true);
            app.WritingSurfaceImage.ImageSource = 'ws_params.png';
            app.CloseImagePreviewButton = uibutton(app.WorkspaceParamsUIFigure, 'push');
            app.CloseImagePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @CloseImagePreviewButtonPushed, true);
            app.CloseImagePreviewButton.Visible = 'off';
            app.CloseImagePreviewButton.Text = 'X';
            % Show the figure after all components are created
            app.WorkspaceParamsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = workspace_params(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WorkspaceParamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WorkspaceParamsUIFigure)
        end
    end
end